classdef GeneralLinearModel < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        MainPanel                     matlab.ui.container.Panel
        MainGridLayout                matlab.ui.container.GridLayout
        TabGroup                      matlab.ui.container.TabGroup
        ModelSpecTab                  matlab.ui.container.Tab
        ModelSpecTabGridLayout        matlab.ui.container.GridLayout
        ModelSpecNumEVsInput          matlab.ui.control.Spinner
        NumberofpredictorsLabel_3     matlab.ui.control.Label
        ModelSpecNumIntxInput         matlab.ui.control.Spinner
        NumberofpredictorsLabel_2     matlab.ui.control.Label
        ModelSpecValue                matlab.ui.control.Label
        VariablesPanel                matlab.ui.container.Panel
        VariablesGridLayout           matlab.ui.container.GridLayout
        VariablesNameLabel            matlab.ui.control.Label
        VariablesTypeLabel            matlab.ui.control.Label
        VariablesStandardizeLabel     matlab.ui.control.Label
        VariablesReferenceLabel       matlab.ui.control.Label
        VariablesInteractLabel        matlab.ui.control.Label
        DesignMatrixPanel             matlab.ui.container.Panel
        DesignMatrixGridLayout        matlab.ui.container.GridLayout
        DesignMatrixEBLabel           matlab.ui.control.Label
        DesignMatrixVGLabel           matlab.ui.control.Label
        DesignMatrixInputLabel        matlab.ui.control.Label
        DesignMatrixInterceptLabel    matlab.ui.control.Label
        StatsTab                      matlab.ui.container.Tab
        StatsTabGridLayout            matlab.ui.container.GridLayout
        StatsPanel                    matlab.ui.container.Panel
        StatsGridLayout               matlab.ui.container.GridLayout
        SidedLabel                    matlab.ui.control.Label
        TitleLabel                    matlab.ui.control.Label
        FtestsSpinner                 matlab.ui.control.Spinner
        FtestsSpinnerLabel            matlab.ui.control.Label
        TcontrastsSpinner             matlab.ui.control.Spinner
        TcontrastsSpinnerLabel        matlab.ui.control.Label
        PostStatsTab                  matlab.ui.container.Tab
        PostStatsGridLayout           matlab.ui.container.GridLayout
        DoPermsLabel                  matlab.ui.control.Label
        DoPermsCheckbox               matlab.ui.control.CheckBox
        DoSignFlipsLabel              matlab.ui.control.Label
        DoSignFlipsCheckbox           matlab.ui.control.CheckBox
        PermWithinLabel               matlab.ui.control.Label
        PermWithinCheckbox            matlab.ui.control.CheckBox
        PermWholeLabel                matlab.ui.control.Label
        PermWholeCheckbox             matlab.ui.control.CheckBox
        NumPermutationsLabel          matlab.ui.control.Label
        NumPermutationsEditField      matlab.ui.control.NumericEditField
        PrethresholdMaskingLabel      matlab.ui.control.Label
        PrethresholdMaskingEditField  matlab.ui.control.EditField
        PrethresholdMaskBrowseButton  matlab.ui.control.Button
        ThresholdingMethodPanel       matlab.ui.container.Panel
        ThresholdingMethodGridLayout  matlab.ui.container.GridLayout
        ThresholdingMethodDropDown    matlab.ui.control.DropDown
        ChannelWiseAlphaLabel         matlab.ui.control.Label
        ChannelWiseAlphaEditField     matlab.ui.control.NumericEditField
        ClusterAlphaLabel             matlab.ui.control.Label
        ClusterAlphaEditField         matlab.ui.control.NumericEditField
        TFCE_ParamsLabel              matlab.ui.control.Label
        TFCE_E_Label                  matlab.ui.control.Label
        TFCE_E_EditField              matlab.ui.control.NumericEditField
        TFCE_H_Label                  matlab.ui.control.Label
        TFCE_H_EditField              matlab.ui.control.NumericEditField
        OutputDirectoryPanel          matlab.ui.container.Panel
        OutputDirectoryGridLayout     matlab.ui.container.GridLayout
        OutputDirectoryValue          matlab.ui.control.EditField
        OutputDirectoryBrowseButton   matlab.ui.control.Button
        LoadingImage                  matlab.ui.control.Image
        AddProcessButton              matlab.ui.control.Button
        CancelButton                  matlab.ui.control.Button
    end


    properties (Access = public)
        MainApp;
        InterruptDialog;
        Import;
        Objects;
        % Structure that keeps the state
        State = struct();
    end

    methods (Access = public)
        % =========================================================
        % FUNCTION: Checks if an object exists
        function exists = ObjectExists(app, fname, i)
            % Check if object exists already
            if ~isfield(app.Objects, fname)
                exists = false;
            elseif length(app.Objects.(fname)) < i
                exists = false;
            elseif ~isfield(app.Objects.(fname)(i), 'Obj')
                exists = false;
            elseif isfield(app.Objects.(fname)(i), 'Obj') && isnumeric(app.Objects.(fname)(i).Obj)
                exists = false;
            elseif isfield(app.Objects.(fname)(i), 'Obj') && ~isvalid(app.Objects.(fname)(i).Obj)
                exists = false;
            else
                exists = true;
            end
        end
        % =========================================================
        function id = GetId(app)
            id = app.State.MaxId;
            app.State.MaxId = app.State.MaxId+1;
        end
        % =========================================================
        function [DesMat, Levels, Ref] = GetVarDesMat(app, VarName, Type, Coding, varargin)
            if nargin > 4
                Ref = varargin{1};
            else
                Ref = [];
            end
            % Extract the values from the data table
            Values = app.State.Phenotype.(VarName);
            % If it is a numeric array, create a single vector
            switch Type
                case 'continuous'
                    % Standardize the value if needed
                    switch Coding
                        case 'demean'
                            Values = Values - mean(Values, 'omitnan');
                        case 'z-score'
                            Values = nanzscore(Values);
                        otherwise
                            % Do nothing
                    end
                    DesMat = Values;
                    Levels = {1};
                case 'categorical'
                    % Make sure none of the cell array values are empty
                    if isnumeric(Values)
                        Values = arrayfun(@(i) sprintf('%i', i), Values, 'UniformOutput', false);
                    end
                    if any(cellfun(@(v) isempty(v), Values))
                        Values(cellfun(@(v) isempty(v), Values)) = {'missing'};
                    end
                    [DesMat, Levels, Ref] = codeDummyVar(categorical(Values), Ref, Coding);
            end
            if length(unique(Values(~ismissing(Values)))) == 1 % If there is only one value in the array, it's no good.
                DesMat = -1;
                Levels = cell(0);
                Ref = cell(0);
            end
        end
        % =========================================================
        function [DesMat, Terms] = GetIntxDesMat(app, IntxIdx)
            % ---------------------------------------------------------
            DesMat = [];
            Terms = cell(0);
            % ---------------------------------------------------------
            % Get which indices the predictors are
            PredIdx = find(ismember([app.State.Model.Predictors.Id], app.State.Model.Interactions(IntxIdx).PredIds));
            % Check how many levels there are for each predictor
            % We count from '0' to get all possible 2 and 3-way interactions
            NLvl = arrayfun(@(i) 0:size(app.State.Model.Predictors(i).DesMat, 2), PredIdx, 'UniformOutput', false);
            if length(NLvl) == 2 % Two-way interaction
                [X,Y] = meshgrid(NLvl{1}, NLvl{2});
                Combos = [X(:), Y(:)];
            elseif length(NLvl) == 3 % Three-way interaction
                [X,Y,Z] = meshgrid(NLvl{1}, NLvl{2}, NLvl{3});
                Combos = [X(:), Y(:), Z(:)];
            else
                return % There was only one predictor or more than three
            end
            % For each combination of levels between the factors...
            for j = 1:size(Combos, 1)
                % ... remove '0's, that was just a trick to get all possible combinations of levels between factors
                idx = Combos(j, :) > 0;
                % The combination must be between at least 2 factors
                if length(Combos(j, idx)) < 2
                    continue
                end
                % Extract the relevant levels for each factor in this combo
                Cols = arrayfun(@(p, c) app.State.Model.Predictors(p).DesMat(:, c), PredIdx(idx), Combos(j, idx), 'UniformOutput', false);
                Cols = array2table([Cols{:}]); % Transform to a table so we can apply the 'rowfun' function
                % The 'rowfun' function calculates the product between all values on each row in the table
                Intx = rowfun(@(varargin) prod([varargin{:}], 'all'), Cols);
                % Add the resulting interaction term to the design matrix
                DesMat = [DesMat, Intx{:, :}]; %#ok<AGROW>
                % Construct the term for this interaction
                Term = arrayfun(...
                    @(p, c) ...
                    ifelse(strcmpi(app.State.Model.Predictors(p).Type, 'categorical'), ...
                    [app.State.Model.Predictors(p).Name, '_', app.State.Model.Predictors(p).Levels{c}], ...
                    app.State.Model.Predictors(p).Name), ...
                    PredIdx(idx), ...
                    Combos(j, idx), ...
                    'UniformOutput', false);
                Terms = [Terms, {strjoin(Term, ':')}]; %#ok<AGROW>
            end
        end
        % =========================================================
        function [ModelSpec, PredNames] = GetModelSpec(app)
            switch app.State.ModelType
                case 'Two-sample paired t-test'
                    ModelSpec = 'Y ~ -1';
                    PredNames = {};
                otherwise
                    ModelSpec = 'Y ~ 1';
                    PredNames = {'intercept'};
            end
            for i = 1:length(app.State.Model.Predictors)
                switch app.State.Model.Predictors(i).Type
                    case 'continuous'
                        ModelSpec = [ModelSpec, ' + ', app.State.Model.Predictors(i).Name]; %#ok<AGROW>
                        PredNames = [PredNames, {app.State.Model.Predictors(i).Name}]; %#ok<AGROW>
                    case 'categorical'
                        for j = 1:length(app.State.Model.Predictors(i).Levels)
                            ModelSpec = [ModelSpec, ' + ', app.State.Model.Predictors(i).Name, '_', app.State.Model.Predictors(i).Levels{j}]; %#ok<AGROW>
                            PredNames = [PredNames, {[app.State.Model.Predictors(i).Name, '_', app.State.Model.Predictors(i).Levels{j}]}]; %#ok<AGROW>
                        end
                end
            end
            for i = 1:length(app.State.Model.Interactions)
                if isempty(app.State.Model.Interactions(i).Terms)
                    continue
                end
                Parenthesis = ' + (';
                for j = 1:length(app.State.Model.Interactions(i).Terms)
                    ModelSpec = [ModelSpec, Parenthesis, app.State.Model.Interactions(i).Terms{j}]; %#ok<AGROW>
                    PredNames = [PredNames, app.State.Model.Interactions(i).Terms(j)]; %#ok<AGROW>
                    Parenthesis = ' + ';
                end
                ModelSpec = [ModelSpec, ')']; %#ok<AGROW>
            end
            % TODO: at the moment, this app only allows for a repeated
            % measures design for a paired t-test. Other mixed effects
            % models are currently not supported. Therefore here the random
            % intercept is hardcoded for subject. But in the future this
            % may need to be a variable.
            if ~isempty(app.State.Model.RandVars)
                ModelSpec = [ModelSpec, ' + (1|subject)'];
            end
        end
        % =========================================================
        function m = GetMaxNumIntx(app)
            n = length(app.State.Model.Predictors); % number of variables
            m = sum(arrayfun(@(k) nchoosek(n, k), 2:n));
        end
        % =========================================================
        function [m, B] = GetMaxFtests(app)
            nttests = length(app.State.Statistics.Ttests.Titles);
            B = cell(0);
            m = 0;
            for k = 1:nttests % TODO check used to be 2:nttests
                b = nchoosek(1:nttests, k);
                B = [B; num2cell(b, 2)]; %#ok<AGROW> 
                m = m+size(b, 1);
            end
        end
        % =========================================================
        function InitOneWayAnovaStats(app, Ref, Levels)
            % IMPORTANT, this function can only be used with
            % 'sigma-restricted' coding of the design matrix!
            % ---------------------------------------------------------
            % Init
            app.State.Statistics = struct();
            app.State.Statistics.Ttests.Titles = cell(0);
            app.State.Statistics.Ttests.Contrasts = zeros(0, length(Levels)+1);
            app.State.Statistics.Ttests.Sided = cell(0);
            app.State.Statistics.Ftests = zeros(0);
            % Get all group mean contrasts
            C = struct();
            for i = 1:length([{Ref}, Levels])
                if i == 1
                    C(i).Level = Ref;
                    C(i).Contrast = [1, -1.*ones(1, length(Levels))];
                else
                    C(i).Level = Levels{i-1};
                    C(i).Contrast = [1, zeros(1, length(Levels))];
                    C(i).Contrast(i) = 1;
                end
            end
            % Create contrasts that test for all beta's against grand-mean to put an F-test on those
            for i = 1:length(Levels)
                app.State.Statistics.Ttests.Titles{i} = sprintf('%s - mu_grand', Levels{i});
                app.State.Statistics.Ttests.Contrasts(i, :) = zeros(1, length(Levels)+1);
                app.State.Statistics.Ttests.Contrasts(i, i+1) = 1;
                app.State.Statistics.Ttests.Sided{i} = 'two';
            end
            % Create contrasts that perform the post-hoc comparisons
            PostHocs = nchoosek([{Ref}, Levels], 2);
            for i = 1:size(PostHocs, 1)
                % Find the index of the group mean contrasts of the two
                % groups that are compared in this post-hoc test
                idx1 = strcmpi({C.Level}, PostHocs{i, 1});
                idx2 = strcmpi({C.Level}, PostHocs{i, 2});
                app.State.Statistics.Ttests.Titles{end+1} = sprintf('%s - %s', PostHocs{i, 1}, PostHocs{i, 2});
                app.State.Statistics.Ttests.Contrasts(end+1, :) = C(idx2).Contrast - C(idx1).Contrast;
                app.State.Statistics.Ttests.Sided{end+1} = 'two';
            end
            app.State.Statistics.Ftests(:, 1) = zeros(size(app.State.Statistics.Ttests.Contrasts, 1), 1);
            app.State.Statistics.Ftests(1:length(Levels), 1) = 1;
        end
        % =========================================================
        function InitTwoWayAnovaStats(app)
            % IMPORTANT, this function can only be used with
            % 'sigma-restricted' coding of the design matrix!
            % ---------------------------------------------------------
            % Init
            VarNameA = app.State.Model.Predictors(1).Name;
            VarNameB = app.State.Model.Predictors(2).Name;
            RefA = app.State.Model.Predictors(1).Reference;
            RefB = app.State.Model.Predictors(2).Reference;
            LevelsA = app.State.Model.Predictors(1).Levels;
            LevelsB = app.State.Model.Predictors(2).Levels;
            CellsA = [{RefA}, LevelsA];
            CellsB = [{RefB}, LevelsB];
            DesMat = [[app.State.Model.Predictors.DesMat], [app.State.Model.Interactions.DesMat]];
            % ---------------------------------------------------------
            app.State.Statistics = struct();
            app.State.Statistics.Ttests.Titles = cell(0);
            app.State.Statistics.Ttests.Contrasts = zeros(0, size(DesMat, 2)+1);
            app.State.Statistics.Ttests.Sided = cell(0);
            app.State.Statistics.Ftests = zeros(0);
            % Get all group mean contrasts
            C = struct();
            cnt = 0;
            for i = 1:length(CellsA)
                for j = 1:length(CellsB)
                    if i == 1
                        ValA = -1;
                        PredA = app.State.Model.Predictors(1).DesMat(:, i);
                    else
                        ValA = 1;
                        PredA = app.State.Model.Predictors(1).DesMat(:, i-1);
                    end
                    if j == 1
                        ValB = -1;
                        PredB = app.State.Model.Predictors(2).DesMat(:, j);
                    else
                        ValB = 1;
                        PredB = app.State.Model.Predictors(2).DesMat(:, j-1);
                    end
                    idx = GetIdx(PredA, PredB, ValA, ValB);
                    cnt = cnt + 1;
                    if isempty(idx)
                        C(cnt).Levels = '';
                        C(cnt).Contrast = [];
                    else
                        C(cnt).Levels = [VarNameA, '-', CellsA{i}, '_', VarNameB, '-', CellsB{j}];
                        C(cnt).Contrast = [1, DesMat(idx, :)];
                    end
                end
            end
            % Check that all cells have at least one observation
            if any(ismissing({C.Levels}))
                app.RenderErrorMessage('At least one combination of levels across the factors does not have an observation. This model cannot be specified.')
                return
            end
            % Create contrasts that test for all beta's against grand-mean to put an F-test on those
            for i = 1:length(LevelsA)
                app.State.Statistics.Ttests.Titles{i} = sprintf('%s_%s', VarNameA, LevelsA{i});
                app.State.Statistics.Ttests.Contrasts(i, :) = zeros(1, size(DesMat, 2)+1);
                app.State.Statistics.Ttests.Contrasts(i, i+1) = 1;
                app.State.Statistics.Ttests.Sided{i} = 'two';
            end
            for i = 1:length(LevelsB)
                app.State.Statistics.Ttests.Titles{length(LevelsA)+i} = sprintf('%s_%s', VarNameB, LevelsB{i});
                app.State.Statistics.Ttests.Contrasts(length(LevelsA)+i, :) = zeros(1, size(DesMat, 2)+1);
                app.State.Statistics.Ttests.Contrasts(length(LevelsA)+i, length(LevelsA)+i+1) = 1;
                app.State.Statistics.Ttests.Sided{length(LevelsA)+i} = 'two';
            end
            for i = 1:size(app.State.Model.Interactions.DesMat, 2)
                app.State.Statistics.Ttests.Titles{length(LevelsA)+length(LevelsB)+i} = sprintf('%s', app.State.Model.Interactions.Terms{i});
                app.State.Statistics.Ttests.Contrasts(length(LevelsA)+length(LevelsB)+i, :) = zeros(1, size(DesMat, 2)+1);
                app.State.Statistics.Ttests.Contrasts(length(LevelsA)+length(LevelsB)+i, length(LevelsA)+length(LevelsB)+i+1) = 1;
                app.State.Statistics.Ttests.Sided{length(LevelsA)+length(LevelsB)+i} = 'two';
            end
%             % Create contrasts that perform the post-hoc comparisons
%             PostHocs = nchoosek({C.Levels}, 2);
%             for i = 1:size(PostHocs, 1)
%                 % Find the index of the group mean contrasts of the two
%                 % groups that are compared in this post-hoc test
%                 idx1 = strcmpi({C.Levels}, PostHocs{i, 1});
%                 idx2 = strcmpi({C.Levels}, PostHocs{i, 2});
%                 app.State.Statistics.Ttests.Titles{end+1} = sprintf('%s - %s', PostHocs{i, 1}, PostHocs{i, 2});
%                 app.State.Statistics.Ttests.Contrasts(end+1, :) = C(idx2).Contrast - C(idx1).Contrast;
%                 app.State.Statistics.Ttests.Sided{end+1} = 'two';
%             end
            app.State.Statistics.Ftests(:, 1) = zeros(size(app.State.Statistics.Ttests.Contrasts, 1), 1);
            app.State.Statistics.Ftests(1:length(LevelsA), 1) = 1;
            app.State.Statistics.Ftests(:, 2) = zeros(size(app.State.Statistics.Ttests.Contrasts, 1), 1);
            app.State.Statistics.Ftests(length(LevelsA)+1:length(LevelsA)+length(LevelsB), 2) = 1;
            app.State.Statistics.Ftests(:, 3) = zeros(size(app.State.Statistics.Ttests.Contrasts, 1), 1);
            app.State.Statistics.Ftests(length(LevelsA)+length(LevelsB)+1:length(LevelsA)+length(LevelsB)+size(app.State.Model.Interactions.DesMat, 2), 3) = 1;
            % Sub function
            function idx = GetIdx(PredA, PredB, ValA, ValB)
                idx = find(...
                    PredA == ValA & ...
                    PredB == ValB, 1, 'first');
                if isempty(idx)
                    idx = [];                    
                end
            end
        end
        % =========================================================
        % FUNCTION: Initializes a new Empty State
        function InitializeState(app, FileList, ModelType)
            % ---------------------------------------------------------
            % Input validation
            % Check all input files are the same modality
            isSameFileType = length(unique(cellfun(@(f) f.filetype, FileList.KeyVals, 'UniformOutput', false))) == 1;
            if ~isSameFileType
                app.RenderErrorMessage('Not all input files are of the same data type. This is required.');
                return
            end
            % Check all input files have the same channels
            chanlocs = FileList.chanlocs;
            hasSameNbChan = length(unique(cellfun(@(cl) size(cl, 1), chanlocs))) == 1;
            if ~hasSameNbChan
                app.RenderErrorMessage('Not all input files have the same number of channels. This is required.');
                return
            end
            chanlabels = FileList.chanlocs{1}.name;
            hasSameChanLabels = all(cellfun(@(f) all(ismember(f.name, chanlabels)), FileList.chanlocs));
            if ~hasSameChanLabels
                app.RenderErrorMessage('Not all input files have the same channel labels. This is required.');
                return
            end
            % Calculate the average channel locations across files
            chanlocs = struct();
            for i = 1:length(chanlabels)
                chanlocs(i).labels = chanlabels{i};
                chanlocs(i).X = mean(cellfun(@(f) f.x(i), FileList.chanlocs));
                chanlocs(i).Y = mean(cellfun(@(f) f.y(i), FileList.chanlocs));
                chanlocs(i).Z = mean(cellfun(@(f) f.z(i), FileList.chanlocs));
            end
            % ---------------------------------------------------------
            % Init state
            app.State = struct();
            % Indicators and main settings
            app.State.isLoading = false;
            app.State.MaxId = 0;
            app.State.OutputDir = '';
            app.State.Input = FileList;
            app.State.Chanlocs = convertlocs(chanlocs, 'cart2topo');
            app.State.ModelType = ModelType;
            % Phenotype
            app.State.Phenotype = readPhenotype(app.State.Input);
            % Remove any constant variables
            isconst = cellfun(@(vn) length(unique(app.State.Phenotype.(vn))) == 1, app.State.Phenotype.Properties.VariableNames);
            app.State.Phenotype(:, isconst) = [];
            % Model specificaion
            app.State.Model.ModelSpec = 'Y ~ 1';
            app.State.Model.PredNames = {'intercept'};
            app.State.Model.Predictors.Id = app.GetId();
            app.State.Model.Predictors.Name = '';
            app.State.Model.Predictors.Type = '';
            app.State.Model.Predictors.Standardize = 'demean';
            app.State.Model.Predictors.Levels = cell(0);
            app.State.Model.Predictors.Reference = '';
            app.State.Model.Predictors.DesMat = zeros(0);
            app.State.Model.Predictors.Enable = 'off';
            app.State.Model.Predictors(1) = [];
            app.State.Model.Interactions.Id = app.GetId();
            app.State.Model.Interactions.PredIds = zeros(0);
            app.State.Model.Interactions.Terms = cell(0);
            app.State.Model.Interactions.DesMat = zeros(0);
            app.State.Model.Interactions(1) = [];
            app.State.Model.RandVars.Id = app.GetId();
            app.State.Model.RandVars.Name = '';
            app.State.Model.RandVars.DesMat = zeros(0);
            app.State.Model.RandVars(1) = [];
            app.State.Model.ExchangeabilityBlocks = ones(size(FileList, 1), 1);
            app.State.Model.VarianceGroups = ones(size(FileList, 1), 1);
            % Statistics
            app.State.Statistics.Ttests.Titles = cell(0);
            app.State.Statistics.Ttests.Contrasts = zeros(0, 1);
            app.State.Statistics.Ttests.Sided = cell(0);
            app.State.Statistics.Ftests = zeros(0);
            % Post-stats
            app.State.PostStats.NumPermutations = 10000;
            app.State.PostStats.DoPermutations = true;
            app.State.PostStats.DoSignFlips = false;
            app.State.PostStats.PermWithin = true;
            app.State.PostStats.PermWhole = true;
            app.State.PostStats.Mask = '';
            app.State.PostStats.Method = 'TFCE';
            app.State.PostStats.ChanWiseAlpha = 0.05;
            app.State.PostStats.ClusterAlpha = 0.05;
            app.State.PostStats.TFCE_E = 1;
            app.State.PostStats.TFCE_H = 2;
            % ---------------------------------------------------------
            % Create default settings depending on the model type
            switch ModelType
                case 'Correlation'
                    % First, keep only continuous variables in the phenotype data
                    VarNames = app.State.Phenotype.Properties.VariableNames;
                    isnum = cellfun(@(vn) strcmpi(vn, 'participant_id') | (isnumeric(app.State.Phenotype.(vn))), VarNames);
                    app.State.Phenotype = app.State.Phenotype(:, isnum);
                    % Check there is at least one variable
                    if sum(isnum) == 1 % only the 'participant_id' column
                        app.RenderErrorMessage('There are no continuous variables in your phenotype dataset.');
                        return
                    end
                    % Ask which variable to use
                    VarNames = app.State.Phenotype.Properties.VariableNames(~strcmpi(app.State.Phenotype.Properties.VariableNames, 'participant_id'));
                    app.Objects.SimpleDropdown = SimpleDropdown(app, 'Select the independent variable', VarNames, VarNames{1});
                    uiwait(app.Objects.SimpleDropdown.UIFigure);
                    % Check if user pressed cancel
                    if ~app.Import.Return
                        app.CancelButtonPushed([]);
                        return
                    end
                    % Make sure the value is an alphanumeric string
                    VarName = app.Import.Data.Answer;
                    [DesMat, Levels, Ref] = app.GetVarDesMat(VarName, 'continuous', 'demean');
                    app.State.Model.Predictors(1).Id = app.GetId();
                    app.State.Model.Predictors(1).Name = VarName;
                    app.State.Model.Predictors(1).Type = 'continuous';
                    app.State.Model.Predictors(1).Standardize = 'demean';
                    app.State.Model.Predictors(1).Reference = Ref;
                    app.State.Model.Predictors(1).Levels = Levels;
                    app.State.Model.Predictors(1).DesMat = DesMat;
                    app.State.Model.Predictors(1).Enable = 'off';
                    [app.State.Model.ModelSpec, app.State.Model.PredNames] = app.GetModelSpec();
                    app.State.Statistics.Ttests.Titles = {'Slope'};
                    app.State.Statistics.Ttests.Contrasts = [0 1];
                    app.State.Statistics.Ttests.Sided = {'two'};
                case 'Two-sample unpaired t-test'
                    % Find the first variable with 2 levels
                    VarNames = app.State.Phenotype.Properties.VariableNames;
                    isgrouping = cellfun(@(vn) ~strcmpi(vn, 'participant_id') & length(unique(app.State.Phenotype.(vn))) == 2, VarNames);
                    if ~any(isgrouping)
                        app.RenderErrorMessage('There are no grouping variables with two levels in your phenotype dataset.');
                        return
                    end
                    VarNames = VarNames(isgrouping);
                    % Ask which variable to use
                    app.Objects.SimpleDropdown = SimpleDropdown(app, 'Select the independent variable', VarNames, VarNames{1});
                    uiwait(app.Objects.SimpleDropdown.UIFigure);
                    % Check if user pressed cancel
                    if ~app.Import.Return
                        app.CancelButtonPushed([]);
                        return
                    end
                    % Create design matrix
                    VarName = app.Import.Data.Answer;
                    [DesMat, Levels, Ref] = app.GetVarDesMat(VarName, 'categorical', 'Treatment');
                    app.State.Model.Predictors(1).Id = app.GetId();
                    app.State.Model.Predictors(1).Name = VarName;
                    app.State.Model.Predictors(1).Type = 'categorical';
                    app.State.Model.Predictors(1).Standardize = 'n/a';
                    app.State.Model.Predictors(1).Reference = Ref;
                    app.State.Model.Predictors(1).Levels = Levels;
                    app.State.Model.Predictors(1).DesMat = DesMat;
                    app.State.Model.Predictors(1).Enable = 'off';
                    [app.State.Model.ModelSpec, app.State.Model.PredNames] = app.GetModelSpec();
                    app.State.Statistics.Ttests.Titles = {'Group diff'};
                    app.State.Statistics.Ttests.Contrasts = [0 1];
                    app.State.Statistics.Ttests.Sided = {'two'};
                case 'Two-sample paired t-test'
                    % Check that the all input files have the same keys
                    KeysLength = cellfun(@(f) length(fieldnames(f)), FileList.KeyVals, 'UniformOutput', true);
                    if length(unique(KeysLength)) ~= 1
                        app.RenderErrorMessage('All input files must have the same number of key-value pairs.');
                        return
                    end
                    Keys = cellfun(@(f) fieldnames(f)', FileList.KeyVals, 'UniformOutput', false);
                    Keys = cat(1, Keys{:});
                    if length(unique(Keys)) ~= size(Keys, 2)
                        app.RenderErrorMessage('All input files must have the same key names.');
                        return
                    end
                    Keys = unique(Keys, 'stable');
                    % Extract the subject ids from the filenames
                    Subs = unique(cellfun(@(f) f.sub, FileList.KeyVals, 'UniformOutput', false));
                    % Extract all key-values from the filenames
                    KeyVals = cellfun(@(f) struct2cell(f)', FileList.KeyVals, 'UniformOutput', false);
                    KeyVals = cat(1, KeyVals{:});
                    % Check that the input files include each participant
                    % twice and only twice
                    if length(Subs)*2 ~= size(KeyVals, 1)
                        app.RenderErrorMessage('Each participant must appear twice, and only twice in the input file list.');
                        return
                    end
                    % Find the key in the filenames that is different
                    % between two input files of the same participant
                    VarValues = cell(size(KeyVals, 1), 1);
                    SubIdx = strcmpi(Keys, 'sub');
                    for i = 1:length(Subs)
                        idxRows = find(strcmpi(KeyVals(:, SubIdx), Subs{i}));
                        idxCols = cellfun(@(a, b) ~strcmpi(a, b), KeyVals(idxRows(1), :), KeyVals(idxRows(2), :));
                        Values = KeyVals(idxRows, idxCols);
                        for j = 1:2
                            VarValues{idxRows(j)} = strjoin(cellfun(@(k, v) [k, '-', v], Keys(idxCols)', Values(j, :), 'UniformOutput', false), '_');
                        end
                    end
                    % Check again that the grouping values has 2 levels
                    if length(unique(VarValues)) ~= 2
                        Message = sprintf('The grouping variable must have 2 unique levels, but %i levels were found (%s).', length(unique(VarValues)), strjoin(unique(VarValues), ', '));
                        app.RenderErrorMessage(Message);
                        return
                    end
                    % Add the VarValues to the phenotype table
                    app.State.Phenotype.IndepVar = VarValues;
                    % Create design matrix
                    [DesMat, Levels, Ref] = app.GetVarDesMat('IndepVar', 'categorical', 'Sigma');
                    app.State.Model.Predictors(1).Id = app.GetId();
                    app.State.Model.Predictors(1).Name = 'IndepVar';
                    app.State.Model.Predictors(1).Type = 'categorical';
                    app.State.Model.Predictors(1).Standardize = 'n/a';
                    app.State.Model.Predictors(1).Reference = Ref;
                    app.State.Model.Predictors(1).Levels = Levels;
                    app.State.Model.Predictors(1).DesMat = DesMat;
                    app.State.Model.Predictors(1).Enable = 'off';
                    for i = 1:length(Subs)
                        idxRows = find(strcmpi(KeyVals(:, SubIdx), Subs{i}));
                        app.State.Model.RandVars(i).Id = app.GetId();
                        app.State.Model.RandVars(i).Name = KeyVals{idxRows(1), SubIdx};
                        app.State.Model.RandVars(i).DesMat = zeros(size(FileList, 1), 1);
                        app.State.Model.RandVars(i).DesMat(idxRows) = 1;
                        app.State.Model.ExchangeabilityBlocks(idxRows) = i;
                    end
                    [app.State.Model.ModelSpec, app.State.Model.PredNames] = app.GetModelSpec();
                    app.State.Statistics.Ttests.Titles = {'Paired diff'};
                    app.State.Statistics.Ttests.Contrasts = [1]; %#ok<NBRAK>
                    app.State.Statistics.Ttests.Sided = {'two'};
                case 'One-way ANOVA'
                    % Find the first variable with 2 or more levels
                    VarNames = app.State.Phenotype.Properties.VariableNames;
                    isgrouping = cellfun(@(vn) ...
                        ~strcmpi(vn, 'participant_id') & ...
                        length(unique(app.State.Phenotype.(vn))) >= 2 & ...
                        length(unique(app.State.Phenotype.(vn))) <= 5, VarNames);
                    if ~any(isgrouping)
                        app.RenderErrorMessage('There are no grouping variables with two to five levels in your phenotype dataset');
                        return
                    end
                    VarNames = VarNames(isgrouping);
                    % Ask which variable to use
                    app.Objects.SimpleDropdown = SimpleDropdown(app, 'Select the independent variable', VarNames, VarNames{1});
                    uiwait(app.Objects.SimpleDropdown.UIFigure);
                    % Check if user pressed cancel
                    if ~app.Import.Return
                        app.CancelButtonPushed([]);
                        return
                    end
                    % Create design matrix
                    VarName = app.Import.Data.Answer;
                    [DesMat, Levels, Ref] = app.GetVarDesMat(VarName, 'categorical', 'Sigma');
                    app.State.Model.Predictors(1).Id = app.GetId();
                    app.State.Model.Predictors(1).Name = VarName;
                    app.State.Model.Predictors(1).Type = 'categorical';
                    app.State.Model.Predictors(1).Standardize = 'n/a';
                    app.State.Model.Predictors(1).Reference = Ref;
                    app.State.Model.Predictors(1).Levels = Levels;
                    app.State.Model.Predictors(1).DesMat = DesMat;
                    app.State.Model.Predictors(1).Enable = 'off';
                    [app.State.Model.ModelSpec, app.State.Model.PredNames] = app.GetModelSpec();
                    app.InitOneWayAnovaStats(Ref, Levels);
                case 'Two-way ANOVA'
                    % Find the first variable with 2 or more levels
                    VarNames = app.State.Phenotype.Properties.VariableNames;
                    isgrouping = cellfun(@(vn) ...
                        ~strcmpi(vn, 'participant_id') & ...
                        length(unique(app.State.Phenotype.(vn))) >= 2 & ...
                        length(unique(app.State.Phenotype.(vn))) <= 5, VarNames);
                    if sum(isgrouping) < 2
                        app.RenderErrorMessage('There are less than two grouping variables with two to five levels in your phenotype dataset');
                        return
                    end
                    VarNames = VarNames(isgrouping);
                    % Ask which variable to use
                    app.Objects.DoubleDropdown = DoubleDropdown(app, ...
                        'Select factor A', VarNames, VarNames{1}, ...
                        'Select factor B', VarNames, VarNames{2});
                    uiwait(app.Objects.DoubleDropdown.UIFigure);
                    % Check if user pressed cancel
                    if ~app.Import.Return
                        app.CancelButtonPushed([]);
                        return
                    end
                    % Extract the factor names
                    VarNameA = app.Import.Data.Answer1;
                    VarNameB = app.Import.Data.Answer2;
                    % Create design matrix for factor A
                    [DesMat, Levels, Ref] = app.GetVarDesMat(VarNameA, 'categorical', 'Sigma');
                    app.State.Model.Predictors(1).Id = app.GetId();
                    app.State.Model.Predictors(1).Name = VarNameA;
                    app.State.Model.Predictors(1).Type = 'categorical';
                    app.State.Model.Predictors(1).Standardize = 'n/a';
                    app.State.Model.Predictors(1).Reference = Ref;
                    app.State.Model.Predictors(1).Levels = Levels;
                    app.State.Model.Predictors(1).DesMat = DesMat;
                    app.State.Model.Predictors(1).Enable = 'off';
                    % Create design matrix for factor B
                    [DesMat, Levels, Ref] = app.GetVarDesMat(VarNameB, 'categorical', 'Sigma');
                    app.State.Model.Predictors(2).Id = app.GetId();
                    app.State.Model.Predictors(2).Name = VarNameB;
                    app.State.Model.Predictors(2).Type = 'categorical';
                    app.State.Model.Predictors(2).Standardize = 'n/a';
                    app.State.Model.Predictors(2).Reference = Ref;
                    app.State.Model.Predictors(2).Levels = Levels;
                    app.State.Model.Predictors(2).DesMat = DesMat;
                    app.State.Model.Predictors(2).Enable = 'off';
                    % Create interactions
                    app.State.Model.Interactions(1).Id = app.GetId();
                    app.State.Model.Interactions(1).PredIds = [app.State.Model.Predictors(1).Id, app.State.Model.Predictors(2).Id];
                    [DesMat, Terms] = GetIntxDesMat(app, 1);
                    app.State.Model.Interactions(1).DesMat = DesMat;
                    app.State.Model.Interactions(1).Terms = Terms;
                    % Create model specification
                    [app.State.Model.ModelSpec, app.State.Model.PredNames] = app.GetModelSpec();
                    app.InitTwoWayAnovaStats();
                case 'General Linear Model'
                    % Dont do anything, user to define everything
                    [app.State.Model.ModelSpec, app.State.Model.PredNames] = app.GetModelSpec();
                    app.State.Statistics.Ttests.Titles = {'c1'};
                    app.State.Statistics.Ttests.Contrasts = [1]; %#ok<NBRAK>
                    app.State.Statistics.Ttests.Sided = {'two'};
                otherwise
                    app.RenderErrorMessage(sprintf('The model type ''%s'' is not supported', ModelType));
                    return
            end
            % Check if there was an error and the app was closed
            if ~isvalid(app)
                return
            end
            % ---------------------------------------------------------
            % Map the State to the Properties of all Components
            app.RenderModelSpecNumEVsInput();
            app.RenderModelSpecNumIntxInput();
            app.RenderModelSpecLabel();
            app.RenderVariablesGridLayout();
            app.RenderVariablesNameInput();
            app.RenderVariablesTypeInput();
            app.RenderVariablesReferenceInput();
            app.RenderVariablesInteractInput();
            app.RenderVariablesStandardizeInput();
            app.RenderDesignMatrixGridLayout();
            app.RenderDesignMatrixExchangeVarianceInput();
            app.RenderDesignMatrixSubjectInput();
            for i = 1:length(app.State.Model.Predictors)
                app.RenderDesignMatrixPredNameLabel(i);
                app.RenderDesignMatrixPredValueInput(i);
            end
            for i = 1:length(app.State.Model.Interactions)
                app.RenderDesignMatrixIntxNameLabel(i);
                app.RenderDesignMatrixIntxValueInput(i);
            end
            for i = 1:length(app.State.Model.RandVars)
                app.RenderDesignMatrixRandVarNameLabel(i);
                app.RenderDesignMatrixRandVarValueInput(i);
            end
            app.RenderStatsGridLayout();
            app.RenderTcontrastsSpinner();
            app.RenderFtestsSpinner();
            app.RenderStatsPanelHeader();
            app.RenderStatsPanel();
            app.RenderPermWithinWhole();
        end
        % =========================================================
        % METHOD: Sets an error message
        function RenderErrorMessage(app, Message)
            sel = uiconfirm(app.UIFigure, Message, 'Error', ...
                'Options',{'Ok'},...
                'DefaultOption', 'Ok', ...
                'Icon', 'error'); %#ok<NASGU> 
            drawnow();
            app.CancelButtonPushed([]);
        end
        % =========================================================
        % METHOD: Renders the number of EVs input
        function RenderOutputDirectoryValue(app)
            UpdateProperty(app, app.OutputDirectoryValue, 'Value', app.State.OutputDir);
        end
        % =========================================================
        % METHOD: Renders the number of EVs input
        function RenderAddProcessButton(app)
            if isempty(app.State.OutputDir)
                UpdateProperty(app, app.AddProcessButton, 'Enable', 'off');
            else
                UpdateProperty(app, app.AddProcessButton, 'Enable', 'on');
            end
        end
        % =========================================================
        % METHOD: Renders the number of EVs input
        function RenderModelSpecNumEVsInput(app)
            switch app.State.ModelType
                case 'Correlation'
                    UpdateProperty(app, app.ModelSpecNumEVsInput, 'Enable', 'off');
                case 'Two-sample unpaired t-test'
                    UpdateProperty(app, app.ModelSpecNumEVsInput, 'Enable', 'on');
                    UpdateProperty(app, app.ModelSpecNumEVsInput, 'Limits', [1, 50]);
                otherwise
                    UpdateProperty(app, app.ModelSpecNumEVsInput, 'Enable', 'on');
                    UpdateProperty(app, app.ModelSpecNumEVsInput, 'Limits', [0, 50]);
            end
            UpdateProperty(app, app.ModelSpecNumEVsInput, 'Value', length(app.State.Model.Predictors));
        end
        % =========================================================
        % METHOD: Renders the number of EVs input
        function RenderModelSpecNumIntxInput(app)
            switch app.State.ModelType
                case 'Correlation'
                    UpdateProperty(app, app.ModelSpecNumIntxInput, 'Enable', 'off');
                    return
            end
            UpdateProperty(app, app.ModelSpecNumIntxInput, 'Value', length(app.State.Model.Interactions));
            if length(app.State.Model.Predictors) > 1
                MaxNumIntx = app.GetMaxNumIntx();
                UpdateProperty(app, app.ModelSpecNumIntxInput, 'Enable', 'on');
                UpdateProperty(app, app.ModelSpecNumIntxInput, 'Limits', [0, MaxNumIntx]);
            else
                UpdateProperty(app, app.ModelSpecNumIntxInput, 'Enable', 'off');
            end
        end
        % =========================================================
        % METHOD: Renders the DesignMatrixModelSpec value
        function RenderModelSpecLabel(app)
            UpdateProperty(app, app.ModelSpecValue, 'Text', app.State.Model.ModelSpec);
        end
        % =========================================================
        % METHOD: Renders the VariablesGridLayout
        function RenderVariablesGridLayout(app)
            % How many predictors and interaction terms do we have?
            NVars = length(app.State.Model.Predictors);
            NIntx = length(app.State.Model.Interactions);
            % Create a row for each variable
            app.VariablesGridLayout.RowHeight = num2cell(ones(1, NVars+1).*24);
            app.VariablesGridLayout.ColumnWidth = [{'5x'}, {85}, {85}, {85}, repmat({24}, 1, max([1, NIntx])), {'1x'}];
            app.ModelSpecTabGridLayout.RowHeight{3} = 34+28*NVars;
            app.VariablesInteractLabel.Layout.Column = [5, max([1, NIntx])+5];
        end
        % =========================================================
        % METHOD: Renders the variables name inputs
        function RenderVariablesNameInput(app)
            for i = 1:length(app.State.Model.Predictors)
                % Render VarNameDropDown
                if app.ObjectExists('VarName', i)
                    % Object exists already, update the value only
                    UpdateProperty(app, app.Objects.VarName(i).Obj, 'Value', app.State.Model.Predictors(i).Name);
                else
                    % Object does not exist, create
                    switch app.State.Model.Predictors(i).Enable
                        case 'on'
                            app.Objects.VarName(i).Obj = uidropdown(app.VariablesGridLayout, ...
                                'FontSize', 10, ...
                                'Items', app.State.Phenotype.Properties.VariableNames(~ismember(app.State.Phenotype.Properties.VariableNames, {'participant_id'})), ...
                                'Value', app.State.Model.Predictors(i).Name, ...
                                'ValueChangedFcn', createCallbackFcn(app, @VarNameDropDownMenuChanged, true));
                        case 'off'
                            app.Objects.VarName(i).Obj = uieditfield(app.VariablesGridLayout, 'text', ...
                                'Value', app.State.Model.Predictors(i).Name, ...
                                'Editable', 'off');
                    end
                    app.Objects.VarName(i).Obj.UserData.Index = i;
                    app.Objects.VarName(i).Obj.Layout.Row = i+1;
                    app.Objects.VarName(i).Obj.Layout.Column = 1;
                end
            end
            % Delete objects if no longer needed (when NumPredictors had changed)
            if ~isfield(app.Objects, 'VarName')
                return
            end
            for i = length(app.Objects.VarName):-1:length(app.State.Model.Predictors)+1
                delete(app.Objects.VarName(i).Obj)
                app.Objects.VarName(i) = [];
            end
        end
        % =========================================================
        % METHOD: Renders the DesignMatrixVarType inputs
        function RenderVariablesTypeInput(app)
            % Do not render this dropdown for some types of models
            switch app.State.ModelType
                case 'Correlation'
                    UpdateProperty(app, app.VariablesTypeLabel, 'Enable', 'off');
                    return
                otherwise
                    UpdateProperty(app, app.VariablesTypeLabel, 'Enable', 'on');
            end
            for i = 1:length(app.State.Model.Predictors)
                % Get the variable name
                VarName = app.State.Model.Predictors(i).Name;
                % Get the values from the data table
                Values = app.State.Phenotype.(VarName);
                % The var type can only be changed if it is numerical
                if isnumeric(Values) && strcmpi(app.State.Model.Predictors(i).Enable, 'on')
                    Enable = 'on';
                else
                    Enable = 'off';
                end
                % Render VarTypeDropDown
                if app.ObjectExists('VarType', i)
                    % Object exists already, update the value only
                    UpdateProperty(app, app.Objects.VarType(i).Obj, 'Value', app.State.Model.Predictors(i).Type);
                    UpdateProperty(app, app.Objects.VarType(i).Obj, 'Enable', Enable);
                    app.Objects.VarType(i).Obj.UserData.VarName = VarName;
                else
                    % Object does not exist, create
                    app.Objects.VarType(i).Obj = uidropdown(app.VariablesGridLayout, ...
                        'FontSize', 10, ...
                        'Items', {'continuous', 'categorical'}, ...
                        'Value', app.State.Model.Predictors(i).Type, ...
                        'Enable', Enable, ...
                        'ValueChangedFcn', createCallbackFcn(app, @VarTypeDropDownMenuChanged, true));
                    app.Objects.VarType(i).Obj.UserData.Index = i;
                    app.Objects.VarType(i).Obj.UserData.VarName = VarName;
                    app.Objects.VarType(i).Obj.Layout.Row = i+1;
                    app.Objects.VarType(i).Obj.Layout.Column = 2;
                end
            end
            % Delete objects if no longer needed (when NumPredictors had changed)
            if ~isfield(app.Objects, 'VarType')
                return
            end
            for i = length(app.Objects.VarType):-1:length(app.State.Model.Predictors)+1
                delete(app.Objects.VarType(i).Obj)
                app.Objects.VarType(i) = [];
            end
        end
        % =========================================================
        % METHOD: Renders the DesignMatrixVarStandardize inputs
        function RenderVariablesStandardizeInput(app)
            for i = 1:length(app.State.Model.Predictors)
                % Check what value it should be, and to enable the input or not
                switch app.State.Model.Predictors(i).Type
                    case 'continuous'
                        Items = {'demean', 'z-score'};
                        Value = app.State.Model.Predictors(i).Standardize;
                        Enable = 'on';
                    case 'categorical'
                        Items = {'n/a'};
                        Value = 'n/a';
                        Enable = 'off';
                end
                % Render VarTypeDropDown
                if app.ObjectExists('VarStandardize', i)
                    % Object exists already, update the value only
                    UpdateProperty(app, app.Objects.VarStandardize(i).Obj, 'Items', Items);
                    UpdateProperty(app, app.Objects.VarStandardize(i).Obj, 'Value', Value);
                    UpdateProperty(app, app.Objects.VarStandardize(i).Obj, 'Enable', Enable);
                else
                    % Object does not exist, create
                    app.Objects.VarStandardize(i).Obj = uidropdown(app.VariablesGridLayout, ...
                        'FontSize', 10, ...
                        'Items', Items, ...
                        'Value', Value, ...
                        'Enable', Enable, ...
                        'ValueChangedFcn', createCallbackFcn(app, @VarStandardizeDropDownMenuChanged, true));
                    app.Objects.VarStandardize(i).Obj.UserData.Index = i;
                    app.Objects.VarStandardize(i).Obj.Layout.Row = i+1;
                    app.Objects.VarStandardize(i).Obj.Layout.Column = 3;
                end
            end
            % Delete objects if no longer needed (when NumPredictors had changed)
            if ~isfield(app.Objects, 'VarStandardize')
                return
            end
            for i = length(app.Objects.VarStandardize):-1:length(app.State.Model.Predictors)+1
                delete(app.Objects.VarStandardize(i).Obj)
                app.Objects.VarStandardize(i) = [];
            end
        end
        % =========================================================
        % METHOD: Renders the DesignMatrixVarType inputs
        function RenderVariablesReferenceInput(app)
            % Do not render this dropdown for some types of models
            switch app.State.ModelType
                case 'Correlation'
                    UpdateProperty(app, app.VariablesReferenceLabel, 'Enable', 'off');
                    return
                otherwise
                    UpdateProperty(app, app.VariablesReferenceLabel, 'Enable', 'on');
            end
            for i = 1:length(app.State.Model.Predictors)
                % Get the variable name and type
                VarName = app.State.Model.Predictors(i).Name;
                VarType = app.State.Model.Predictors(i).Type;
                % The reference can only be changed if it is categorical
                switch VarType
                    case 'categorical'
                        Enable = 'on';
                        Value = app.State.Model.Predictors(i).Reference;
                        Items = [{app.State.Model.Predictors(i).Reference}, app.State.Model.Predictors(i).Levels];
                    otherwise
                        Enable = 'off';
                        Value = 'n/a';
                        Items = {'n/a'};
                end
                % Render VarRefDropDown
                if app.ObjectExists('VarRef', i)
                    % Object exists already, update the value only
                    UpdateProperty(app, app.Objects.VarRef(i).Obj, 'Items', Items);
                    UpdateProperty(app, app.Objects.VarRef(i).Obj, 'Value', Value);
                    UpdateProperty(app, app.Objects.VarRef(i).Obj, 'Enable', Enable);
                    app.Objects.VarRef(i).Obj.UserData.VarName = VarName;
                else
                    % Object does not exist, create
                    app.Objects.VarRef(i).Obj = uidropdown(app.VariablesGridLayout, ...
                        'FontSize', 10, ...
                        'Items', Items, ...
                        'Value', Value, ...
                        'Enable', Enable, ...
                        'ValueChangedFcn', createCallbackFcn(app, @VarReferenceDropDownMenuChanged, true));
                    app.Objects.VarRef(i).Obj.UserData.Index = i;
                    app.Objects.VarRef(i).Obj.UserData.VarName = VarName;
                    app.Objects.VarRef(i).Obj.Layout.Row = i+1;
                    app.Objects.VarRef(i).Obj.Layout.Column = 4;
                end
            end
            % Delete objects if no longer needed (when NumPredictors had changed)
            if ~isfield(app.Objects, 'VarRef')
                return
            end
            for i = length(app.Objects.VarRef):-1:length(app.State.Model.Predictors)+1
                delete(app.Objects.VarRef(i).Obj)
                app.Objects.VarRef(i) = [];
            end
        end
        % =========================================================
        % METHOD: Renders the DesignMatrixVarType inputs
        function RenderVariablesInteractInput(app)
            % Do not render this dropdown for some types of models
            switch app.State.ModelType
                case 'Correlation'
                    UpdateProperty(app, app.VariablesInteractLabel, 'Enable', 'off');
                    return
                otherwise
                    UpdateProperty(app, app.VariablesInteractLabel, 'Enable', 'on');
            end
            NPred = length(app.State.Model.Predictors);
            NIntx = length(app.State.Model.Interactions);
            for i = 1:NIntx
                % Check how many terms this interaction has
                NTerms = length(app.State.Model.Interactions(i).PredIds);
                for j = 1:NPred
                    PredId = app.State.Model.Predictors(j).Id;
                    idx = (i-1)*NPred+j;
                    if app.ObjectExists('IntxCheckbox', idx)
                        % Object exists already, update the value only
                        Value = any(PredId == app.State.Model.Interactions(i).PredIds);
                        UpdateProperty(app, app.Objects.IntxCheckbox(idx).Obj, 'Value', Value);
                        UpdateProperty(app, app.Objects.IntxCheckbox(idx).Obj, 'Tag', num2str(app.State.Model.Interactions(i).Id));
                    else
                        % Object does not exist, create
                        app.Objects.IntxCheckbox(idx).Obj = uicheckbox(app.VariablesGridLayout, ...
                            'Value', any(PredId == app.State.Model.Interactions(i).PredIds), ...
                            'Text', '', ...
                            'Tag', num2str(app.State.Model.Interactions(i).Id), ...
                            'ValueChangedFcn', createCallbackFcn(app, @VarIntxCheckboxValueChanged, true));
                        app.Objects.IntxCheckbox(idx).Obj.UserData.Intx = i;
                        app.Objects.IntxCheckbox(idx).Obj.UserData.Pred = j;
                        app.Objects.IntxCheckbox(idx).Obj.Layout.Row = 1+j;
                        app.Objects.IntxCheckbox(idx).Obj.Layout.Column = 4+i;
                    end
                    % Each interaction may not have more than 3 terms
                    if NTerms >= 3 && ~Value
                        UpdateProperty(app, app.Objects.IntxCheckbox(idx).Obj, 'Enable', 'off');
                    else
                        UpdateProperty(app, app.Objects.IntxCheckbox(idx).Obj, 'Enable', 'on');
                    end
                end
            end
            % Delete objects if no longer needed
            Tags = arrayfun(@(obj) obj.Tag, app.VariablesGridLayout.Children, 'UniformOutput', false);
            Ids = [app.State.Model.Interactions.Id];
            Rm = cellfun(@(t) ~isempty(t) & ~any(str2double(t) == Ids), Tags);
            if any(Rm)
                delete(app.VariablesGridLayout.Children(Rm))
            end
        end
        % =========================================================
        % METHOD: Renders the DesignMatrixGridLayout
        function RenderDesignMatrixGridLayout(app)
            % Create a row for each input
            app.DesignMatrixGridLayout.RowHeight = num2cell(ones(1, size(app.State.Input, 1)+1).*24);
            % How many predictors?
            NumPredictors = size([[app.State.Model.Predictors.DesMat], [app.State.Model.Interactions.DesMat]], 2);
            NumRandVars = size([app.State.Model.RandVars.DesMat], 2);
            % Delete any child that does no longer fit in the grid
            Rm = arrayfun(@(obj) obj.Layout.Column, app.DesignMatrixGridLayout.Children) > NumPredictors+NumRandVars+4;
            delete(app.DesignMatrixGridLayout.Children(Rm))
            % Create a column for each predictor (categorical vars can have more than 1)
            app.DesignMatrixGridLayout.ColumnWidth = [{24, 24, 85, 36}, num2cell(ones(1, NumPredictors).*36), num2cell(ones(1, NumRandVars).*16)];
            % Hide the intercept column for some model types
            switch app.State.ModelType
                case 'Two-sample paired t-test'
                    app.DesignMatrixGridLayout.ColumnWidth{4} = 0;
            end
            % Show some handy info in the panel title
            app.DesignMatrixPanel.Title = sprintf('Design matrix – %i input files', size(app.State.Input, 1));
        end
        % =========================================================
        % METHOD: Renders input for the exchanability block and variance groups
        function RenderDesignMatrixExchangeVarianceInput(app)
            % Render an input field for each input
            for i = 1:size(app.State.Input, 1)
                % Render EditField
                if app.ObjectExists('ExchangeabilityBlocks', i)
                    % Object exists already, update the value only
                    UpdateProperty(app, app.Objects.ExchangeabilityBlocks(i).Obj, 'Value', app.State.Model.ExchangeabilityBlocks(i));
                else
                    % Object does not exist, create
                    app.Objects.ExchangeabilityBlocks(i).Obj = uieditfield(app.DesignMatrixGridLayout, 'numeric', ...
                        'Value', app.State.Model.ExchangeabilityBlocks(i), ...
                        'FontSize', 10, ...
                        'Limits', [1, Inf], ...
                        'HorizontalAlignment', 'center', ...
                        'ValueChangedFcn', createCallbackFcn(app, @ExchangeabilityBlocksValueChanged, true));
                    app.Objects.ExchangeabilityBlocks(i).Obj.Layout.Row = 1+i;
                    app.Objects.ExchangeabilityBlocks(i).Obj.Layout.Column = 1;
                    app.Objects.ExchangeabilityBlocks(i).Obj.UserData.idx = i;
                end
                % Render EditField
                if app.ObjectExists('VarianceGroups', i)
                    % Object exists already, update the value only
                    UpdateProperty(app, app.Objects.VarianceGroups(i).Obj, 'Value', app.State.Model.VarianceGroups(i));
                else
                    % Object does not exist, create
                    app.Objects.VarianceGroups(i).Obj = uieditfield(app.DesignMatrixGridLayout, 'numeric', ...
                        'Value', app.State.Model.VarianceGroups(i), ...
                        'FontSize', 10, ...
                        'Limits', [1, Inf], ...
                        'HorizontalAlignment', 'center', ...
                        'ValueChangedFcn', createCallbackFcn(app, @VarianceGroupsValueChanged, true));
                    app.Objects.VarianceGroups(i).Obj.Layout.Row = 1+i;
                    app.Objects.VarianceGroups(i).Obj.Layout.Column = 2;
                    app.Objects.VarianceGroups(i).Obj.UserData.idx = i;
                end
            end
        end
        % =========================================================
        % METHOD: Renders the DesignMatrixSubjectInput fields and their intercept input field
        function RenderDesignMatrixSubjectInput(app)
            % Render an input field for each input
            for i = 1:size(app.State.Input, 1)
                switch app.State.ModelType
                    case 'Two-sample paired t-test'
                        [~, Value] = fileparts(app.State.Input.Path{i});
                        DoIntercept = false;
                    otherwise
                        DoIntercept = true;
                        Value = app.State.Input.KeyVals{i}.sub;
                end
                % Render EditField
                app.Objects.InputName(i).Obj = uieditfield(app.DesignMatrixGridLayout, 'text', ...
                    'Value', Value, ...
                    'Tooltip', Value, ...
                    'FontSize', 10, ...
                    'Editable', 'off');
                app.Objects.InputName(i).Obj.Layout.Row = 1+i;
                app.Objects.InputName(i).Obj.Layout.Column = 3;
                % Render Intercept Value Fields
                if DoIntercept
                    app.Objects.Intercept(i).Obj = uieditfield(app.DesignMatrixGridLayout, 'text', ...
                        'Value', '1', ...
                        'Tooltip', 'intercept', ...
                        'FontSize', 10, ...
                        'HorizontalAlignment', 'center', ...
                        'Editable', 'off');
                    app.Objects.Intercept(i).Obj.Layout.Row = 1+i;
                    app.Objects.Intercept(i).Obj.Layout.Column = 4;
                else
                    app.DesignMatrixInterceptLabel.Visible = 'off';
                end
            end
        end
        % =========================================================
        % METHOD: Renders the beta labels for predictors
        function RenderDesignMatrixPredNameLabel(app, VarIdx)
            if VarIdx > 0
                % For some model types there is no intercept
                switch app.State.ModelType
                    case 'Two-sample paired t-test'
                        DoIntercept = -1;
                    otherwise
                        DoIntercept = 0;
                end
                % Calculate which column to start
                startCol = size([app.State.Model.Predictors(1:VarIdx-1).DesMat], 2);
                Levels = app.State.Model.Predictors(VarIdx).Levels;
                for i = 1:length(Levels)
                    % Calculate the index where to place the object in the array
                    idx = startCol+i;
                    % Specify text string
                    switch app.State.Model.Predictors(VarIdx).Type
                        case 'categorical'
                            Text = sprintf('%s_%s', app.State.Model.Predictors(VarIdx).Name, Levels{i});
                        otherwise
                            Text = sprintf('%s', app.State.Model.Predictors(VarIdx).Name);
                    end
                    % Render label
                    if app.ObjectExists('DesMatVarNameLabel', idx)
                        % Object exists already, update the value only
                        UpdateProperty(app, app.Objects.DesMatVarNameLabel(idx).Obj, 'Text', ['$$\beta_{', num2str(idx+DoIntercept),'}$$']);
                        UpdateProperty(app, app.Objects.DesMatVarNameLabel(idx).Obj, 'Tooltip', Text);
                        UpdateProperty(app, app.Objects.DesMatVarNameLabel(idx).Obj, 'Tag', num2str(app.State.Model.Predictors(VarIdx).Id));
                    else
                        % Object does not exist, create
                        app.Objects.DesMatVarNameLabel(idx).Obj = uilabel(app.DesignMatrixGridLayout, ...
                            'Text', ['$$\beta_{', num2str(idx+DoIntercept),'}$$'], ...
                            'Interpreter', 'latex', ...
                            'HorizontalAlignment', 'center', ...
                            'Tooltip', Text);
                        app.Objects.DesMatVarNameLabel(idx).Obj.Tag = num2str(app.State.Model.Predictors(VarIdx).Id);
                        app.Objects.DesMatVarNameLabel(idx).Obj.Layout.Row = 1;
                        app.Objects.DesMatVarNameLabel(idx).Obj.Layout.Column = 4+startCol+i;
                    end
                end
            end
            % Delete objects if no longer needed
            app.UnmountDesMatGridLayoutChildren();
        end
        % =========================================================
        % METHOD: Renders the beta labels for interaction terms
        function RenderDesignMatrixIntxNameLabel(app, IntxIdx)
            if IntxIdx > 0
                % Caculcate which column to start
                startCol = size([[app.State.Model.Predictors.DesMat], [app.State.Model.Interactions(1:IntxIdx-1).DesMat]], 2);
                Terms = app.State.Model.Interactions(IntxIdx).Terms;
                for i = 1:length(Terms)
                    % Calculate the index where to place the object in the array
                    idx = startCol+i;
                    % Specify text string
                    Text = sprintf('%s', Terms{i});
                    % Render label
                    if app.ObjectExists('DesMatVarNameLabel', idx)
                        % Object exists already, update the value only
                        UpdateProperty(app, app.Objects.DesMatVarNameLabel(idx).Obj, 'Text', ['$$\beta_{', num2str(idx),'}$$']);
                        UpdateProperty(app, app.Objects.DesMatVarNameLabel(idx).Obj, 'Tooltip', Text);
                        UpdateProperty(app, app.Objects.DesMatVarNameLabel(idx).Obj, 'Tag', num2str(app.State.Model.Interactions(IntxIdx).Id));
                    else
                        % Object does not exist, create
                        app.Objects.DesMatVarNameLabel(idx).Obj = uilabel(app.DesignMatrixGridLayout, ...
                            'Text', ['$$\beta_{', num2str(idx),'}$$'], ...
                            'Interpreter', 'latex', ...
                            'HorizontalAlignment', 'center', ...
                            'Tooltip', Text);
                        app.Objects.DesMatVarNameLabel(idx).Obj.Tag = num2str(app.State.Model.Interactions(IntxIdx).Id);
                        app.Objects.DesMatVarNameLabel(idx).Obj.Layout.Row = 1;
                        app.Objects.DesMatVarNameLabel(idx).Obj.Layout.Column = 4+startCol+i;
                    end
                end
            end
            % Delete objects if no longer needed
            app.UnmountDesMatGridLayoutChildren();
        end
        % =========================================================
        % METHOD: Renders the beta labels for random variable terms
        function RenderDesignMatrixRandVarNameLabel(app, VarIdx)
            if VarIdx > 0
                % Caculcate which column to start
                idx = size([[app.State.Model.Predictors.DesMat], [app.State.Model.Interactions.DesMat], [app.State.Model.RandVars(1:VarIdx-1).DesMat]], 2)+1;
                VarName = app.State.Model.RandVars(VarIdx).Name;
                % Specify text string
                Text = sprintf('%s', VarName);
                % Render label
                if app.ObjectExists('DesMatVarNameLabel', idx)
                    % Object exists already, update the value only
                    UpdateProperty(app, app.Objects.DesMatVarNameLabel(idx).Obj, 'Text', ['$$\gamma_{', num2str(VarIdx),'}$$']);
                    UpdateProperty(app, app.Objects.DesMatVarNameLabel(idx).Obj, 'Tooltip', Text);
                    UpdateProperty(app, app.Objects.DesMatVarNameLabel(idx).Obj, 'Tag', num2str(app.State.Model.RandVars(VarIdx).Id));
                else
                    % Object does not exist, create
                    app.Objects.DesMatVarNameLabel(idx).Obj = uilabel(app.DesignMatrixGridLayout, ...
                        'Text', ['$$\gamma_{', num2str(VarIdx),'}$$'], ...
                        'Interpreter', 'latex', ...
                        'HorizontalAlignment', 'center', ...
                        'Tooltip', Text);
                    app.Objects.DesMatVarNameLabel(idx).Obj.Tag = num2str(app.State.Model.RandVars(VarIdx).Id);
                    app.Objects.DesMatVarNameLabel(idx).Obj.Layout.Row = 1;
                    app.Objects.DesMatVarNameLabel(idx).Obj.Layout.Column = 4+idx;
                end
            end
            % Delete objects if no longer needed
            app.UnmountDesMatGridLayoutChildren();
        end
        % =========================================================
        function RenderDesignMatrixValueInput(app, DesMat, startCol, i, j, N, Tooltip, Tag)
            % Calculate the index where to place the object in the array
            idx = (startCol+j-1)*N+i;
            % Extract the value from the design matrix
            Value = DesMat(i, j);
            if ismissing(Value) || isempty(Value)
                BackgroundColor = [255, 235, 238]./255;
                FontColor = [183, 28, 28]./255;
            else
                BackgroundColor = [1, 1, 1];
                FontColor = [0, 0, 0];
            end
            % Convert to text or extract contents from cell
            if isnumeric(Value)
                Value = num2str(Value);
            elseif iscell(Value)
                Value = Value{1};
            end
            % Render EditField
            if app.ObjectExists('DesMatValueInput', idx)
                % Object exists already, update the value only
                UpdateProperty(app, app.Objects.DesMatValueInput(idx).Obj, 'Value', Value);
                UpdateProperty(app, app.Objects.DesMatValueInput(idx).Obj, 'BackgroundColor', BackgroundColor);
                UpdateProperty(app, app.Objects.DesMatValueInput(idx).Obj, 'FontColor', FontColor);
                UpdateProperty(app, app.Objects.DesMatValueInput(idx).Obj, 'Tooltip', Tooltip);
                UpdateProperty(app, app.Objects.DesMatValueInput(idx).Obj, 'Tag', Tag);
                app.Objects.DesMatValueInput(idx).Obj.Layout.Row = i+1;
                app.Objects.DesMatValueInput(idx).Obj.Layout.Column = 4+startCol+j;
            else
                % Object does not exist, create
                app.Objects.DesMatValueInput(idx).Obj = uieditfield(app.DesignMatrixGridLayout, 'text', ...
                    'Value', Value, ...
                    'BackgroundColor', BackgroundColor, ...
                    'FontSize', 10, ...
                    'FontColor', FontColor, ...
                    'Tooltip', Tooltip, ...
                    'HorizontalAlignment', 'center', ...
                    'Editable', 'off');
                app.Objects.DesMatValueInput(idx).Obj.Tag = Tag;
                app.Objects.DesMatValueInput(idx).Obj.Layout.Row = i+1;
                app.Objects.DesMatValueInput(idx).Obj.Layout.Column = 4+startCol+j;
            end
        end
        % =========================================================
        % METHOD: Renders the DesignMatrix Value inputs
        function RenderDesignMatrixPredValueInput(app, VarIdx)
            if VarIdx > 0
                % Get the values from the data table
                DesMat = app.State.Model.Predictors(VarIdx).DesMat;
                VarName = app.State.Model.Predictors(VarIdx).Name;
                Levels = app.State.Model.Predictors(VarIdx).Levels;
                startCol = size([app.State.Model.Predictors(1:VarIdx-1).DesMat], 2);
                % For each input, create an input field
                N = size(app.State.Input, 1);
                for i = 1:N
                    for j = 1:size(DesMat, 2)
                        % Specify tooltip string
                        switch app.State.Model.Predictors(VarIdx).Type
                            case 'categorical'
                                Tooltip = sprintf('%s_%s', VarName, Levels{j});
                            otherwise
                                Tooltip = sprintf('%s', VarName);
                        end
                        % Specify tag
                        Tag = num2str(app.State.Model.Predictors(VarIdx).Id);
                        % Render input
                        app.RenderDesignMatrixValueInput(DesMat, startCol, i, j, N, Tooltip, Tag);
                    end
                end
            end
            % Delete objects if no longer needed
            app.UnmountDesMatGridLayoutChildren();
        end
        % =========================================================
        % METHOD: Renders the DesignMatrix Value inputs
        function RenderDesignMatrixIntxValueInput(app, IntxIdx)
            if IntxIdx > 0
                % Get the values from the data table
                DesMat = app.State.Model.Interactions(IntxIdx).DesMat;
                Terms = app.State.Model.Interactions(IntxIdx).Terms;
                startCol = size([[app.State.Model.Predictors.DesMat], [app.State.Model.Interactions(1:IntxIdx-1).DesMat]], 2);
                % For each input, create an input field
                N = size(app.State.Input, 1);
                for i = 1:N
                    for j = 1:size(DesMat, 2)
                        % Specify tag
                        Tag = num2str(app.State.Model.Interactions(IntxIdx).Id);
                        % Render input
                        app.RenderDesignMatrixValueInput(DesMat, startCol, i, j, N, Terms{j}, Tag);
                    end
                end
            end
            % Delete objects if no longer needed
            app.UnmountDesMatGridLayoutChildren();
        end
        % =========================================================
        % METHOD: Renders the DesignMatrix Value inputs
        function RenderDesignMatrixRandVarValueInput(app, VarIdx)
            if VarIdx > 0
                % Get the values from the data table
                DesMat = app.State.Model.RandVars(VarIdx).DesMat;
                VarName = app.State.Model.RandVars(VarIdx).Name;
                startCol = size([[app.State.Model.Predictors.DesMat], [app.State.Model.Interactions.DesMat], [app.State.Model.RandVars(1:VarIdx-1).DesMat]], 2);
                % For each input, create an input field
                N = size(app.State.Input, 1);
                for i = 1:N
                    % Specify tag
                    Tag = num2str(app.State.Model.RandVars(VarIdx).Id);
                    % Render input
                    app.RenderDesignMatrixValueInput(DesMat, startCol, i, 1, N, VarName, Tag);
                end
            end
            % Delete objects if no longer needed
            app.UnmountDesMatGridLayoutChildren();
        end
        % =========================================================
        % METHOD: Un-mounts objects that are no longer required
        function UnmountDesMatGridLayoutChildren(app)
            Tags = arrayfun(@(obj) obj.Tag, app.DesignMatrixGridLayout.Children, 'UniformOutput', false);
            Ids = [[app.State.Model.Predictors.Id], [app.State.Model.Interactions.Id], [app.State.Model.RandVars.Id]];
            Rm = cellfun(@(t) ~isempty(t) & ~any(str2double(t) == Ids), Tags);
            if any(Rm)
                delete(app.DesignMatrixGridLayout.Children(Rm))
            end
        end
        % =========================================================
        % METHOD: Renders the stats panel header line
        function RenderStatsGridLayout(app)
            % Create one row in the gridlayout for each t contrast
            RowHeight = [{24}, num2cell(ones(1, length(app.State.Statistics.Ttests.Titles)).*24)];
            ColumnWidth = [{120, 28}, repmat({'3x'}, 1, length(app.State.Model.PredNames)), repmat({16}, 1, size(app.State.Statistics.Ftests, 2))];
            app.StatsGridLayout.RowHeight = RowHeight;
            app.StatsGridLayout.ColumnWidth = ColumnWidth;
            % Delete any child that does no longer fit in the grid
            Rm = arrayfun(@(obj) obj.Layout.Column, app.StatsGridLayout.Children) > length(ColumnWidth);
            delete(app.StatsGridLayout.Children(Rm));
            Rm = arrayfun(@(obj) obj.Layout.Row, app.StatsGridLayout.Children) > length(RowHeight);
            delete(app.StatsGridLayout.Children(Rm));
        end
        % =========================================================
        % METHOD: Renders the t-contrast spinner input
        function RenderTcontrastsSpinner(app)
            if size(app.State.Statistics.Ttests.Contrasts, 1) > 0
                UpdateProperty(app, app.TcontrastsSpinner, 'Value', size(app.State.Statistics.Ttests.Contrasts, 1));
                UpdateProperty(app, app.TcontrastsSpinner, 'Enable', 'on');
            end
            switch app.State.ModelType
                case 'Correlation'
                    UpdateProperty(app, app.TcontrastsSpinner, 'Enable', 'off');
            end
        end
        % =========================================================
        % METHOD: Renders the F-test spinner input
        function RenderFtestsSpinner(app)
            % Determine the max number of f-tests
            m = app.GetMaxFtests();
            if m == 0
                UpdateProperty(app, app.FtestsSpinner, 'Enable', 'off');
                UpdateProperty(app, app.FtestsSpinner, 'Value', size(app.State.Statistics.Ftests, 2));
            else
                UpdateProperty(app, app.FtestsSpinner, 'Enable', 'on');
                UpdateProperty(app, app.FtestsSpinner, 'Limits', [0, m]);
                UpdateProperty(app, app.FtestsSpinner, 'Value', size(app.State.Statistics.Ftests, 2));
            end
            switch app.State.ModelType
                case 'Correlation'
                    UpdateProperty(app, app.FtestsSpinner, 'Enable', 'off');
            end
        end
        % =========================================================
        % METHOD: Renders the stats panel header line
        function RenderStatsPanelHeader(app)
            % Create a label for each predictor
            for i = 1:size(app.State.Statistics.Ttests.Contrasts, 2)
                if app.ObjectExists('TContrastHeader', i)
                    % Object exists already, update the value only
                    UpdateProperty(app, app.Objects.TContrastHeader(i).Obj, 'Text', ['$$\beta_{', num2str(i-1),'}$$']);
                    UpdateProperty(app, app.Objects.TContrastHeader(i).Obj, 'Tag', app.State.Model.PredNames{i});
                    UpdateProperty(app, app.Objects.TContrastHeader(i).Obj, 'Tooltip', app.State.Model.PredNames{i});
                    app.Objects.TContrastHeader(i).Obj.Layout.Row = 1;
                    app.Objects.TContrastHeader(i).Obj.Layout.Column = i+2; % Title and sided are first 2
                else
                    % Object does not exist, create
                    app.Objects.TContrastHeader(i).Obj = uilabel(app.StatsGridLayout, ...
                        'Text', ['$$\beta_{', num2str(i-1),'}$$'], ...
                        'Tag', app.State.Model.PredNames{i}, ...
                        'Tooltip', app.State.Model.PredNames{i}, ...
                        'Interpreter', 'latex', ...
                        'FontSize', 10, ...
                        'HorizontalAlignment', 'center');
                    app.Objects.TContrastHeader(i).Obj.Layout.Row = 1;
                    app.Objects.TContrastHeader(i).Obj.Layout.Column = i+2; % Title and sided are first 2
                end
            end
            % Create a label for each F-test
            for i = 1:size(app.State.Statistics.Ftests, 2)
                if app.ObjectExists('FContrastHeader', i)
                    % Object exists already, update the value only
                    UpdateProperty(app, app.Objects.FContrastHeader(i).Obj, 'Text', ['$$F_{', num2str(i),'}$$']);
                    app.Objects.FContrastHeader(i).Obj.Layout.Row = 1;
                    app.Objects.FContrastHeader(i).Obj.Layout.Column = i+2+size(app.State.Statistics.Ttests.Contrasts, 2);
                else
                    % Object does not exist, create
                    app.Objects.FContrastHeader(i).Obj = uilabel(app.StatsGridLayout, ...
                        'Text', ['$$F_{', num2str(i),'}$$'], ...
                        'Interpreter', 'latex', ...
                        'FontSize', 10, ...
                        'HorizontalAlignment', 'center');
                    app.Objects.FContrastHeader(i).Obj.Layout.Row = 1;
                    app.Objects.FContrastHeader(i).Obj.Layout.Column = i+2+size(app.State.Statistics.Ttests.Contrasts, 2);
                end
            end
        end
        % =========================================================
        % METHOD: Renders the T- and F-contrasts stats panel
        function RenderStatsPanel(app)
            % Render the contrast title input fields
            for i = 1:length(app.State.Statistics.Ttests.Titles)
                if app.ObjectExists('ContrastTitle', i)
                    % Object exists already, update the value only
                    UpdateProperty(app, app.Objects.ContrastTitle(i).Obj, 'Value', app.State.Statistics.Ttests.Titles{i});
                else
                    % Object does not exist, create
                    app.Objects.ContrastTitle(i).Obj = uieditfield(app.StatsGridLayout, 'text', ...
                        'Value', app.State.Statistics.Ttests.Titles{i}, ...
                        'ValueChangedFcn', createCallbackFcn(app, @TContrastTitleValueChanged, true));
                    app.Objects.ContrastTitle(i).Obj.Layout.Row = i+1;
                    app.Objects.ContrastTitle(i).Obj.Layout.Column = 1;
                    app.Objects.ContrastTitle(i).Obj.UserData.idx = i;
                end
            end
            % Render the sided buttons
            for i = 1:length(app.State.Statistics.Ttests.Sided)
                if app.ObjectExists('ContrastSided', i)
                    % Object exists already, update the value only
                    UpdateProperty(app, app.Objects.ContrastSided(i).Obj, 'Value', strcmpi(app.State.Statistics.Ttests.Sided{i}, 'two'));
                    UpdateProperty(app, app.Objects.ContrastSided(i).Obj, 'Text', app.State.Statistics.Ttests.Sided{i});
                else
                    % Object does not exist, create
                    app.Objects.ContrastSided(i).Obj = uibutton(app.StatsGridLayout, 'state', ...
                        'BackgroundColor', [0.4196 0.4588 0.4902], ...
                        'FontSize', 10, ...
                        'FontWeight', 'bold', ...
                        'FontColor', 'w', ...
                        'Text', app.State.Statistics.Ttests.Sided{i}, ...
                        'Value', strcmpi(app.State.Statistics.Ttests.Sided{i}, 'two'), ...
                        'ValueChangedFcn', createCallbackFcn(app, @TContrastSidedValueChanged, true));
                    app.Objects.ContrastSided(i).Obj.Layout.Row = i+1;
                    app.Objects.ContrastSided(i).Obj.Layout.Column = 2;
                    app.Objects.ContrastSided(i).Obj.UserData.idx = i;
                end
            end
            % Render the t-contrast input fields
            for i = 1:size(app.State.Statistics.Ttests.Contrasts, 1)
                for j = 1:size(app.State.Statistics.Ttests.Contrasts, 2)
                    idx = (i-1)*size(app.State.Statistics.Ttests.Contrasts, 2)+j;
                    if app.ObjectExists('TContrastValue', idx)
                        % Object exists already, update the value only
                        UpdateProperty(app, app.Objects.TContrastValue(idx).Obj, 'Value', app.State.Statistics.Ttests.Contrasts(i, j));
                        UpdateProperty(app, app.Objects.TContrastValue(idx).Obj, 'Tag', app.State.Model.PredNames{j});
                        UpdateProperty(app, app.Objects.TContrastValue(idx).Obj, 'Tooltip', app.State.Model.PredNames{j});
                        app.Objects.TContrastValue(idx).Obj.Layout.Row = i+1;
                        app.Objects.TContrastValue(idx).Obj.Layout.Column = j+2;
                        app.Objects.TContrastValue(idx).Obj.UserData.ContrastIdx = i;
                        app.Objects.TContrastValue(idx).Obj.UserData.PredictorIdx = j;
                    else
                        % Object does not exist, create
                        app.Objects.TContrastValue(idx).Obj = uieditfield(app.StatsGridLayout, 'numeric', ...
                            'Value', app.State.Statistics.Ttests.Contrasts(i, j), ...
                            'Tag', app.State.Model.PredNames{j}, ...
                            'Tooltip', app.State.Model.PredNames{j}, ...
                            'HorizontalAlignment', 'center', ...
                            'ValueChangedFcn', createCallbackFcn(app, @TContrastValueChanged, true));
                        app.Objects.TContrastValue(idx).Obj.Layout.Row = i+1;
                        app.Objects.TContrastValue(idx).Obj.Layout.Column = j+2;
                        app.Objects.TContrastValue(idx).Obj.UserData.ContrastIdx = i;
                        app.Objects.TContrastValue(idx).Obj.UserData.PredictorIdx = j;
                    end
                end
            end
            % Render the f-test checkboxes
            for i = 1:size(app.State.Statistics.Ftests, 2)
                for j = 1:size(app.State.Statistics.Ttests.Contrasts, 1)
                    idx = (i-1)*size(app.State.Statistics.Ttests.Contrasts, 1)+j;
                    if app.ObjectExists('FTestValue', idx)
                        % Object exists already, update the value only
                        UpdateProperty(app, app.Objects.FTestValue(idx).Obj, 'Value', app.State.Statistics.Ftests(j, i));
                        app.Objects.FTestValue(idx).Obj.Layout.Row = j+1;
                        app.Objects.FTestValue(idx).Obj.Layout.Column = i+2+size(app.State.Statistics.Ttests.Contrasts, 2);
                        app.Objects.FTestValue(idx).Obj.UserData.ContrastIdx = j;
                        app.Objects.FTestValue(idx).Obj.UserData.FtestIdx = i;
                    else
                        % Object does not exist, create
                        app.Objects.FTestValue(idx).Obj = uicheckbox(app.StatsGridLayout, ...
                            'Value', app.State.Statistics.Ftests(j, i), ...
                            'Tooltip', 'Check at least 2 T-contrasts', ...
                            'Text', '', ...
                            'ValueChangedFcn', createCallbackFcn(app, @FTestValueChanged, true));
                        app.Objects.FTestValue(idx).Obj.Layout.Row = j+1;
                        app.Objects.FTestValue(idx).Obj.Layout.Column = i+2+size(app.State.Statistics.Ttests.Contrasts, 2);
                        app.Objects.FTestValue(idx).Obj.UserData.ContrastIdx = j;
                        app.Objects.FTestValue(idx).Obj.UserData.FtestIdx = i;
                    end
                    % if unchecking this checkbox results in only 1 remaining checked box, then disable
                    if app.State.Statistics.Ftests(j, i) == 1 && sum(app.State.Statistics.Ftests(:, i)) <= 2
                        UpdateProperty(app, app.Objects.FTestValue(idx).Obj, 'Enable', 'on'); % TODO: Check this was 'off'
                    else
                        UpdateProperty(app, app.Objects.FTestValue(idx).Obj, 'Enable', 'on');
                    end
                end
            end
            % Reorder children
            if ~isfield(app.Objects, 'ContrastTitle')
                return
            end
            if isfield(app.Objects, 'FTestValue')
                Children = [...
                    app.SidedLabel, ...
                    app.TitleLabel, ...
                    [app.Objects.TContrastHeader.Obj], ...
                    [app.Objects.FContrastHeader.Obj], ...
                    [app.Objects.ContrastTitle.Obj], ...
                    [app.Objects.ContrastSided.Obj], ...
                    [app.Objects.TContrastValue.Obj], ...
                    [app.Objects.FTestValue.Obj], ...
                    ];
            else
                Children = [...
                    app.SidedLabel, ...
                    app.TitleLabel, ...
                    [app.Objects.TContrastHeader.Obj], ...
                    [app.Objects.ContrastTitle.Obj], ...
                    [app.Objects.ContrastSided.Obj], ...
                    [app.Objects.TContrastValue.Obj], ...
                    ];
            end
            app.StatsGridLayout.Children = Children(isvalid(Children));
        end
        % =========================================================
        % METHOD: Un-mounts objects that are no longer required
        function UnmountStatsGridLayoutChildren(app)
            Tags = arrayfun(@(obj) obj.Tag, app.StatsGridLayout.Children, 'UniformOutput', false);
            Rm = cellfun(@(t) ~isempty(t) & ~ismember(t, app.State.Model.PredNames), Tags);
            if any(Rm)
                delete(app.StatsGridLayout.Children(Rm))
            end
            % Remove invalid objects from the objects structure
            if isfield(app.Objects, 'ContrastTitle')
                app.Objects.ContrastTitle(~isvalid([app.Objects.ContrastTitle.Obj])) = [];
            end
            if isfield(app.Objects, 'ContrastSided')
                app.Objects.ContrastSided(~isvalid([app.Objects.ContrastSided.Obj])) = [];
            end
            if isfield(app.Objects, 'TContrastValue')
                app.Objects.TContrastValue(~isvalid([app.Objects.TContrastValue.Obj])) = [];
            end
            if isfield(app.Objects, 'FTestValue')
                Rm = ~isvalid([app.Objects.FTestValue.Obj]);
                app.Objects.FTestValue(Rm) = [];
            end
        end
        % =========================================================
        % METHOD: Renders the checkboxes to toggle permuting within and whole exchangeability blocks
        function RenderPermWithinWhole(app)
            UpdateProperty(app, app.PermWithinCheckbox, 'Value', app.State.PostStats.PermWithin);
            UpdateProperty(app, app.PermWholeCheckbox, 'Value', app.State.PostStats.PermWhole);
            if length(unique(app.State.Model.ExchangeabilityBlocks)) == 1
                UpdateProperty(app, app.PermWithinLabel, 'Enable', 'off');
                UpdateProperty(app, app.PermWholeLabel, 'Enable', 'off');
                UpdateProperty(app, app.PermWithinCheckbox, 'Enable', 'off');
                UpdateProperty(app, app.PermWholeCheckbox, 'Enable', 'off');
            else
                UpdateProperty(app, app.PermWithinLabel, 'Enable', 'on');
                UpdateProperty(app, app.PermWholeLabel, 'Enable', 'on');
                UpdateProperty(app, app.PermWithinCheckbox, 'Enable', 'on');
                UpdateProperty(app, app.PermWholeCheckbox, 'Enable', 'on');
            end
        end
        % =========================================================
        % METHOD: Renders the cluster alpha edit field
        function RenderClusterAlphaEditField(app)
            switch app.State.PostStats.Method
                case {'ClusterExtent', 'ClusterMass'}
                    UpdateProperty(app, app.ClusterAlphaLabel, 'Enable', 'on');
                    UpdateProperty(app, app.ClusterAlphaEditField, 'Enable', 'on');
                otherwise
                    UpdateProperty(app, app.ClusterAlphaLabel, 'Enable', 'off');
                    UpdateProperty(app, app.ClusterAlphaEditField, 'Enable', 'off');
            end
        end
        % =========================================================
        % METHOD: Renders the TFCE parameter inputs
        function RenderTFCEParameterFields(app)
            switch app.State.PostStats.Method
                case 'TFCE'
                    UpdateProperty(app, app.TFCE_ParamsLabel, 'Enable', 'on');
                    UpdateProperty(app, app.TFCE_E_Label, 'Enable', 'on');
                    UpdateProperty(app, app.TFCE_E_EditField, 'Enable', 'on');
                    UpdateProperty(app, app.TFCE_H_Label, 'Enable', 'on');
                    UpdateProperty(app, app.TFCE_H_EditField, 'Enable', 'on');
                otherwise
                    UpdateProperty(app, app.TFCE_ParamsLabel, 'Enable', 'off');
                    UpdateProperty(app, app.TFCE_E_Label, 'Enable', 'off');
                    UpdateProperty(app, app.TFCE_E_EditField, 'Enable', 'off');
                    UpdateProperty(app, app.TFCE_H_Label, 'Enable', 'off');
                    UpdateProperty(app, app.TFCE_H_EditField, 'Enable', 'off');
            end
        end
        % =========================================================
        % APP FUNCTIONS: SETS THE APP STATE
        % ---------------------------------------------------------
        % FUNCTION: Sets loading state
        function SetLoading(app, isLoading)
            app.State.isLoading = isLoading;
            if isLoading
                app.LoadingImage.Visible = 'on';
                app.UIFigure.Pointer = 'watch';
                app.MainPanel.Enable = 'off';
                drawnow();
            else
                app.LoadingImage.Visible = 'off';
                app.UIFigure.Pointer = 'arrow';
                app.MainPanel.Enable = 'on';
            end
        end

        % =========================================================
        % CALLBACK FUNCTIONS
        % =========================================================
        function VarNameDropDownMenuChanged(app, event)
            % ---------------------------------------------------------
            % Set State
            VarIdx = event.Source.UserData.Index;
            VarName = event.Value;
            % Check that the variable is not already in the model
            inModel = ismember(VarName, {app.State.Model.Predictors.Name});
            if inModel
                event.Source.Value = event.PreviousValue;
                sel = uiconfirm(app.UIFigure, 'This variable already exists in the model.', 'Warning', ...
                    'Options',{'OK'},...
                    'DefaultOption', 'OK', ...
                    'Icon', 'warning'); %#ok<NASGU>
                return
            end
            % If the new variable is numeric, change to a continuous variable
            if isnumeric(app.State.Phenotype.(VarName)) && length(unique(app.State.Phenotype.(VarName))) > 4
                Type = 'continuous';
                Standardize = 'demean';
                Coding = 'demean';
            else % Change to a categorical variable
                Type = 'categorical';
                Standardize = 'n/a';
                switch app.State.ModelType
                    case 'Two-sample unpaired t-test'
                        Coding = 'Treatment';
                    otherwise
                        Coding = 'Sigma';
                end
            end
            % Get the design matrix for this variable
            [DesMat, Levels, Ref] = app.GetVarDesMat(event.Value, Type, Coding);
            % Update the predictors's state
            PrevId = app.State.Model.Predictors(VarIdx).Id;
            app.State.Model.Predictors(VarIdx).Id = app.GetId();
            app.State.Model.Predictors(VarIdx).Name = event.Value;
            app.State.Model.Predictors(VarIdx).Type = Type;
            app.State.Model.Predictors(VarIdx).Levels = Levels;
            app.State.Model.Predictors(VarIdx).Reference = Ref;
            app.State.Model.Predictors(VarIdx).DesMat = DesMat;
            app.State.Model.Predictors(VarIdx).Standardize = Standardize;
            % Update any interaction terms that contains this variable
            for i = 1:length(app.State.Model.Interactions)
                idx = app.State.Model.Interactions(i).PredIds == PrevId;
                if any(idx)
                    app.State.Model.Interactions(i).PredIds(idx) = app.State.Model.Predictors(VarIdx).Id;
                    [DesMat, Terms] = GetIntxDesMat(app, i);
                    app.State.Model.Interactions(i).DesMat = DesMat;
                    app.State.Model.Interactions(i).Terms = Terms;
                end
            end
            % Set the model specification
            [app.State.Model.ModelSpec, app.State.Model.PredNames] = app.GetModelSpec(); % Must be done after setting the predictors
            % ---------------------------------------------------------
            % Render
            app.SetLoading(true);
            app.RenderModelSpecLabel();
            app.RenderVariablesTypeInput();
            app.RenderVariablesStandardizeInput();
            app.RenderVariablesReferenceInput();
            app.RenderVariablesInteractInput();
            app.RenderDesignMatrixGridLayout();
            for i = VarIdx:length(app.State.Model.Predictors)
                app.RenderDesignMatrixPredNameLabel(i);
                app.RenderDesignMatrixPredValueInput(i);
            end
            for i = 1:length(app.State.Model.Interactions)
                app.RenderDesignMatrixIntxNameLabel(i);
                app.RenderDesignMatrixIntxValueInput(i);
            end
            for i = 1:length(app.State.Model.RandVars)
                app.RenderDesignMatrixRandVarNameLabel(i);
                app.RenderDesignMatrixRandVarValueInput(i);
            end
            app.SetLoading(false);
        end
        % =========================================================
        function VarTypeDropDownMenuChanged(app, event)
            % ---------------------------------------------------------
            % Set State
            VarIdx = event.Source.UserData.Index;
            VarName = app.State.Model.Predictors(VarIdx).Name;
            % Set the standardize value
            switch event.Value
                case 'continuous'
                    Standardize = 'demean';
                    Coding = 'demean';
                case 'categorical'
                    Standardize = 'n/a';
                    switch app.State.ModelType
                        case 'Two-sample unpaired t-test'
                            Coding = 'Treatment';
                        otherwise
                            Coding = 'Sigma';
                    end
            end
            % Get the design matrix for this variable
            [DesMat, Levels, Ref] = app.GetVarDesMat(VarName, event.Value, Coding);
            PrevId = app.State.Model.Predictors(VarIdx).Id;
            app.State.Model.Predictors(VarIdx).Id = app.GetId();
            app.State.Model.Predictors(VarIdx).Type = event.Value;
            app.State.Model.Predictors(VarIdx).Standardize = Standardize;
            app.State.Model.Predictors(VarIdx).DesMat = DesMat;
            app.State.Model.Predictors(VarIdx).Reference = Ref;
            app.State.Model.Predictors(VarIdx).Levels = Levels;
            % Update any interaction terms that contains this variable
            for i = 1:length(app.State.Model.Interactions)
                idx = app.State.Model.Interactions(i).PredIds == PrevId;
                if any(idx)
                    app.State.Model.Interactions(i).PredIds(idx) = app.State.Model.Predictors(VarIdx).Id;
                    [DesMat, Terms] = GetIntxDesMat(app, i);
                    app.State.Model.Interactions(i).DesMat = DesMat;
                    app.State.Model.Interactions(i).Terms = Terms;
                end
            end
            % Set the model specification
            [app.State.Model.ModelSpec, app.State.Model.PredNames] = app.GetModelSpec(); % Must be done after setting the predictors
            % ---------------------------------------------------------
            % Render
            app.SetLoading(true);
            app.RenderModelSpecLabel();
            app.RenderVariablesStandardizeInput();
            app.RenderVariablesReferenceInput();
            app.RenderVariablesInteractInput();
            app.RenderDesignMatrixGridLayout();
            for i = VarIdx:length(app.State.Model.Predictors)
                app.RenderDesignMatrixPredNameLabel(i);
                app.RenderDesignMatrixPredValueInput(i);
            end
            for i = 1:length(app.State.Model.Interactions)
                app.RenderDesignMatrixIntxNameLabel(i);
                app.RenderDesignMatrixIntxValueInput(i);
            end
            for i = 1:length(app.State.Model.RandVars)
                app.RenderDesignMatrixRandVarNameLabel(i);
                app.RenderDesignMatrixRandVarValueInput(i);
            end
            app.SetLoading(false);
        end
        % =========================================================
        function VarStandardizeDropDownMenuChanged(app, event)
            % ---------------------------------------------------------
            % Set State
            VarIdx = event.Source.UserData.Index;
            VarName = app.State.Model.Predictors(VarIdx).Name;
            VarType = app.State.Model.Predictors(VarIdx).Type;
            % Get the design matrix for this variable
            DesMat = app.GetVarDesMat(VarName, VarType, event.Value);
            app.State.Model.Predictors(VarIdx).Standardize = event.Value;
            app.State.Model.Predictors(VarIdx).DesMat = DesMat;
            % Update any interaction terms that contains this variable
            IntxIdxs = [];
            for i = 1:length(app.State.Model.Interactions)
                idx = app.State.Model.Interactions(i).PredIds == app.State.Model.Predictors(VarIdx).Id;
                if any(idx)
                    IntxIdxs = [IntxIdxs, i]; %#ok<AGROW>
                    [DesMat, Terms] = GetIntxDesMat(app, i);
                    app.State.Model.Interactions(i).DesMat = DesMat;
                    app.State.Model.Interactions(i).Terms = Terms;
                end
            end
            % ---------------------------------------------------------
            % Render
            app.RenderDesignMatrixPredValueInput(VarIdx);
            for i = 1:length(IntxIdxs)
                app.RenderDesignMatrixIntxNameLabel(IntxIdxs(i));
                app.RenderDesignMatrixIntxValueInput(IntxIdxs(i));
            end
        end
        % =========================================================
        function VarReferenceDropDownMenuChanged(app, event)
            % ---------------------------------------------------------
            % Set State
            VarIdx = event.Source.UserData.Index;
            VarName = app.State.Model.Predictors(VarIdx).Name;
            VarType = app.State.Model.Predictors(VarIdx).Type;
            DoResetStats = false;
            % Get the design matrix for this variable
            switch app.State.ModelType
                case 'Two-sample unpaired t-test'
                    % For the two-sample t-test, we're using a 'treatment' coding of the design matrix
                    [DesMat, Levels, Ref] = app.GetVarDesMat(VarName, VarType, 'treatment', event.Value);
                case 'One-way ANOVA'
                    % If the reference group of the main factor of a one-way ANOVA is changed, then update the statistics
                    [DesMat, Levels, Ref] = app.GetVarDesMat(VarName, VarType, 'sigma', event.Value);
                    if VarIdx == 1
                        DoResetStats = true;
                    end
                case 'Two-way ANOVA'
                    % If the reference group of either factor of a two-way ANOVA is changed, then update the statistics
                    [DesMat, Levels, Ref] = app.GetVarDesMat(VarName, VarType, 'sigma', event.Value);
                    if VarIdx <= 2
                        DoResetStats = true;
                    end
                otherwise
                    [DesMat, Levels, Ref] = app.GetVarDesMat(VarName, VarType, 'sigma', event.Value);
            end
            app.State.Model.Predictors(VarIdx).Standardize = event.Value;
            app.State.Model.Predictors(VarIdx).DesMat = DesMat;
            app.State.Model.Predictors(VarIdx).Levels = Levels;
            app.State.Model.Predictors(VarIdx).Reference = Ref;
            % Update any interaction terms that contains this variable
            IntxIdxs = [];
            for i = 1:length(app.State.Model.Interactions)
                idx = app.State.Model.Interactions(i).PredIds == app.State.Model.Predictors(VarIdx).Id;
                if any(idx)
                    IntxIdxs = [IntxIdxs, i]; %#ok<AGROW>
                    [DesMat, Terms] = GetIntxDesMat(app, i);
                    app.State.Model.Interactions(i).DesMat = DesMat;
                    app.State.Model.Interactions(i).Terms = Terms;
                end
            end
            % Set the model specification
            [app.State.Model.ModelSpec, app.State.Model.PredNames] = app.GetModelSpec(); % Must be done after setting the predictors
            % Reset stats if needed
            if DoResetStats
                switch app.State.ModelType
                    case 'One-way ANOVA'
                        app.InitOneWayAnovaStats(Ref, Levels);
                    case 'Two-way ANOVA'
                        app.InitTwoWayAnovaStats();
                end
            end
            % ---------------------------------------------------------
            % Render
            app.RenderModelSpecLabel();
            app.RenderDesignMatrixPredValueInput(VarIdx);
            app.RenderDesignMatrixPredNameLabel(VarIdx);
            for i = 1:length(IntxIdxs)
                app.RenderDesignMatrixIntxNameLabel(IntxIdxs(i));
                app.RenderDesignMatrixIntxValueInput(IntxIdxs(i));
            end
        end
        % =========================================================
        function VarIntxCheckboxValueChanged(app, event)
            % ---------------------------------------------------------
            % Set State
            PredIdx = event.Source.UserData.Pred;
            PredId = app.State.Model.Predictors(PredIdx).Id;
            IntxIdx = event.Source.UserData.Intx;
            if event.Source.Value
                % Add predictor to list of interaction terms
                app.State.Model.Interactions(IntxIdx).PredIds(end+1) = PredId;
            else
                % Remove predictor from list of interaction terms
                idx = app.State.Model.Interactions(IntxIdx).PredIds == PredId;
                app.State.Model.Interactions(IntxIdx).PredIds(idx) = [];
            end
            [DesMat, Terms] = GetIntxDesMat(app, IntxIdx);
            app.State.Model.Interactions(IntxIdx).DesMat = DesMat;
            app.State.Model.Interactions(IntxIdx).Terms = Terms;
            [app.State.Model.ModelSpec, app.State.Model.PredNames] = app.GetModelSpec(); % Must be done after setting the predictors
            % ---------------------------------------------------------
            % Render
            app.SetLoading(true);
            app.RenderModelSpecLabel();
            app.RenderVariablesInteractInput();
            app.RenderDesignMatrixGridLayout();
            app.RenderDesignMatrixIntxNameLabel(IntxIdx);
            app.RenderDesignMatrixIntxValueInput(IntxIdx);
            for i = 1:length(app.State.Model.RandVars)
                app.RenderDesignMatrixRandVarNameLabel(i);
                app.RenderDesignMatrixRandVarValueInput(i);
            end
            app.SetLoading(false);
        end
        % =========================================================
        function ExchangeabilityBlocksValueChanged(app, event)
            % ---------------------------------------------------------
            % Set State
            app.State.Model.ExchangeabilityBlocks(event.Source.UserData.idx) = event.Source.Value;
            % If there is only one EB, then force permutations within and whole
            if length(unique(app.State.Model.ExchangeabilityBlocks)) == 1
                app.State.PostStats.PermWithin = true;
                app.State.PostStats.PermWhole = true;
            end
            % ---------------------------------------------------------
            % Render
            app.RenderPermWithinWhole();
        end
        % =========================================================
        function VarianceGroupsValueChanged(app, event)
            % ---------------------------------------------------------
            % Set State
            app.State.Model.VarianceGroups(event.Source.UserData.idx) = event.Source.Value;
        end
        % =========================================================
        function TContrastTitleValueChanged(app, event)
            % ---------------------------------------------------------
            % Set State
            app.State.Statistics.Ttests.Titles{event.Source.UserData.idx} = event.Source.Value;
        end
        % =========================================================
        function TContrastSidedValueChanged(app, event)
            % ---------------------------------------------------------
            % Set State
            if event.Source.Value
                app.State.Statistics.Ttests.Sided{event.Source.UserData.idx} = 'two';
            else
                app.State.Statistics.Ttests.Sided{event.Source.UserData.idx} = 'one';
            end
            % ---------------------------------------------------------
            % Render
            app.RenderStatsPanel();
        end
        % =========================================================
        function TContrastValueChanged(app, event)
            % ---------------------------------------------------------
            % Set State
            i = event.Source.UserData.ContrastIdx;
            j = event.Source.UserData.PredictorIdx;
            app.State.Statistics.Ttests.Contrasts(i, j) = event.Source.Value;
        end
        % =========================================================
        function FTestValueChanged(app, event)
            % ---------------------------------------------------------
            % Set State
            i = event.Source.UserData.ContrastIdx;
            j = event.Source.UserData.FtestIdx;
            app.State.Statistics.Ftests(i, j) = event.Source.Value;
            % ---------------------------------------------------------
            % Render
            app.RenderStatsPanel();
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MainApp, FileList, ModelType)
            % ---------------------------------------------------------
            % Store main app object
            if exist('MainApp', 'var')
                app.MainApp = MainApp;
                % Position the new app relative to the main app
                app_positionGUI(app.MainApp, app.UIFigure)
                % Show waitbar to indicate another gui is open
                app.InterruptDialog = uiprogressdlg(app.MainApp.UIFigure);
            else
                app.MainApp = struct();
            end
            % Clear the import structure
            app.MainApp.Import = struct();
            app.MainApp.Import.Return = false;
            % Set the figure title
            app.UIFigure.Name = ModelType;
            % ---------------------------------------------------------
            % Add icons to components
            app.LoadingImage.ImageSource = 'loading.gif';
            app.OutputDirectoryBrowseButton.Icon = 'icon_browse.png';
            % ---------------------------------------------------------
            % Initialize State
            app.SetLoading(true);
            app.InitializeState(FileList, ModelType);
            if ~isvalid(app)
                return
            end
            app.SetLoading(false);
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            app.MainApp.Import.Return = false;
            % Delete the dialog box
            if isvalid(app)
                delete(app);
            end
        end

        % Button pushed function: AddProcessButton
        function AddProcessButtonPushed(app, event)
            % Before we return, add columns of zeros to the contrast matrix
            % for each random intercept
            switch app.State.ModelType
                case 'Two-sample paired t-test'
                    Add = zeros(size(app.State.Statistics.Ttests.Contrasts, 1), size([app.State.Model.RandVars.DesMat], 2));
                otherwise
                    Add = [];
            end
            app.State.Statistics.Ttests.Contrasts = [app.State.Statistics.Ttests.Contrasts, Add];
            % Return to main app
            app.MainApp.Import.Return = true;
            app.MainApp.Import.Data = app.State;
            % Delete the dialog box
            if isvalid(app)
                delete(app);
            end
        end

        % Button pushed function: OutputDirectoryBrowseButton
        function OutputDirectoryBrowseButtonPushed(app, event)
            % ---------------------------------------------------------
            % Disable button
            event.Source.Enable = 'off';
            % ---------------------------------------------------------
            % Update state
            FullFilePath = uigetdir(path, 'Select a file to import');
            if FullFilePath == 0
                return
            end
            app.State.OutputDir = FullFilePath;
            % ---------------------------------------------------------
            % Render
            app.RenderOutputDirectoryValue();
            app.RenderAddProcessButton();
            drawnow();
            event.Source.Enable = 'on';
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            % ---------------------------------------------------------
            % Update state
            switch app.TabGroup.SelectedTab.Title
                case 'Statistics'
                    % Check if we have an intercept or not
                    switch app.State.ModelType
                        case 'Two-sample paired t-test'
                            Intercept = [];
                        otherwise
                            Intercept = ones(size(app.State.Input, 1), 1);
                    end
                    % Set the T- and F-contrasts
                    DesMat = [Intercept, [app.State.Model.Predictors.DesMat], [app.State.Model.Interactions.DesMat]];
                    AddCols = size(DesMat, 2) - size(app.State.Statistics.Ttests.Contrasts, 2);
                    if AddCols > 0
                        app.State.Statistics.Ttests.Contrasts = [app.State.Statistics.Ttests.Contrasts, zeros(size(app.State.Statistics.Ttests.Contrasts, 1), AddCols)];
                    else
                        app.State.Statistics.Ttests.Contrasts = app.State.Statistics.Ttests.Contrasts(:, 1:size(DesMat, 2));
                    end
                otherwise
                    return
            end
            % ---------------------------------------------------------
            % Render
            app.StatsPanel.Enable = 'off';
            drawnow();
            app.RenderTcontrastsSpinner();
            app.RenderFtestsSpinner();
            app.UnmountStatsGridLayoutChildren();
            app.RenderStatsGridLayout();
            app.RenderStatsPanelHeader();
            app.RenderStatsPanel();
            drawnow();
            app.StatsPanel.Enable = 'on';
        end

        % Value changed function: ModelSpecNumEVsInput
        function ModelSpecNumEVsInputValueChanged(app, event)
            % ---------------------------------------------------------
            % Update state
            if app.ModelSpecNumEVsInput.Value > length(app.State.Model.Predictors)
                % ---------------------------------------------------------
                % ... the number of predictors increased
                % ---------------------------------------------------------
                % How many predictors should we add?
                NumToAdd = app.ModelSpecNumEVsInput.Value - length(app.State.Model.Predictors);
                VarIdxs = [];
                % ---------------------------------------------------------
                for i = 1:NumToAdd
                    % ---------------------------------------------------------
                    % Check that there are more variables in the data table
                    VarNames = app.State.Phenotype.Properties.VariableNames;
                    inModel = ismember(VarNames, [{'participant_id'}, {app.State.Model.Predictors.Name}]);
                    if all(inModel)
                        % No more variables in the data table that are not
                        % already in the model
                        event.Source.Value = event.PreviousValue + i - 1;
                        sel = uiconfirm(app.UIFigure, 'There are no more variables in the phenotype files. No more predictors added.', 'Warning', ...
                            'Options',{'OK'},...
                            'DefaultOption', 'OK', ...
                            'Icon', 'warning'); %#ok<NASGU>
                        break
                    end
                    % Check if there are more than 2 levels in this variable, or select the next one
                    SelIdx = find(~inModel, 1, 'first');
                    if isnumeric(app.State.Phenotype.(VarNames{SelIdx})) && length(unique(app.State.Phenotype.(VarNames{SelIdx}))) > 4
                        Type = 'continuous';
                        [DesMat, Levels, Ref] = app.GetVarDesMat(VarNames{SelIdx}, 'continuous', 'demean');
                    else
                        Type = 'categorical';
                        switch app.State.ModelType
                            case 'Two-sample unpaired t-test'
                                [DesMat, Levels, Ref] = app.GetVarDesMat(VarNames{SelIdx}, 'categorical', 'treatment');
                            otherwise
                                [DesMat, Levels, Ref] = app.GetVarDesMat(VarNames{SelIdx}, 'categorical', 'sigma');
                        end
                    end
                    % ---------------------------------------------------------
                    % Get the label of the new term
                    NewPred = VarNames{SelIdx};
                    % and the index of the new predictor
                    VarIdx = length(app.State.Model.Predictors)+1;
                    VarIdxs = [VarIdxs, VarIdx]; %#ok<AGROW>
                    % and append the term to the predictors array
                    app.State.Model.Predictors(VarIdx).Id = app.GetId();
                    app.State.Model.Predictors(VarIdx).Name = NewPred;
                    % Check what type of variable it is, and set the state accordingly
                    app.State.Model.Predictors(VarIdx).Type = Type;
                    switch Type
                        case 'categorical'
                            app.State.Model.Predictors(VarIdx).Standardize = 'n/a';
                        case 'continuous'
                            app.State.Model.Predictors(VarIdx).Standardize = 'demean';
                    end
                    app.State.Model.Predictors(VarIdx).DesMat = DesMat;
                    app.State.Model.Predictors(VarIdx).Levels = Levels;
                    app.State.Model.Predictors(VarIdx).Reference = Ref;
                    app.State.Model.Predictors(VarIdx).Enable = 'on';
                end
            else
                % ---------------------------------------------------------
                % The number of predictors decreased, simply crop the predictors array
                VarIdxs = 0;
                app.State.Model.Predictors = app.State.Model.Predictors(1:app.ModelSpecNumEVsInput.Value);
            end
            % ---------------------------------------------------------
            % Check if we're still allowed to have all interactions
            m = app.GetMaxNumIntx();
            if length(app.State.Model.Interactions) > m
                app.State.Model.Interactions = app.State.Model.Interactions(1:m);
            end
            % ---------------------------------------------------------
            % Set the new model specification
            [app.State.Model.ModelSpec, app.State.Model.PredNames] = app.GetModelSpec(); % Must be done after setting the predictors
            % ---------------------------------------------------------
            % Render
            app.SetLoading(true);
            app.RenderModelSpecNumIntxInput();
            app.RenderModelSpecLabel();
            app.RenderVariablesGridLayout();
            app.RenderVariablesNameInput();
            app.RenderVariablesTypeInput();
            app.RenderVariablesStandardizeInput();
            app.RenderVariablesInteractInput();
            app.RenderVariablesReferenceInput();
            app.RenderDesignMatrixGridLayout();
            for i = 1:length(VarIdxs)
                app.RenderDesignMatrixPredNameLabel(VarIdxs(i))
                app.RenderDesignMatrixPredValueInput(VarIdxs(i));
            end
            for i = 1:length(app.State.Model.Interactions)
                app.RenderDesignMatrixIntxNameLabel(i);
                app.RenderDesignMatrixIntxValueInput(i);
            end
            for i = 1:length(app.State.Model.RandVars)
                app.RenderDesignMatrixRandVarNameLabel(i);
                app.RenderDesignMatrixRandVarValueInput(i);
            end
            app.SetLoading(false);
        end

        % Value changed function: ModelSpecNumIntxInput
        function ModelSpecNumIntxInputValueChanged(app, event)
            % ---------------------------------------------------------
            % Update state
            if app.ModelSpecNumIntxInput.Value > length(app.State.Model.Interactions)
                % ---------------------------------------------------------
                % ... the number of predictors increased
                % ---------------------------------------------------------
                % How many predictors should we add?
                NumToAdd = app.ModelSpecNumIntxInput.Value - length(app.State.Model.Interactions);
                for i = 1:NumToAdd
                    app.State.Model.Interactions(end+1).Id = app.GetId();
                    app.State.Model.Interactions(end).PredIds = zeros(0);
                end
            else
                % ---------------------------------------------------------
                % The number of predictors decreased, simply crop the interactions array
                app.State.Model.Interactions = app.State.Model.Interactions(1:app.ModelSpecNumIntxInput.Value);
            end
            % ---------------------------------------------------------
            % Set the new model specification
            [app.State.Model.ModelSpec, app.State.Model.PredNames] = app.GetModelSpec(); % Must be done after setting the predictors
            % ---------------------------------------------------------
            % Render
            app.SetLoading(true);
            app.RenderModelSpecLabel();
            app.RenderVariablesGridLayout();
            app.RenderVariablesInteractInput();
            app.RenderDesignMatrixGridLayout();
            app.SetLoading(false);
        end

        % Value changed function: TcontrastsSpinner
        function TcontrastsSpinnerValueChanged(app, event)
            % ---------------------------------------------------------
            % Update state
            value = event.Source.Value;
            if value < length(app.State.Statistics.Ttests.Titles)
                app.State.Statistics.Ttests.Titles = app.State.Statistics.Ttests.Titles(1:value);
                app.State.Statistics.Ttests.Sided = app.State.Statistics.Ttests.Sided(1:value);
                app.State.Statistics.Ttests.Contrasts = app.State.Statistics.Ttests.Contrasts(1:value, :);
                app.State.Statistics.Ftests = app.State.Statistics.Ftests(1:value, :);
                m = app.GetMaxFtests();
                if size(app.State.Statistics.Ftests, 2) > m
                    app.State.Statistics.Ftests = app.State.Statistics.Ftests(:, 1:m);
                end
                if size(app.State.Statistics.Ftests, 2) > 0
                    rm_idx = sum(app.State.Statistics.Ftests) <= 1;
                    app.State.Statistics.Ftests(:, rm_idx) = [];
                end
            else
                % Check if we have an intercept or not
                switch app.State.ModelType
                    case 'Two-sample paired t-test'
                        Intercept = [];
                    otherwise
                        Intercept = ones(size(app.State.Input, 1), 1);
                end
                % How many columns in the desmat?
                DesMat = [Intercept, [app.State.Model.Predictors.DesMat], [app.State.Model.Interactions.DesMat]];
                NumCols = size(DesMat, 2);
                CurRows = size(app.State.Statistics.Ttests.Contrasts, 1);
                NumRows = length(CurRows+1:value);
                app.State.Statistics.Ttests.Titles = [app.State.Statistics.Ttests.Titles, arrayfun(@(c) sprintf('c%i', c), CurRows+1:value, 'UniformOutput', false)];
                app.State.Statistics.Ttests.Sided = [app.State.Statistics.Ttests.Sided, arrayfun(@(c) 'two', CurRows+1:value, 'UniformOutput', false)];
                app.State.Statistics.Ttests.Contrasts = [app.State.Statistics.Ttests.Contrasts; zeros(NumRows, NumCols)];
                app.State.Statistics.Ftests = [app.State.Statistics.Ftests; zeros(NumRows, size(app.State.Statistics.Ftests, 2))];
            end
            % ---------------------------------------------------------
            % Render
            app.StatsPanel.Enable = 'off';
            drawnow();
            app.RenderStatsGridLayout();
            app.RenderStatsPanel();
            app.RenderFtestsSpinner();
            drawnow();
            app.StatsPanel.Enable = 'on';
        end

        % Value changed function: FtestsSpinner
        function FtestsSpinnerValueChanged(app, event)
            % ---------------------------------------------------------
            % Update state
            clc
            value = event.Source.Value;
            if value < size(app.State.Statistics.Ftests, 2)
                app.State.Statistics.Ftests = app.State.Statistics.Ftests(:, 1:value);
            else
                NumTTests = length(app.State.Statistics.Ttests.Titles);
                NumCols = length(size(app.State.Statistics.Ftests, 2)+1:value);
                Add = zeros(NumTTests, NumCols);
                [~, B] = app.GetMaxFtests();
                for i = size(B, 1):-1:1
                    for j = 1:size(app.State.Statistics.Ftests, 2)
                        if all(app.State.Statistics.Ftests(B{i, :}, j)) && sum(app.State.Statistics.Ftests(:, j)) == length(B{i, :})
                            B(i) = [];
                            break
                        end
                    end
                end
                
                for i = 1:size(Add, 2)
                    Add(B{i, :}, i) = 1;
                end
                app.State.Statistics.Ftests = [app.State.Statistics.Ftests, Add];
            end
            % ---------------------------------------------------------
            % Render
            app.StatsPanel.Enable = 'off';
            drawnow();
            app.RenderStatsGridLayout();
            app.RenderStatsPanelHeader();
            app.RenderStatsPanel();
            drawnow();
            app.StatsPanel.Enable = 'on';
        end

        % Value changed function: DoPermsCheckbox
        function DoPermsCheckboxValueChanged(app, event)
            % ---------------------------------------------------------
            % Update state
            app.State.PostStats.DoPermutations = event.Source.Value;
            % ---------------------------------------------------------
            % Render here in place for simplicity
            if ~app.State.PostStats.DoPermutations
                UpdateProperty(app, app.DoSignFlipsCheckbox, 'Value', true);
                UpdateProperty(app, app.DoSignFlipsCheckbox, 'Enable', 'off');
            else
                UpdateProperty(app, app.DoSignFlipsCheckbox, 'Enable', 'on');
            end
        end

        % Value changed function: DoSignFlipsCheckbox
        function DoSignFlipsCheckboxValueChanged(app, event)
            % ---------------------------------------------------------
            % Update state
            app.State.PostStats.DoSignFlips = event.Source.Value;
            % ---------------------------------------------------------
            % Render here in place for simplicity
            if ~app.State.PostStats.DoSignFlips
                UpdateProperty(app, app.DoPermsCheckbox, 'Value', true);
                UpdateProperty(app, app.DoPermsCheckbox, 'Enable', 'off');
            else
                UpdateProperty(app, app.DoPermsCheckbox, 'Enable', 'on');
            end
        end

        % Value changed function: PermWithinCheckbox
        function PermWithinCheckboxValueChanged(app, event)
            % ---------------------------------------------------------
            % Update state
            app.State.PostStats.PermWithin = event.Source.Value;
        end

        % Value changed function: PermWholeCheckbox
        function PermWholeCheckboxValueChanged(app, event)
            % ---------------------------------------------------------
            % Update state
            app.State.PostStats.PermWhole = event.Source.Value;
        end

        % Value changed function: NumPermutationsEditField
        function NumPermutationsEditFieldValueChanged(app, event)
            % ---------------------------------------------------------
            % Update state
            app.State.PostStats.NumPermutations = event.Source.Value;
        end

        % Value changed function: ThresholdingMethodDropDown
        function ThresholdingMethodDropDownValueChanged(app, event)
            % ---------------------------------------------------------
            % Update state
            app.State.PostStats.Method = event.Source.Value;
            % ---------------------------------------------------------
            % Render
            app.RenderClusterAlphaEditField();
            app.RenderTFCEParameterFields();
        end

        % Value changed function: ChannelWiseAlphaEditField
        function ChannelWiseAlphaEditFieldValueChanged(app, event)
            % ---------------------------------------------------------
            % Update state
            app.State.PostStats.ChanWiseAlpha = event.Source.Value;
        end

        % Value changed function: ClusterAlphaEditField
        function ClusterAlphaEditFieldValueChanged(app, event)
            % ---------------------------------------------------------
            % Update state
            app.State.PostStats.ClusterAlpha = event.Source.Value;
        end

        % Value changed function: TFCE_E_EditField
        function TFCE_E_EditFieldValueChanged(app, event)
            % ---------------------------------------------------------
            % Update state
            app.State.PostStats.TFCE_E = event.Source.Value;
        end

        % Value changed function: TFCE_H_EditField
        function TFCE_H_EditFieldValueChanged(app, event)
            % ---------------------------------------------------------
            % Update state
            app.State.PostStats.TFCE_H = event.Source.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'General Linear Model';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.CancelButton = uibutton(app.GridLayout, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.AddProcessButton = uibutton(app.GridLayout, 'push');
            app.AddProcessButton.ButtonPushedFcn = createCallbackFcn(app, @AddProcessButtonPushed, true);
            app.AddProcessButton.Enable = 'off';
            app.AddProcessButton.Text = 'Add Process';
            app.LoadingImage = uiimage(app.GridLayout);
            app.LoadingImage.Visible = 'off';
            app.MainPanel = uipanel(app.GridLayout);
            app.MainGridLayout = uigridlayout(app.MainPanel);
            app.OutputDirectoryPanel = uipanel(app.MainGridLayout);
            app.OutputDirectoryPanel.Title = 'Output directory';
            app.OutputDirectoryGridLayout = uigridlayout(app.OutputDirectoryPanel);
            app.OutputDirectoryBrowseButton = uibutton(app.OutputDirectoryGridLayout, 'push');
            app.OutputDirectoryBrowseButton.ButtonPushedFcn = createCallbackFcn(app, @OutputDirectoryBrowseButtonPushed, true);
            app.OutputDirectoryBrowseButton.Text = '';
            app.OutputDirectoryValue = uieditfield(app.OutputDirectoryGridLayout, 'text');
            app.OutputDirectoryValue.Editable = 'off';
            app.TabGroup = uitabgroup(app.MainGridLayout);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.ModelSpecTab = uitab(app.TabGroup);
            app.ModelSpecTab.Title = 'Model specification';
            app.ModelSpecTabGridLayout = uigridlayout(app.ModelSpecTab);
            app.DesignMatrixPanel = uipanel(app.ModelSpecTabGridLayout);
            app.DesignMatrixPanel.Title = 'Design matrix';
            app.DesignMatrixGridLayout = uigridlayout(app.DesignMatrixPanel);
            app.DesignMatrixInterceptLabel = uilabel(app.DesignMatrixGridLayout);
            app.DesignMatrixInterceptLabel.Interpreter = 'latex';
            app.DesignMatrixInterceptLabel.Text = '$$\beta_{0}$$';
            app.DesignMatrixInputLabel = uilabel(app.DesignMatrixGridLayout);
            app.DesignMatrixInputLabel.Text = 'Input';
            app.DesignMatrixVGLabel = uilabel(app.DesignMatrixGridLayout);
            app.DesignMatrixVGLabel.Text = 'VG';
            app.DesignMatrixEBLabel = uilabel(app.DesignMatrixGridLayout);
            app.DesignMatrixEBLabel.Text = 'EB';
            app.VariablesPanel = uipanel(app.ModelSpecTabGridLayout);
            app.VariablesGridLayout = uigridlayout(app.VariablesPanel);
            app.VariablesInteractLabel = uilabel(app.VariablesGridLayout);
            app.VariablesInteractLabel.Enable = 'off';
            app.VariablesInteractLabel.Text = 'Interactions';
            app.VariablesReferenceLabel = uilabel(app.VariablesGridLayout);
            app.VariablesReferenceLabel.Enable = 'off';
            app.VariablesReferenceLabel.Text = 'Reference';
            app.VariablesStandardizeLabel = uilabel(app.VariablesGridLayout);
            app.VariablesStandardizeLabel.Text = 'Standardize';
            app.VariablesTypeLabel = uilabel(app.VariablesGridLayout);
            app.VariablesTypeLabel.Enable = 'off';
            app.VariablesTypeLabel.Text = 'Type';
            app.VariablesNameLabel = uilabel(app.VariablesGridLayout);
            app.VariablesNameLabel.Text = 'Variable';
            app.ModelSpecValue = uilabel(app.ModelSpecTabGridLayout);
            app.ModelSpecValue.Text = 'Y ~ 1';
            app.NumberofpredictorsLabel_2 = uilabel(app.ModelSpecTabGridLayout);
            app.NumberofpredictorsLabel_2.Text = 'Number of interactions';
            app.ModelSpecNumIntxInput = uispinner(app.ModelSpecTabGridLayout);
            app.ModelSpecNumIntxInput.Limits = [0 50];
            app.ModelSpecNumIntxInput.ValueChangedFcn = createCallbackFcn(app, @ModelSpecNumIntxInputValueChanged, true);
            app.NumberofpredictorsLabel_3 = uilabel(app.ModelSpecTabGridLayout);
            app.NumberofpredictorsLabel_3.Text = 'Number of factors';
            app.ModelSpecNumEVsInput = uispinner(app.ModelSpecTabGridLayout);
            app.ModelSpecNumEVsInput.Limits = [0 50];
            app.ModelSpecNumEVsInput.ValueChangedFcn = createCallbackFcn(app, @ModelSpecNumEVsInputValueChanged, true);
            app.StatsTab = uitab(app.TabGroup);
            app.StatsTab.Title = 'Statistics';
            app.StatsTabGridLayout = uigridlayout(app.StatsTab);
            app.TcontrastsSpinnerLabel = uilabel(app.StatsTabGridLayout);
            app.TcontrastsSpinnerLabel.Text = 'T-contrasts';
            app.TcontrastsSpinner = uispinner(app.StatsTabGridLayout);
            app.TcontrastsSpinner.Limits = [1 50];
            app.TcontrastsSpinner.ValueChangedFcn = createCallbackFcn(app, @TcontrastsSpinnerValueChanged, true);
            app.TcontrastsSpinner.Value = 1;
            app.FtestsSpinnerLabel = uilabel(app.StatsTabGridLayout);
            app.FtestsSpinnerLabel.Text = 'F-tests';
            app.FtestsSpinner = uispinner(app.StatsTabGridLayout);
            app.FtestsSpinner.Limits = [0 50];
            app.FtestsSpinner.ValueChangedFcn = createCallbackFcn(app, @FtestsSpinnerValueChanged, true);
            app.StatsPanel = uipanel(app.StatsTabGridLayout);
            app.StatsGridLayout = uigridlayout(app.StatsPanel);
            app.TitleLabel = uilabel(app.StatsGridLayout);
            app.TitleLabel.Text = 'Title';
            app.SidedLabel = uilabel(app.StatsGridLayout);
            app.SidedLabel.Text = 'Sided';
            app.PostStatsTab = uitab(app.TabGroup);
            app.PostStatsTab.Title = 'Post-statistics';
            app.PostStatsGridLayout = uigridlayout(app.PostStatsTab);
            app.ThresholdingMethodPanel = uipanel(app.PostStatsGridLayout);
            app.ThresholdingMethodPanel.Title = 'Thresholding method';
            app.ThresholdingMethodGridLayout = uigridlayout(app.ThresholdingMethodPanel);
            app.TFCE_H_EditField = uieditfield(app.ThresholdingMethodGridLayout, 'numeric');
            app.TFCE_H_EditField.Limits = [0 Inf];
            app.TFCE_H_EditField.ValueChangedFcn = createCallbackFcn(app, @TFCE_H_EditFieldValueChanged, true);
            app.TFCE_H_EditField.Value = 2;
            app.TFCE_H_Label = uilabel(app.ThresholdingMethodGridLayout);
            app.TFCE_H_Label.Text = 'H';
            app.TFCE_E_EditField = uieditfield(app.ThresholdingMethodGridLayout, 'numeric');
            app.TFCE_E_EditField.Limits = [0 Inf];
            app.TFCE_E_EditField.ValueChangedFcn = createCallbackFcn(app, @TFCE_E_EditFieldValueChanged, true);
            app.TFCE_E_EditField.Value = 1;
            app.TFCE_E_Label = uilabel(app.ThresholdingMethodGridLayout);
            app.TFCE_E_Label.Text = 'E';
            app.TFCE_ParamsLabel = uilabel(app.ThresholdingMethodGridLayout);
            app.TFCE_ParamsLabel.Text = 'TFCE parameters:';
            app.ClusterAlphaEditField = uieditfield(app.ThresholdingMethodGridLayout, 'numeric');
            app.ClusterAlphaEditField.Limits = [0 1];
            app.ClusterAlphaEditField.ValueChangedFcn = createCallbackFcn(app, @ClusterAlphaEditFieldValueChanged, true);
            app.ClusterAlphaEditField.Enable = 'off';
            app.ClusterAlphaEditField.Value = 0.05;
            app.ClusterAlphaLabel = uilabel(app.ThresholdingMethodGridLayout);
            app.ClusterAlphaLabel.Enable = 'off';
            app.ClusterAlphaLabel.Text = 'Cluster alpha';
            app.ChannelWiseAlphaEditField = uieditfield(app.ThresholdingMethodGridLayout, 'numeric');
            app.ChannelWiseAlphaEditField.Limits = [0 1];
            app.ChannelWiseAlphaEditField.ValueChangedFcn = createCallbackFcn(app, @ChannelWiseAlphaEditFieldValueChanged, true);
            app.ChannelWiseAlphaEditField.Value = 0.05;
            app.ChannelWiseAlphaLabel = uilabel(app.ThresholdingMethodGridLayout);
            app.ChannelWiseAlphaLabel.Text = 'Channel-wise alpha';
            app.ThresholdingMethodDropDown = uidropdown(app.ThresholdingMethodGridLayout);
            app.ThresholdingMethodDropDown.Items = {'Uncorrected', 'False Discovery Rate', 'Cluster extent permutations', 'Cluster mass permutations', 'TFCE permutations'};
            app.ThresholdingMethodDropDown.ItemsData = {'Uncorrected', 'FDR', 'ClusterExtent', 'ClusterMass', 'TFCE'};
            app.ThresholdingMethodDropDown.ValueChangedFcn = createCallbackFcn(app, @ThresholdingMethodDropDownValueChanged, true);
            app.ThresholdingMethodDropDown.Value = 'TFCE';
            app.PrethresholdMaskBrowseButton = uibutton(app.PostStatsGridLayout, 'push');
            app.PrethresholdMaskBrowseButton.Enable = 'off';
            app.PrethresholdMaskBrowseButton.Text = '';
            app.PrethresholdMaskingEditField = uieditfield(app.PostStatsGridLayout, 'text');
            app.PrethresholdMaskingEditField.Enable = 'off';
            app.PrethresholdMaskingLabel = uilabel(app.PostStatsGridLayout);
            app.PrethresholdMaskingLabel.Enable = 'off';
            app.PrethresholdMaskingLabel.Text = 'Pre-threshold masking';
            app.NumPermutationsEditField = uieditfield(app.PostStatsGridLayout, 'numeric');
            app.NumPermutationsEditField.ValueDisplayFormat = '%i';
            app.NumPermutationsEditField.ValueChangedFcn = createCallbackFcn(app, @NumPermutationsEditFieldValueChanged, true);
            app.NumPermutationsEditField.Tooltip = {'Use ''0'' to run all permutations exhaustively'};
            app.NumPermutationsEditField.Value = 10000;
            app.NumPermutationsLabel = uilabel(app.PostStatsGridLayout);
            app.NumPermutationsLabel.Tooltip = {'Use ''0'' to run all permutations exhaustively'};
            app.NumPermutationsLabel.Text = 'Number of permutations';
            app.PermWholeCheckbox = uicheckbox(app.PostStatsGridLayout);
            app.PermWholeCheckbox.ValueChangedFcn = createCallbackFcn(app, @PermWholeCheckboxValueChanged, true);
            app.PermWholeCheckbox.Enable = 'off';
            app.PermWholeCheckbox.Tooltip = {''};
            app.PermWholeCheckbox.Text = '';
            app.PermWholeCheckbox.Value = true;
            app.PermWholeLabel = uilabel(app.PostStatsGridLayout);
            app.PermWholeLabel.Enable = 'off';
            app.PermWholeLabel.Tooltip = {''};
            app.PermWholeLabel.Text = 'Permute whole blocks';
            app.PermWithinCheckbox = uicheckbox(app.PostStatsGridLayout);
            app.PermWithinCheckbox.ValueChangedFcn = createCallbackFcn(app, @PermWithinCheckboxValueChanged, true);
            app.PermWithinCheckbox.Enable = 'off';
            app.PermWithinCheckbox.Tooltip = {''};
            app.PermWithinCheckbox.Text = '';
            app.PermWithinCheckbox.Value = true;
            app.PermWithinLabel = uilabel(app.PostStatsGridLayout);
            app.PermWithinLabel.Enable = 'off';
            app.PermWithinLabel.Tooltip = {''};
            app.PermWithinLabel.Text = 'Permute within blocks';
            app.DoSignFlipsCheckbox = uicheckbox(app.PostStatsGridLayout);
            app.DoSignFlipsCheckbox.ValueChangedFcn = createCallbackFcn(app, @DoSignFlipsCheckboxValueChanged, true);
            app.DoSignFlipsCheckbox.Tooltip = {'* Assumes independent and symmetric errors'};
            app.DoSignFlipsCheckbox.Text = '';
            app.DoSignFlipsLabel = uilabel(app.PostStatsGridLayout);
            app.DoSignFlipsLabel.Tooltip = {'* Assumes independent and symmetric errors'};
            app.DoSignFlipsLabel.Text = 'Do sign-flips';
            app.DoPermsCheckbox = uicheckbox(app.PostStatsGridLayout);
            app.DoPermsCheckbox.ValueChangedFcn = createCallbackFcn(app, @DoPermsCheckboxValueChanged, true);
            app.DoPermsCheckbox.Enable = 'off';
            app.DoPermsCheckbox.Tooltip = {'* Assumes exchangable errors'};
            app.DoPermsCheckbox.Text = '';
            app.DoPermsCheckbox.Value = true;
            app.DoPermsLabel = uilabel(app.PostStatsGridLayout);
            app.DoPermsLabel.Tooltip = {'* Assumes exchangable errors'};
            app.DoPermsLabel.Text = 'Do permutations';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GeneralLinearModel(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end