classdef ImportExternalEventsSettings < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        Panel                          matlab.ui.container.Panel
        ExternalEventsPanelGridLayout  matlab.ui.container.GridLayout
        WonambiXMLValue                matlab.ui.control.EditField
        WonambiXMLBrowseButton         matlab.ui.control.Button
        WonambiXMLLabel                matlab.ui.control.Label
        ScoredEventsValue              matlab.ui.control.EditField
        ScoredEventsBrowseButton       matlab.ui.control.Button
        ScoredEventsLabel              matlab.ui.control.Label
        HypnogramValue                 matlab.ui.control.EditField
        HypnogramBrowseButton          matlab.ui.control.Button
        HypnogramLabel                 matlab.ui.control.Label
        OKButton                       matlab.ui.control.Button
        CancelButton                   matlab.ui.control.Button
    end

    
    properties (Access = public)
        MainApp;
        InterruptDialog;
        % Structure that keeps the state
        State = struct();
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MainApp, Settings)
            % ---------------------------------------------------------
            if nargin < 3
                Settings = struct();
                Settings.HypnoPath = '';
                Settings.EventsPath = '';
                Settings.WonambiXMLPath = '';
            end
            % ---------------------------------------------------------
            % Store main app object
            if exist('MainApp', 'var')
                app.MainApp = MainApp;
                % Position the new app relative to the main app
                app_positionGUI(app.MainApp, app.UIFigure)
                % Show waitbar to indicate another gui is open
                app.InterruptDialog = uiprogressdlg(app.MainApp.UIFigure);
            else
                app.MainApp = struct();
            end
            % Clear the import structure
            app.MainApp.Import = struct();
            app.MainApp.Import.Return = false;
            % Icons
            app.HypnogramBrowseButton.Icon = 'icon_folder-open.png';
            app.ScoredEventsBrowseButton.Icon = 'icon_folder-open.png';
            app.WonambiXMLBrowseButton.Icon = 'icon_folder-open.png';
            % ---------------------------------------------------------
            % Initialize State
            app.State = Settings;
            app.HypnogramValue.Value = app.State.HypnoPath;
            app.ScoredEventsValue.Value = app.State.EventsPath;
            app.WonambiXMLValue.Value = app.State.WonambiXMLPath;
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            app.MainApp.Import.Return = false;
            % Delete the dialog box
            if isvalid(app)
                delete(app)
            end
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            app.MainApp.Import.Return = true;
            app.MainApp.Import.Data = app.State;
            % Delete the dialog box
            if isvalid(app)
                delete(app)
            end
        end

        % Button pushed function: HypnogramBrowseButton
        function HypnogramBrowseButtonPushed(app, event)
            % ---------------------------------------------------------
            % Ask the user to select a file
            Filter = ifelse(ispc, { ...
                '*.txt', 'Compumedics Hypnogram file (*.txt)'; ...
                }, '*.*');
            [Filename, Filepath] = uigetfile(Filter, 'Select a file to import');
            % ---------------------------------------------------------
            % If filename is zero, user pressed cancel
            if Filename == 0
                return
            end
            % Navigate to the selected folder
            cd(Filepath)
            % ---------------------------------------------------------
            % Set filepath
            app.State.HypnoPath = fullfile(Filepath, Filename);
            app.HypnogramValue.Value = app.State.HypnoPath;
        end

        % Button pushed function: ScoredEventsBrowseButton
        function ScoredEventsBrowseButtonPushed(app, event)
            % ---------------------------------------------------------
            % Ask the user to select a file
            Filter = ifelse(ispc, { ...
                '*.txt', 'Compumedics Scored Events file (*.txt)'; ...
                }, '*.*');
            [Filename, Filepath] = uigetfile(Filter, 'Select a file to import');
            % ---------------------------------------------------------
            % If filename is zero, user pressed cancel
            if Filename == 0
                return
            end
            % Navigate to the selected folder
            cd(Filepath)
            % ---------------------------------------------------------
            % Set filepath
            app.State.EventsPath = fullfile(Filepath, Filename);
            app.ScoredEventsValue.Value = app.State.EventsPath;
        end

        % Button pushed function: WonambiXMLBrowseButton
        function WonambiXMLBrowseButtonPushed(app, event)
            % ---------------------------------------------------------
            % Ask the user to select a file
            Filter = ifelse(ispc, { ...
                '*.txt', 'Wonambi XML file (*.xml)'; ...
                }, '*.*');
            [Filename, Filepath] = uigetfile(Filter, 'Select a file to import');
            % ---------------------------------------------------------
            % If filename is zero, user pressed cancel
            if Filename == 0
                return
            end
            % Navigate to the selected folder
            cd(Filepath)
            % ---------------------------------------------------------
            % Set filepath
            app.State.WonambiXMLPath = fullfile(Filepath, Filename);
            app.WonambiXMLValue.Value = app.State.WonambiXMLPath;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Import External Event Files';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.CancelButton = uibutton(app.GridLayout, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.OKButton = uibutton(app.GridLayout, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.Panel = uipanel(app.GridLayout);
            app.ExternalEventsPanelGridLayout = uigridlayout(app.Panel);
            app.HypnogramLabel = uilabel(app.ExternalEventsPanelGridLayout);
            app.HypnogramLabel.Text = 'Compumedics Hypnogram';
            app.HypnogramBrowseButton = uibutton(app.ExternalEventsPanelGridLayout, 'push');
            app.HypnogramBrowseButton.ButtonPushedFcn = createCallbackFcn(app, @HypnogramBrowseButtonPushed, true);
            app.HypnogramBrowseButton.Text = '';
            app.HypnogramValue = uieditfield(app.ExternalEventsPanelGridLayout, 'text');
            app.HypnogramValue.Editable = 'off';
            app.ScoredEventsLabel = uilabel(app.ExternalEventsPanelGridLayout);
            app.ScoredEventsLabel.Text = 'Compumedics Scored Events';
            app.ScoredEventsBrowseButton = uibutton(app.ExternalEventsPanelGridLayout, 'push');
            app.ScoredEventsBrowseButton.ButtonPushedFcn = createCallbackFcn(app, @ScoredEventsBrowseButtonPushed, true);
            app.ScoredEventsBrowseButton.Text = '';
            app.ScoredEventsValue = uieditfield(app.ExternalEventsPanelGridLayout, 'text');
            app.ScoredEventsValue.Editable = 'off';
            app.WonambiXMLLabel = uilabel(app.ExternalEventsPanelGridLayout);
            app.WonambiXMLLabel.Text = 'Wonambi XML';
            app.WonambiXMLBrowseButton = uibutton(app.ExternalEventsPanelGridLayout, 'push');
            app.WonambiXMLBrowseButton.ButtonPushedFcn = createCallbackFcn(app, @WonambiXMLBrowseButtonPushed, true);
            app.WonambiXMLBrowseButton.Text = '';
            app.WonambiXMLValue = uieditfield(app.ExternalEventsPanelGridLayout, 'text');
            app.WonambiXMLValue.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ImportExternalEventsSettings(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end