classdef PowerSpectralAnalysisSettings < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        NormalizePanel               matlab.ui.container.Panel
        NormalizeGridLayout          matlab.ui.container.GridLayout
        NormalizeButtonGroup         matlab.ui.container.ButtonGroup
        NormTrialsCheckbox           matlab.ui.control.CheckBox
        NormChannelsCheckBox         matlab.ui.control.CheckBox
        NormWithinFreqRadio          matlab.ui.control.RadioButton
        NormAcrossFreqRadio          matlab.ui.control.RadioButton
        OptionsPanel                 matlab.ui.container.Panel
        OptionsGridLayout            matlab.ui.container.GridLayout
        FrequencyStepUnit            matlab.ui.control.Label
        FrequencyStepValue           matlab.ui.control.Label
        FrequencyStepLabel           matlab.ui.control.Label
        WindowPercentLabel           matlab.ui.control.Label
        WindowOverlapEditField       matlab.ui.control.NumericEditField
        WindowoverlapEditFieldLabel  matlab.ui.control.Label
        WindowSecondLabel            matlab.ui.control.Label
        WindowLengthEditField        matlab.ui.control.NumericEditField
        WindowlengthEditFieldLabel   matlab.ui.control.Label
        FreqDefPanel                 matlab.ui.container.Panel
        FreqDefGridLayout            matlab.ui.container.GridLayout
        AddFreqButton                matlab.ui.control.Button
        FreqUpperLabel               matlab.ui.control.Label
        FreqLowerLabel               matlab.ui.control.Label
        FreqLabel                    matlab.ui.control.Label
        OutputOptionsPanel           matlab.ui.container.Panel
        OutputOptionsGridLayout      matlab.ui.container.GridLayout
        DescriptionInput             matlab.ui.control.EditField
        DescriptionEditFieldLabel    matlab.ui.control.Label
        OutputButtonGroup            matlab.ui.container.ButtonGroup
        OutputIndividualPSD          matlab.ui.control.RadioButton
        OutputMeanPSD                matlab.ui.control.RadioButton
        OutputMedianPSD              matlab.ui.control.RadioButton
        AddProcessButton             matlab.ui.control.Button
        CancelButton                 matlab.ui.control.Button
    end

    
    properties (Access = public)
        MainApp;
        State;
        FreqInputs
    end
    
    methods (Access = public)

        function InitializeMapping(app)
            % Render all the default freq bands
            app.GridLayout.RowHeight{2} = getPanelHeight(length(app.State.FreqDef)+2) + 3;
            app.UIFigure.Position(4) = 450 + app.GridLayout.RowHeight{2};
            app.FreqDefGridLayout.RowHeight = repmat({24}, 1, length(app.State.FreqDef)+2);
            app.AddFreqButton.Layout.Row = length(app.State.FreqDef)+2;
            for i = 1:length(app.State.FreqDef)
                % Create Label edit field
                app.FreqInputs.Labels(i) = uieditfield(app.FreqDefGridLayout, 'text');
                app.FreqInputs.Labels(i).Layout.Row = i+1;
                app.FreqInputs.Labels(i).Layout.Column = 1;
                app.FreqInputs.Labels(i).Value = app.State.FreqDef(i).label;
                app.FreqInputs.Labels(i).Tag = 'FreqLabel';
                app.FreqInputs.Labels(i).Editable = ifelse(app.State.FreqDef(i).editable, 'on', 'off');
                app.FreqInputs.Labels(i).FontColor = ifelse(app.State.FreqDef(i).editable, [0, 0, 0], [0.15, 0.15, 0.15]);
                app.FreqInputs.Labels(i).UserData.idx = i;
                app.FreqInputs.Labels(i).ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
                % Create Lower limit edit field
                app.FreqInputs.Lower(i) = uieditfield(app.FreqDefGridLayout, 'numeric');
                app.FreqInputs.Lower(i).Layout.Row = i+1;
                app.FreqInputs.Lower(i).Layout.Column = 2;
                app.FreqInputs.Lower(i).Value = app.State.FreqDef(i).band(1);
                app.FreqInputs.Lower(i).Tag = 'FreqLower';
                app.FreqInputs.Lower(i).Editable = ifelse(app.State.FreqDef(i).editable, 'on', 'off');
                app.FreqInputs.Lower(i).FontColor = ifelse(app.State.FreqDef(i).editable, [0, 0, 0], [0.15, 0.15, 0.15]);
                app.FreqInputs.Lower(i).UserData.idx = i;
                app.FreqInputs.Lower(i).ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
                % Create Lower limit edit field
                app.FreqInputs.Upper(i) = uieditfield(app.FreqDefGridLayout, 'numeric');
                app.FreqInputs.Upper(i).Layout.Row = i+1;
                app.FreqInputs.Upper(i).Layout.Column = 3;
                app.FreqInputs.Upper(i).Value = app.State.FreqDef(i).band(2);
                app.FreqInputs.Upper(i).Tag = 'FreqUpper';
                app.FreqInputs.Upper(i).Editable = ifelse(app.State.FreqDef(i).editable, 'on', 'off');
                app.FreqInputs.Upper(i).FontColor = ifelse(app.State.FreqDef(i).editable, [0, 0, 0], [0.15, 0.15, 0.15]);
                app.FreqInputs.Upper(i).UserData.idx = i;
                app.FreqInputs.Upper(i).ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
                % Create Delete Button
                if app.State.FreqDef(i).editable
                    app.FreqInputs.Button(i) = uibutton(app.FreqDefGridLayout, 'push');
                    app.FreqInputs.Button(i).BackgroundColor = [0.8627 0.2078 0.2706];
                    app.FreqInputs.Button(i).Tooltip = {'delete'};
                    app.FreqInputs.Button(i).Layout.Row = i+1;
                    app.FreqInputs.Button(i).Layout.Column = 4;
                    app.FreqInputs.Button(i).Icon = 'icon_trash.png';
                    app.FreqInputs.Button(i).Text = '';
                    app.FreqInputs.Button(i).UserData.idx = i;
                    app.FreqInputs.Button(i).ButtonPushedFcn = createCallbackFcn(app, @FreqButtonPushed, true);
                end
            end
        end

        function AddRow(app)
            % How many rows?
            i = length(app.State.FreqDef);
            % Increase the height of the figure
            app.UIFigure.Position(4) = 480 + app.GridLayout.RowHeight{2};
            app.UIFigure.Position(2) = max([10, app.UIFigure.Position(2) - 30]);
            % Increase the height of the main Gridlayout
            app.GridLayout.RowHeight{2} = getPanelHeight(i+2) + 3;
            % Set the number of rows in the panel gridlayout
            app.FreqDefGridLayout.RowHeight = repmat({24}, 1, i+2);
            % Position the add-freq button
            app.AddFreqButton.Layout.Row = i+2;
            % Create Label edit field
            app.FreqInputs.Labels(i) = uieditfield(app.FreqDefGridLayout, 'text');
            app.FreqInputs.Labels(i).Layout.Row = i+1;
            app.FreqInputs.Labels(i).Layout.Column = 1;
            app.FreqInputs.Labels(i).Value = app.State.FreqDef(i).label;
            app.FreqInputs.Labels(i).Tag = 'FreqLabel';
            app.FreqInputs.Labels(i).UserData.idx = i;
            app.FreqInputs.Labels(i).ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            % Create Lower limit edit field
            app.FreqInputs.Lower(i) = uieditfield(app.FreqDefGridLayout, 'numeric');
            app.FreqInputs.Lower(i).Layout.Row = i+1;
            app.FreqInputs.Lower(i).Layout.Column = 2;
            app.FreqInputs.Lower(i).Value = app.State.FreqDef(i).band(1);
            app.FreqInputs.Lower(i).Tag = 'FreqLower';
            app.FreqInputs.Lower(i).UserData.idx = i;
            app.FreqInputs.Lower(i).ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            % Create Lower limit edit field
            app.FreqInputs.Upper(i) = uieditfield(app.FreqDefGridLayout, 'numeric');
            app.FreqInputs.Upper(i).Layout.Row = i+1;
            app.FreqInputs.Upper(i).Layout.Column = 3;
            app.FreqInputs.Upper(i).Value = app.State.FreqDef(i).band(2);
            app.FreqInputs.Upper(i).Tag = 'FreqUpper';
            app.FreqInputs.Upper(i).UserData.idx = i;
            app.FreqInputs.Upper(i).ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            % Create Delete Button
            app.FreqInputs.Button(i) = uibutton(app.FreqDefGridLayout, 'push');
            app.FreqInputs.Button(i).BackgroundColor = [0.8627 0.2078 0.2706];
            app.FreqInputs.Button(i).Tooltip = {'delete'};
            app.FreqInputs.Button(i).Layout.Row = i+1;
            app.FreqInputs.Button(i).Layout.Column = 4;
            app.FreqInputs.Button(i).Icon = 'icon_trash.png';
            app.FreqInputs.Button(i).Text = '';
            app.FreqInputs.Button(i).UserData.idx = i;
            app.FreqInputs.Button(i).ButtonPushedFcn = createCallbackFcn(app, @FreqButtonPushed, true);
            % If there is only one freq band left, diable the delete button
            if length(app.State.FreqDef) == 1
                app.FreqInputs.Button(1).Enable = 'off';
            else
                app.FreqInputs.Button(1).Enable = 'on';
            end
        end

        function DeleteRow(app, idx)
            % First delete the input
            delete(app.FreqInputs.Labels(idx))
            delete(app.FreqInputs.Lower(idx))
            delete(app.FreqInputs.Upper(idx))
            delete(app.FreqInputs.Button(idx))
            app.FreqInputs.Labels(idx) = [];
            app.FreqInputs.Lower(idx) = [];
            app.FreqInputs.Upper(idx) = [];
            app.FreqInputs.Button(idx) = [];
            % Then relocate the remaining items
            for i = 1:length(app.State.FreqDef)
                app.FreqInputs.Labels(i).Layout.Row = i+1;
                app.FreqInputs.Labels(i).UserData.idx = i;
                app.FreqInputs.Lower(i).Layout.Row = i+1;
                app.FreqInputs.Lower(i).UserData.idx = i;
                app.FreqInputs.Upper(i).Layout.Row = i+1;
                app.FreqInputs.Upper(i).UserData.idx = i;
                if app.State.FreqDef(i).editable
                    app.FreqInputs.Button(i).Layout.Row = i+1;
                    app.FreqInputs.Button(i).UserData.idx = i;
                end
            end
            % Relocate the add button
            app.AddFreqButton.Layout.Row = length(app.State.FreqDef)+2;
            % Remove the last RowHeight item from the GridLayout
            app.FreqDefGridLayout.RowHeight(end) = [];
            % Update the main gridlayout height
            app.GridLayout.RowHeight{2} = getPanelHeight(length(app.State.FreqDef)+2) + 3;
            % Update the figure height
            app.UIFigure.Position(4) = 450 + app.GridLayout.RowHeight{2};
            app.UIFigure.Position(2) = max([10, app.UIFigure.Position(2) + 30]);
            % If there is only one freq band left, diable the delete button
            if length(app.State.FreqDef) == 1
                app.FreqInputs.Button(1).Enable = 'off';
            else
                app.FreqInputs.Button(1).Enable = 'on';
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MainApp, FileList)
            % ---------------------------------------------------------
            % Store main app object
            if exist('MainApp', 'var')
                app.MainApp = MainApp;
                % Position the new app relative to the main app
                app_positionGUI(app.MainApp, app.UIFigure)
            else
                app.MainApp = struct();
            end
            % Clear the import structure
            app.MainApp.Import = struct();
            app.MainApp.Import.Return = false;
            % ---------------------------------------------------------
            % Initial state
            app.State.Window.Length = 6; % Seconds
            app.State.Window.Overlap = 50; % Percent
            app.State.FreqDef(1).label = 'low-delta';
            app.State.FreqDef(1).band = [0.5, 1]; % Hz
            app.State.FreqDef(1).editable = false;
            app.State.FreqDef(2).label = 'delta';
            app.State.FreqDef(2).band = [1, 4.5]; % Hz
            app.State.FreqDef(2).editable = false;
            app.State.FreqDef(3).label = 'theta';
            app.State.FreqDef(3).band = [4.5, 8]; % Hz
            app.State.FreqDef(3).editable = false;
            app.State.FreqDef(4).label = 'alpha';
            app.State.FreqDef(4).band = [8, 12]; % Hz
            app.State.FreqDef(4).editable = false;
            app.State.FreqDef(5).label = 'sigma';
            app.State.FreqDef(5).band = [12, 15]; % Hz
            app.State.FreqDef(5).editable = false;
            app.State.FreqDef(6).label = 'beta';
            app.State.FreqDef(6).band = [15, 25]; % Hz
            app.State.FreqDef(6).editable = false;
            app.State.FreqDef(7).label = 'gamma';
            app.State.FreqDef(7).band = [25, 40]; % Hz
            app.State.FreqDef(7).editable = false;
            app.State.Norm.AcrossFreqBands = true;
            app.State.Norm.AcrossChannels = true;
            app.State.Norm.AcrossTrials = true;
            app.State.EpochAverage = 'mean';
            app.State.KeyVals.desc = '<desc>';
            % ---------------------------------------------------------
            % Init
            app.InitializeMapping()
            % ---------------------------------------------------------
            % Get the minimum sampling rate and calculate freq step
            app.State.SamplingFrequency = min(cellfun(@(f) f.SamplingFrequency, FileList.JSON));
            app.FrequencyStepValue.Text = sprintf('%.3f', (app.State.SamplingFrequency/2)/(max([256, 2^nextpow2(app.State.Window.Length*app.State.SamplingFrequency)])/2));
            % ---------------------------------------------------------
            % Draw
            drawnow;
            app.AddProcessButton.Enable = 'on';
            app.OptionsPanel.Enable = 'on';
            app.FreqDefPanel.Enable = 'on';
            app.NormalizePanel.Enable = 'on';
            app.OutputOptionsPanel.Enable = 'on';
        end

        % Button pushed function: AddProcessButton
        function AddProcessButtonPushed(app, event)
            app.MainApp.Import.Return = true;
            app.MainApp.Import.Data = app.State;
            % Delete the dialog box
            if isvalid(app)
                delete(app)
            end
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            app.MainApp.Import.Return = false;
            % Delete the dialog box
            if isvalid(app)
                delete(app)
            end
        end

        % Button pushed function: AddFreqButton
        function FreqButtonPushed(app, event)
            event.Source.Enable = 'off';
            drawnow;
            try
                switch event.Source.Tag
                    case 'add'
                        app.State.FreqDef(end+1).label = '';
                        app.State.FreqDef(end).band(1) = app.State.FreqDef(end-1).band(2);
                        app.State.FreqDef(end).band(2) = app.State.FreqDef(end).band(1)+1;
                        app.State.FreqDef(end).editable = true;
                        app.AddRow();
                    otherwise
                        idx = event.Source.UserData.idx;
                        app.State.FreqDef(idx) = [];
                        app.DeleteRow(idx);
                end
            catch
                if isvalid(event.Source)
                    event.Source.Enable = 'on';
                end
            end
            if isvalid(event.Source)
                event.Source.Enable = 'on';
            end
        end

        % Value changed function: WindowLengthEditField, 
        % ...and 1 other component
        function EditFieldValueChanged(app, event)
            switch event.Source.Tag
                case 'WindowLength'
                    app.State.Window.Length = app.WindowLengthEditField.Value;
                    app.FrequencyStepValue.Text = sprintf('%.3f', (app.State.SamplingFrequency/2)/(max([256, 2^nextpow2(app.State.Window.Length*app.State.SamplingFrequency)])/2));
                case 'WindowOverlap'
                    app.State.Window.Overlap = app.WindowOverlapEditField.Value;
                case 'FreqLabel'
                    idx = event.Source.UserData.idx;
                    app.State.FreqDef(idx).label = event.Source.Value;
                case 'FreqLower'
                    idx = event.Source.UserData.idx;
                    app.State.FreqDef(idx).band(1) = event.Source.Value;
                case 'FreqUpper'
                    idx = event.Source.UserData.idx;
                    app.State.FreqDef(idx).band(2) = event.Source.Value;
            end
        end

        % Selection changed function: OutputButtonGroup
        function OutputButtonGroupSelectionChanged(app, event)
            app.State.EpochAverage = app.OutputButtonGroup.SelectedObject.Tag;
        end

        % Selection changed function: NormalizeButtonGroup
        function NormalizeButtonGroupSelectionChanged(app, event)
            % Set state
            switch app.NormalizeButtonGroup.SelectedObject.Tag
                case 'AcrossFreqBands'
                    app.State.Norm.AcrossFreqBands = true;
                case 'WithinFreqBands'
                    app.State.Norm.AcrossFreqBands = false;
            end
        end

        % Value changed function: NormChannelsCheckBox
        function NormChannelsCheckBoxValueChanged(app, event)
            % Set state
            app.State.Norm.AcrossChannels = event.Source.Value;
        end

        % Value changed function: NormTrialsCheckbox
        function NormTrialsCheckboxValueChanged(app, event)
            % Set state
            app.State.Norm.AcrossTrials = event.Source.Value;
        end

        % Value changed function: DescriptionInput
        function DescriptionInputValueChanged(app, event)
            % ---------------------------------------------------------
            % Remove nonalpha numeric values except arrows
            Rm = isstrprop(app.DescriptionInput.Value, 'alphanum') == 0;
            arrowidx = regexp(app.DescriptionInput.Value, '<|>');
            if ~isempty(arrowidx)
                Rm(arrowidx) = false;
            end
            app.DescriptionInput.Value(Rm) = [];
            app.DescriptionInput.Value = lower(app.DescriptionInput.Value);
            startIdx = regexp(app.DescriptionInput.Value, '<');
            isproblem = false;
            if ~isempty(startIdx)
                endIdx = regexp(app.DescriptionInput.Value, '>');
                if length(startIdx) ~= length(endIdx)
                    isproblem = true;
                    Message = 'Issue: different number of ''<'' and ''>'' characters found.';
                end
                if ~isproblem
                    if any(endIdx < startIdx)
                        isproblem = true;
                        Message = 'Issue: at least one ''<'' character appears after its corresponding ''>'' character.';
                    end
                end
            end
            if isproblem
                sel = uiconfirm(app.UIFigure, Message, 'Error', ...
                    'Options',{'Ok'},...
                    'DefaultOption', 'Ok', ...
                    'Icon', 'error'); %#ok<NASGU>
                event.Source.Value = app.State.KeyVals.desc;
            else
                app.State.KeyVals.desc = event.Source.Value;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Power Spectral Analysis';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.CancelButton = uibutton(app.GridLayout, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.AddProcessButton = uibutton(app.GridLayout, 'push');
            app.AddProcessButton.ButtonPushedFcn = createCallbackFcn(app, @AddProcessButtonPushed, true);
            app.AddProcessButton.Enable = 'off';
            app.AddProcessButton.Text = 'Add Process';
            app.OutputOptionsPanel = uipanel(app.GridLayout);
            app.OutputOptionsPanel.Enable = 'off';
            app.OutputOptionsPanel.Title = 'Output options';
            app.OutputOptionsGridLayout = uigridlayout(app.OutputOptionsPanel);
            app.OutputButtonGroup = uibuttongroup(app.OutputOptionsGridLayout);
            app.OutputButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @OutputButtonGroupSelectionChanged, true);
            app.OutputMedianPSD = uiradiobutton(app.OutputButtonGroup);
            app.OutputMedianPSD.Tag = 'median';
            app.OutputMedianPSD.Text = 'Save median PSD values (across trials)';
            app.OutputMeanPSD = uiradiobutton(app.OutputButtonGroup);
            app.OutputMeanPSD.Tag = 'mean';
            app.OutputMeanPSD.Text = 'Save mean PSD values (across trials)';
            app.OutputMeanPSD.Value = true;
            app.OutputIndividualPSD = uiradiobutton(app.OutputButtonGroup);
            app.OutputIndividualPSD.Tag = 'none';
            app.OutputIndividualPSD.Text = 'Save individual PSD values for each trial';
            app.DescriptionEditFieldLabel = uilabel(app.OutputOptionsGridLayout);
            app.DescriptionEditFieldLabel.Text = 'Description:';
            app.DescriptionInput = uieditfield(app.OutputOptionsGridLayout, 'text');
            app.DescriptionInput.ValueChangedFcn = createCallbackFcn(app, @DescriptionInputValueChanged, true);
            app.DescriptionInput.Value = '<desc>';
            app.FreqDefPanel = uipanel(app.GridLayout);
            app.FreqDefPanel.Enable = 'off';
            app.FreqDefPanel.Title = 'Frequency definition';
            app.FreqDefGridLayout = uigridlayout(app.FreqDefPanel);
            app.FreqLabel = uilabel(app.FreqDefGridLayout);
            app.FreqLowerLabel = uilabel(app.FreqDefGridLayout);
            app.FreqLowerLabel.Text = 'Lower (inclusive)';
            app.FreqUpperLabel = uilabel(app.FreqDefGridLayout);
            app.FreqUpperLabel.Text = 'Upper (exclusive)';
            app.AddFreqButton = uibutton(app.FreqDefGridLayout, 'push');
            app.AddFreqButton.ButtonPushedFcn = createCallbackFcn(app, @FreqButtonPushed, true);
            app.AddFreqButton.Tag = 'add';
            app.AddFreqButton.Text = 'ADD FREQUENCY RANGE';
            app.OptionsPanel = uipanel(app.GridLayout);
            app.OptionsPanel.Enable = 'off';
            app.OptionsPanel.Title = 'Analysis options';
            app.OptionsGridLayout = uigridlayout(app.OptionsPanel);
            app.WindowlengthEditFieldLabel = uilabel(app.OptionsGridLayout);
            app.WindowlengthEditFieldLabel.Text = 'Window length';
            app.WindowLengthEditField = uieditfield(app.OptionsGridLayout, 'numeric');
            app.WindowLengthEditField.Limits = [0 Inf];
            app.WindowLengthEditField.ValueDisplayFormat = '%.3f';
            app.WindowLengthEditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.WindowLengthEditField.Tag = 'WindowLength';
            app.WindowLengthEditField.Value = 6;
            app.WindowSecondLabel = uilabel(app.OptionsGridLayout);
            app.WindowSecondLabel.Text = 's';
            app.WindowoverlapEditFieldLabel = uilabel(app.OptionsGridLayout);
            app.WindowoverlapEditFieldLabel.Text = 'Window overlap';
            app.WindowOverlapEditField = uieditfield(app.OptionsGridLayout, 'numeric');
            app.WindowOverlapEditField.Limits = [5 95];
            app.WindowOverlapEditField.RoundFractionalValues = 'on';
            app.WindowOverlapEditField.ValueDisplayFormat = '%.0f';
            app.WindowOverlapEditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.WindowOverlapEditField.Tag = 'WindowOverlap';
            app.WindowOverlapEditField.Value = 50;
            app.WindowPercentLabel = uilabel(app.OptionsGridLayout);
            app.WindowPercentLabel.Text = '%';
            app.FrequencyStepLabel = uilabel(app.OptionsGridLayout);
            app.FrequencyStepLabel.Text = 'Frequency step';
            app.FrequencyStepValue = uilabel(app.OptionsGridLayout);
            app.FrequencyStepValue.Text = '0.5';
            app.FrequencyStepUnit = uilabel(app.OptionsGridLayout);
            app.FrequencyStepUnit.Text = 'Hz';
            app.NormalizePanel = uipanel(app.GridLayout);
            app.NormalizePanel.Enable = 'off';
            app.NormalizePanel.Title = 'Normalize power relative to';
            app.NormalizeGridLayout = uigridlayout(app.NormalizePanel);
            app.NormalizeButtonGroup = uibuttongroup(app.NormalizeGridLayout);
            app.NormalizeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @NormalizeButtonGroupSelectionChanged, true);
            app.NormAcrossFreqRadio = uiradiobutton(app.NormalizeButtonGroup);
            app.NormAcrossFreqRadio.Tag = 'AcrossFreqBands';
            app.NormAcrossFreqRadio.Text = 'Total power summed across freq. bands, averaged across';
            app.NormAcrossFreqRadio.Value = true;
            app.NormWithinFreqRadio = uiradiobutton(app.NormalizeButtonGroup);
            app.NormWithinFreqRadio.Tag = 'WithinFreqBands';
            app.NormWithinFreqRadio.Text = 'Total power summed within freq. bands, averaged across';
            app.NormChannelsCheckBox = uicheckbox(app.NormalizeButtonGroup);
            app.NormChannelsCheckBox.ValueChangedFcn = createCallbackFcn(app, @NormChannelsCheckBoxValueChanged, true);
            app.NormChannelsCheckBox.Text = 'channels';
            app.NormChannelsCheckBox.Value = true;
            app.NormTrialsCheckbox = uicheckbox(app.NormalizeButtonGroup);
            app.NormTrialsCheckbox.ValueChangedFcn = createCallbackFcn(app, @NormTrialsCheckboxValueChanged, true);
            app.NormTrialsCheckbox.Text = 'trials';
            app.NormTrialsCheckbox.Value = true;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PowerSpectralAnalysisSettings(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end