classdef PowerSpectralTopoplots < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        GridLayout        matlab.ui.container.GridLayout
        LoadingImage      matlab.ui.control.Image
        TrialPanel        matlab.ui.container.Panel
        TrialGridLayout   matlab.ui.container.GridLayout
        TrialSlider       matlab.ui.control.Slider
        MainPanel         matlab.ui.container.Panel
        MainGridLayout    matlab.ui.container.GridLayout
        SpectUIAxes       matlab.ui.control.UIAxes
        TopoCursorUIAxes  matlab.ui.control.UIAxes
        ExportButton      matlab.ui.control.Button
        CloseButton       matlab.ui.control.Button
    end

    
    properties (Access = public)
        MainApp;
        Waitbar;
        InterruptDialog;
        Dataset;
        Objects;
        % Structure that keeps the state
        State = struct();
    end
    
    methods (Access = public)
        % =========================================================
        % FUNCTION: Initializes a new Empty State
        function InitializeState(app)
            app.State = struct();
            app.State.IsDragging = false;
            app.State.Trial = 1;
            app.State.FreqIdx = app.Dataset.freqs >= min([app.Dataset.bands.freqrange]) & app.Dataset.freqs <= max([app.Dataset.bands.freqrange]);
            app.State.Cursor = [app.Dataset.freqs(1), app.Dataset.freqs(1)];
        end
        % =========================================================
        % MAIN FUNCTION
        % =========================================================
        % FUNCTION: Map the State to the Properties of all Components
        function MapStateToProps(app)
            if ~isvalid(app)
                return
            end
            app.LoadingImage.Visible = 'on'; drawnow;
            app.TrialPanel.Title = sprintf('Trial %i', app.State.Trial);
            if isempty(app.State.Cursor)
                app.Objects.PatchCursor.XData = [];
                app.Objects.PatchCursor.YData = [];
            else
                app.Objects.PatchCursor.XData = [app.State.Cursor, fliplr(app.State.Cursor)];
                app.Objects.PatchCursor.YData = [app.SpectUIAxes.YLim(1), app.SpectUIAxes.YLim(1), app.SpectUIAxes.YLim(2), app.SpectUIAxes.YLim(2)];
                app.TopoCursorUIAxes.Title.String = sprintf('%.1f - %.1f Hz', sort(app.State.Cursor));
                app.SetTopoSurface(findobj(app.TopoCursorUIAxes.Children, 'Type', 'Surface'), app.Dataset.freqs >= min(app.State.Cursor) & app.Dataset.freqs <= max(app.State.Cursor))
            end
            if app.SpectUIAxes.UserData.Trial ~= app.State.Trial
                app.SpectUIAxes.UserData.Trial = app.State.Trial;
                delete(findobj(app.SpectUIAxes.Children, 'Type', 'Line'));
                app.Objects.PlotSpectro = plot(app.SpectUIAxes, app.Dataset.freqs(app.State.FreqIdx), squeeze(app.Dataset.data(:, app.State.FreqIdx, app.State.Trial))', 'Color', [0.4196, 0.4588, 0.4902], 'PickableParts', 'none');
                app.Objects.PlotMeanSpectro = plot(app.SpectUIAxes, app.Dataset.freqs(app.State.FreqIdx), squeeze(mean(app.Dataset.data(:, app.State.FreqIdx, app.State.Trial), 1))', 'Color', [0, 0, 0], 'LineWidth', 3, 'PickableParts', 'none');
            end
            nBands = length(app.Dataset.bands);
            for i = 1:nBands
                if app.Objects.Topos(i).UIAxes.UserData.Trial ~= app.State.Trial
                    app.Objects.Topos(i).UIAxes.UserData.Trial = app.State.Trial;
                    app.SetTopoSurface(findobj(app.Objects.Topos(i).UIAxes.Children, 'Type', 'Surface'), app.Dataset.bands(i).data)
                end
            end
            drawnow;
            app.LoadingImage.Visible = 'off';
        end
        % ---
        % Plot a topoplot
        function handleTopoplot(app, axHandle, data)
            Fig = figure;
            if islogical(data)
                topoVals = squeeze(mean(app.Dataset.data(:, data, app.State.Trial), 2));
            else
                topoVals = data(:, app.State.Trial);
            end
            topoVals(isnan(topoVals)) = min(topoVals, [], 'omitnan');
            if length(unique(topoVals)) == 1
                topoVals(1) = topoVals(1) - 10e-6;
            end
            topoVals = 10*log10(topoVals);
            topoplot(topoVals, app.Dataset.chanlocs(strcmpi({app.Dataset.chanlocs.type}, 'EEG')), ...
                'style', 'map', ...
                'electrodes', 'off', ...
                'plotrad', 1, ...
                'headrad', 0.6, ...
                'intrad', 1, ...
                'conv', 'on', ...
                'hcolor', 'k', ...
                'whitebk', 'on');
            copyobj(Fig.Children.Children, axHandle);
            for j = 1:length(Fig.Children.Children)
                axHandle.Children(j).PickableParts = 'none';
            end
            axHandle.CLim = Fig.Children.CLim;
            close(Fig)
        end
        % =========================================================
        function SetTopoSurface(app, objHandle, data)
            Fig = figure;
            if islogical(data)
                topoVals = squeeze(mean(app.Dataset.data(:, data, app.State.Trial), 2));
            else
                topoVals = data(:, app.State.Trial);
            end
            topoVals(isnan(topoVals)) = min(topoVals, [], 'omitnan');
            if length(unique(topoVals)) == 1
                topoVals(1) = topoVals(1) - 10e-6;
            end
            topoVals = 10*log10(topoVals);
            [~, objHandle.CData] = topoplot(topoVals, app.Dataset.chanlocs(strcmpi({app.Dataset.chanlocs.type}, 'EEG')), ...
                'noplot', 'on', ...
                'style', 'map', ...
                'electrodes', 'on', ...
                'plotrad', 1, ...
                'headrad', 0.6, ...
                'intrad', 1, ...
                'conv', 'on', ...
                'numcontour', 0, ...
                'emarker', {'.', 'w', 1, 1}, ...
                'hcolor', 'k', ...
                'whitebk', 'on');
            close(Fig)
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MainApp, Filename)
            % ---------------------------------------------------------
            % Store main app object
            if exist('MainApp', 'var')
                app.MainApp = MainApp;
                % Position the new app relative to the main app
                app_positionGUI(app.MainApp, app.UIFigure)
                app.UIFigure.Resize = 'on';
                % Show waitbar to indicate another gui is open
                app.InterruptDialog = uiprogressdlg(app.MainApp.UIFigure);
            else
                app.MainApp = struct();
            end
            % Clear the import structure
            app.MainApp.Import = struct();
            app.MainApp.Import.Return = false;
            % ---------------------------------------------------------
            % Load the colormap
            if exist('colormap_batlow.mat', 'file') == 2
                app.State.CMap = load('colormap_batlow.mat');
                app.State.CMap = app.State.CMap.batlow;
                app.UIFigure.Colormap = app.State.CMap;
            else
                app.State.CMap = parula();
                app.UIFigure.Colormap = app.State.CMap;
            end
            % ---------------------------------------------------------
            % Load
            app.Waitbar = uiprogressdlg(app.UIFigure);
            app.Waitbar.Title = 'Cogwheels are turning, please wait...';
            app.Waitbar.Message = 'Loading data...';
            app.Waitbar.Indeterminate ='on';
            drawnow;
            app.Dataset = LoadDataset(Filename, 'all');
            % ---------------------------------------------------------
            % Edit 2025-05-20 by Rick Wassing
            % Added this if statement for backward compatability
            if ~isfield(app.Dataset, 'bands')
                if isfield(app.Dataset, 'features')
                    app.Dataset.bands = app.Dataset.features;
                else
                    error('Dataset does not contain the field ''bands'' or ''features''.')
                end
            end
            % ---------------------------------------------------------
            % Initialize State
            app.InitializeState();
            % ---------------------------------------------------------
            % Initialize mapping
            app.LoadingImage.ImageSource = 'loading.gif';
            % Spectrogram axes
            app.SpectUIAxes.YLim = [min(min(min(app.Dataset.data(:, app.State.FreqIdx, :)))), 2.^nextpow2(max(max(max(app.Dataset.data(:, app.State.FreqIdx, :)))))];
            app.SpectUIAxes.XLim = [min(app.Dataset.freqs(app.State.FreqIdx)), max(app.Dataset.freqs(app.State.FreqIdx))];
            app.SpectUIAxes.XTick = unique([app.Dataset.bands.freqrange]);
            app.SpectUIAxes.UserData.Trial = 0;
            % Topoplots
            app.handleTopoplot(app.TopoCursorUIAxes, app.Dataset.freqs >= min(app.State.Cursor) & app.Dataset.freqs <= max(app.State.Cursor))
            % Plot a topoplot for each band in the dataset
            nBands = length(app.Dataset.bands);
            app.MainGridLayout.ColumnWidth = repmat({'1x'}, 1, nBands+1);
            app.SpectUIAxes.Layout.Column = [1, nBands+1];
            for i = 1:nBands
                % Create TopoCursorUIAxes
                app.Objects.Topos(i).UIAxes = uiaxes(app.MainGridLayout);
                app.Objects.Topos(i).UIAxes.Toolbar.Visible = 'off';
                app.Objects.Topos(i).UIAxes.LabelFontSizeMultiplier = 1;
                app.Objects.Topos(i).UIAxes.DataAspectRatio = [1 1 1];
                app.Objects.Topos(i).UIAxes.XTick = [];
                app.Objects.Topos(i).UIAxes.YTick = [];
                app.Objects.Topos(i).UIAxes.ZTick = [];
                app.Objects.Topos(i).UIAxes.Title.String = app.Dataset.bands(i).label;
                app.Objects.Topos(i).UIAxes.TitleFontWeight = 'normal';
                app.Objects.Topos(i).UIAxes.TitleFontSizeMultiplier = 1;
                app.Objects.Topos(i).UIAxes.Box = 'on';
                app.Objects.Topos(i).UIAxes.Layout.Row = 1;
                app.Objects.Topos(i).UIAxes.Layout.Column = i+1;
                app.Objects.Topos(i).UIAxes.HitTest = 'off';
                app.Objects.Topos(i).UIAxes.XColor = [1, 1, 1];
                app.Objects.Topos(i).UIAxes.YColor = [1, 1, 1];
                app.Objects.Topos(i).UIAxes.UserData.Trial = 1;
                % Plot topoplot
                app.handleTopoplot(app.Objects.Topos(i).UIAxes, app.Dataset.bands(i).data)
            end
            % Slider
            if app.Dataset.trials > 1
                app.TrialSlider.Value = app.State.Trial;
                app.TrialSlider.Limits = [1, app.Dataset.trials];
                app.TrialSlider.MajorTicks = 1:app.Dataset.trials;
                app.TrialSlider.MinorTicks = 1:app.Dataset.trials;
            else
                app.GridLayout.RowHeight{1} = 0;
                app.TrialSlider.Enable = 'off';
            end
            % Cursor
            app.Objects.PatchCursor = patch(app.SpectUIAxes, 'XData', [-999, -998, -998, -999], 'YData', [0, 0, 1, 1], 'FaceColor', [0.188, 0.310, 0.792], 'FaceAlpha', 0.1, 'LineWidth', 1.5, 'LineStyle', '-', 'EdgeColor', [0.188, 0.310, 0.792], 'Tag', 'Cursor', 'PickableParts', 'none');
            % ---------------------------------------------------------
            % Map the State to the Properties of all Components
            app.MapStateToProps();
            drawnow;
            delete(app.Waitbar);
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            app.MainApp.Import.Return = false;
            % Delete the dialog box
            if isvalid(app)
                delete(app)
            end
        end

        % Callback function
        function AddProcessButtonPushed(app, event)
            app.MainApp.Import.Return = true;
            app.MainApp.Import.Data = app.State;
            % Delete the dialog box
            if isvalid(app)
                delete(app)
            end
        end

        % Value changing function: TrialSlider
        function TrialSliderValueChanging(app, event)
            app.State.Trial = round(event.Value);
            app.TrialSlider.Value = app.State.Trial;
            app.MapStateToProps();
        end

        % Window button down function: UIFigure
        function UIFigureWindowButtonDown(app, event)
            switch event.Source.CurrentObject.Tag
                case 'SpectAxes'
                    % Get the current point
                    CurrPoint = event.Source.CurrentObject.CurrentPoint(1, 1:2);
                    [~, CurrFreqIdx] = min(abs(app.Dataset.freqs - CurrPoint(1)));
                    % -----
                    % Indicate that the user is dragging
                    app.State.IsDragging = true;
                    % Save the cursor index
                    app.State.Cursor = [app.Dataset.freqs(CurrFreqIdx), app.Dataset.freqs(CurrFreqIdx)];
                    app.MapStateToProps();
                otherwise
                    return
            end
        end

        % Window button motion function: UIFigure
        function UIFigureWindowButtonMotion(app, event)
            % ---------------------------------------------------------
            if ~isfield(app.State, 'IsDragging')
                return
            end
            if app.State.IsDragging
                % Get the current point
                [~, CurrFreqIdx] = min(abs(app.Dataset.freqs - event.Source.CurrentObject.CurrentPoint(1, 1)));
                app.State.Cursor(2) = app.Dataset.freqs(CurrFreqIdx);
                app.Objects.PatchCursor.XData = [app.State.Cursor(1), app.State.Cursor(2), app.State.Cursor(2), app.State.Cursor(1)];
                app.MapStateToProps();
            end
        end

        % Window button up function: UIFigure
        function UIFigureWindowButtonUp(app, event)
            app.State.IsDragging = false;
            try
                app.MapStateToProps();
            catch
                return
            end
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            switch event.Key
                case 'leftarrow'
                    app.State.Trial = max([1, app.State.Trial-1]);
                case 'rightarrow'
                    app.State.Trial = min([app.Dataset.trials, app.State.Trial+1]);
            end
            app.TrialSlider.Value = app.State.Trial;
            app.MapStateToProps();
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            filter = {...
                '*.jpg;*.jpeg', 'JPEG (*.jpg)'; ...
                '*.png','PNG (*.png)'; ...
                '*.tif;*.tiff','TIFF (*.tif)'; ...
                '*.pdf','PDF (*.pdf)'; ...
                '*.eps','EPS (*.eps)'};
            [filename, pathname, filterIdx] = uiputfile(filter);
            if filename == 0
                return
            end
            if filterIdx >= 4
                ContentType = 'vector';
            else
                ContentType = 'image';
            end
            app.Waitbar = uiprogressdlg(app.UIFigure);
            app.Waitbar.Title = 'Cogwheels are turning, please wait...';
            app.Waitbar.Message = 'Exporting image...';
            app.Waitbar.Indeterminate ='on';
            exportgraphics(app.MainGridLayout, [pathname, '/', filename], 'Resolution', 300, 'ContentType', ContentType)
            drawnow;
            delete(app.Waitbar)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Spect Topoplots';
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @UIFigureWindowButtonDown, true);
            app.UIFigure.WindowButtonUpFcn = createCallbackFcn(app, @UIFigureWindowButtonUp, true);
            app.UIFigure.WindowButtonMotionFcn = createCallbackFcn(app, @UIFigureWindowButtonMotion, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.CloseButton = uibutton(app.GridLayout, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.ExportButton = uibutton(app.GridLayout, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            app.MainPanel = uipanel(app.GridLayout);
            app.MainGridLayout = uigridlayout(app.MainPanel);
            app.TopoCursorUIAxes = uiaxes(app.MainGridLayout);
            app.TopoCursorUIAxes.XTick = [];
            app.TopoCursorUIAxes.YTick = [];
            app.TopoCursorUIAxes.ZTick = [];
            app.SpectUIAxes = uiaxes(app.MainGridLayout);
            xlabel(app.SpectUIAxes, 'Frequency (Hz)')
            ylabel(app.SpectUIAxes, 'Power Spectral Density (\muV^2/Hz)')
            app.SpectUIAxes.YLim = [0.1 1];
            app.SpectUIAxes.YScale = 'log';
            app.SpectUIAxes.Tag = 'SpectAxes';
            app.TrialPanel = uipanel(app.GridLayout);
            app.TrialPanel.Title = 'Trial';
            app.TrialGridLayout = uigridlayout(app.TrialPanel);
            app.TrialSlider = uislider(app.TrialGridLayout);
            app.TrialSlider.Limits = [0 1];
            app.TrialSlider.ValueChangingFcn = createCallbackFcn(app, @TrialSliderValueChanging, true);
            app.LoadingImage = uiimage(app.GridLayout);
            app.LoadingImage.Visible = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PowerSpectralTopoplots(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end