classdef ShowErrors < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure    matlab.ui.Figure
        GridLayout  matlab.ui.container.GridLayout
        OKButton    matlab.ui.control.Button
        CopyButton  matlab.ui.control.Button
        TextArea    matlab.ui.control.TextArea
    end


    properties (Access = public)
        MainApp;
        Results;
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MainApp, Results)
            try
                % ---------------------------------------------------------
                % Store main app object
                if exist('MainApp', 'var')
                    app.MainApp = MainApp;
                    % Position the new app relative to the main app
                    app_positionGUI(app.MainApp, app.UIFigure)
                else
                    app.MainApp = struct();
                end
                % TODO simply loop over results to check if any warnings
                % are not empty because [Results.Warnings] results in an
                % error.
                % ---------------------------------------------------------
                hasErrors = false;
                for i = 1:length(Results)
                    if ~isempty(Results(i).Errors)
                        hasErrors = true;
                        break;
                    end
                end
                % ---------------------------------------------------------
                hasWarnings = false;
                for i = 1:length(Results)
                    if ~isempty(Results(i).Warnings)
                        hasWarnings = true;
                        break;
                    end
                end
                % ---------------------------------------------------------
                % Close all other GUIs
                if isstruct(app.MainApp.GUIs)
                    guiNames = fieldnames(app.MainApp.GUIs);
                    for i = 1:length(guiNames)
                        delete(app.MainApp.GUIs.(guiNames{i}))
                    end
                end
                % ---------------------------------------------------------
                % Add icons to components
                app.CopyButton.Icon = 'icon_copy.png';
                % ---------------------------------------------------------
                % Show error messages in Text Area
                msg = {};
                if hasErrors
                    msg = [msg; {'=============== ERRORS ==============='}];
                    for i = 1:length(Results)
                        if ~isempty(Results(i).Errors)
                            if i > 1
                                msg = [msg; {'---------------------------------------------------------'}]; %#ok<*AGROW>
                            end
                            msg = [msg; {Results(i).Process.Name}; printME([Results(i).Errors])];
                        end
                    end
                    if hasWarnings
                        msg = [msg; {''}];
                    end
                end
                if hasWarnings
                    msg = [msg; {'============== WARNINGS =============='}];
                    for i = 1:length(Results)
                        if ~isempty(Results(i).Warnings)
                            if i > 1
                                msg = [msg; {'---------------------------------------------------------'}]; %#ok<*AGROW>
                            end
                            msg = [msg; {Results(i).Process.Name}; Results(i).Warnings];
                        end
                    end
                end
                app.TextArea.Value = msg;
                % ---------------------------------------------------------
                % Save the results in the log file
                if exist(fullfile(app.MainApp.State.Protocol.Path, 'errorlog.mat')) ~= 0 %#ok<EXIST>
                    save(fullfile(app.MainApp.State.Protocol.Path, 'errorlog.mat'), 'Results')
                end
            catch ME
                getReport(ME)
                keyboard
                disp('%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%')
                disp('%%% PLEASE CONTACT RICK WASSING %%%')
                disp('%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%')
            end
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            % Delete the dialog box
            if isvalid(app)
                delete(app)
            end
        end

        % Button pushed function: CopyButton
        function CopyButtonPushed(app, event)
            % Copy the errors to the clipboard
            str = cellfun(@(str) [str, newline], app.TextArea.Value, 'UniformOutput', false);
            clipboard('copy', [str{:}])
            sel = uiconfirm(app.UIFigure, {'Message copied to clipboard.', 'Error log saved to', sprintf('%s', fullfile(app.MainApp.State.Protocol.Path, 'errorlog.mat'))}, 'Info', ...
                'Options',{'OK'},...
                'DefaultOption', 'OK', ...
                'Icon', 'info'); %#ok<*NASGU>
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Errors and Warnings';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.TextArea = uitextarea(app.GridLayout);
            app.CopyButton = uibutton(app.GridLayout, 'push');
            app.CopyButton.ButtonPushedFcn = createCallbackFcn(app, @CopyButtonPushed, true);
            app.CopyButton.Text = 'Copy';
            app.OKButton = uibutton(app.GridLayout, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ShowErrors(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end