classdef AddFile < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        SaveAsPanel                    matlab.ui.container.Panel
        SaveAsPanelGridLayout          matlab.ui.container.GridLayout
        SaveAsDropDown                 matlab.ui.control.DropDown
        ProcessingPanel                matlab.ui.container.Panel
        ProcessingGridLayout           matlab.ui.container.GridLayout
        DoICAInput                     matlab.ui.control.CheckBox
        SpectrogramSettingsValue       matlab.ui.control.Label
        SpectrogramSettingsButton      matlab.ui.control.Button
        DoSpectrogramInput             matlab.ui.control.CheckBox
        ResampleUnitLabel              matlab.ui.control.Label
        ResampleFreqInput              matlab.ui.control.NumericEditField
        ResampleLabel                  matlab.ui.control.Label
        DoResampleInput                matlab.ui.control.CheckBox
        FilterSettingsValue            matlab.ui.control.Label
        FilterSettingsButton           matlab.ui.control.Button
        DoFilterInput                  matlab.ui.control.CheckBox
        ExternalEventsPanel            matlab.ui.container.Panel
        ExternalEventsPanelGridLayout  matlab.ui.container.GridLayout
        ImportEventsSettingsButton     matlab.ui.control.Button
        ImportEventFilesValue          matlab.ui.control.Label
        DoImportEvents                 matlab.ui.control.CheckBox
        ChannelLocationsPanel          matlab.ui.container.Panel
        ChannelLocationsPanelGridLayout  matlab.ui.container.GridLayout
        ChannelLocationsValue          matlab.ui.control.EditField
        ChannelLocationsBrowseButton   matlab.ui.control.Button
        ChannelLocationsDropDown       matlab.ui.control.DropDown
        SourceDataFilePanel            matlab.ui.container.Panel
        SourceDataFileGridLayout       matlab.ui.container.GridLayout
        DurationValue                  matlab.ui.control.Label
        ChannelsPNSValue               matlab.ui.control.Label
        ChannelsEEGValue               matlab.ui.control.Label
        DurationLabel                  matlab.ui.control.Label
        ChannelsLabel                  matlab.ui.control.Label
        DataFileTypeInput              matlab.ui.control.DropDown
        DataFileTypeLabel              matlab.ui.control.Label
        DataFileValue                  matlab.ui.control.EditField
        DataFileBrowseButton           matlab.ui.control.Button
        RecordingNamePanel             matlab.ui.container.Panel
        SessionPanelGridLayout         matlab.ui.container.GridLayout
        SessionLabel                   matlab.ui.control.Label
        SessionNaInput                 matlab.ui.control.CheckBox
        SessionInput                   matlab.ui.control.DropDown
        TaskLabel                      matlab.ui.control.Label
        TaskInput                      matlab.ui.control.DropDown
        RunLabel                       matlab.ui.control.Label
        RunNaInput                     matlab.ui.control.CheckBox
        RunInput                       matlab.ui.control.Spinner
        AddProcessButton               matlab.ui.control.Button
        CancelButton                   matlab.ui.control.Button
    end

    
    properties (Access = public)
        MainApp;
        InterruptDialog;
        Waitbar;
        Import;
        % GUIs
        gui_SpecifyPNS;
        gui_FilterSettings;
        gui_ExternalEvents;
        gui_SpectrogramSettings;
        % Structure that keeps the state
        State = struct();
    end
    
    methods (Access = public)
        % =========================================================
        % FUNCTION: Initializes a new Empty State
        function InitializeState(app)
            app.State = struct();
            app.State.Auth = struct();
            app.State.SourceFile = struct();
            app.State.SourceFilePath = '';
            app.State.SourceFileType = 'MFF';
            app.State.TargetFile = struct();
            app.State.TargetFile.KeyVals.sub = '';
            app.State.TargetFile.KeyVals.ses = app.SessionInput.Items{1};
            app.State.TargetFile.KeyVals.task = app.TaskInput.Items{1};
            app.State.TargetFile.KeyVals.run = 1;
            app.State.TargetFile.KeyVals.filetype = 'eeg';
            app.State.TargetFile.KeyVals.exclude.ses = true;
            app.State.TargetFile.KeyVals.exclude.run = true;
            app.State.TargetFilePath = '';
            app.State.TargetFileType = 256;
            app.State.Channels.Type = 'GSN-HydroCel-257.sfp';
            app.State.Channels.Path = which('GSN-HydroCel-257.sfp');
            app.State.Channels.Chanlocs = template_to_chanlocs(app.State.Channels.Path);
            app.State.Events.Do = false;
            app.State.Events.HypnoPath = '';
            app.State.Events.EventsPath = '';
            app.State.Events.WonambiXMLPath = '';
            app.State.Processing.DoResample = false;
            app.State.Processing.ResampleFreq = 500;
            app.State.Processing.DoFilter = true;
            app.State.Processing.FilterSettings.Fs = app.State.Processing.ResampleFreq;
            app.State.Processing.FilterSettings.DoBandpass = true;
            app.State.Processing.FilterSettings.DoNotch = true;
            app.State.Processing.FilterSettings.Highpass = 0.1;
            app.State.Processing.FilterSettings.Lowpass = 60;
            app.State.Processing.FilterSettings.Notch = 50;
            app.State.Processing.FilterSettings.WindowType = 'Hamming';
            app.State.Processing.FilterSettings.TransitionBW = 0.2;
            app.State.Processing.FilterSettings.FilterOrder = 8250;
            app.State.Processing.DoSpectrogram = false;
            app.State.Processing.SpectrogramSettings.ChanSel = {};
            app.State.Processing.SpectrogramSettings.SpectrogramType = 'Multitaper';
            app.State.Processing.SpectrogramSettings.TimeStep = 0.05;
            app.State.Processing.SpectrogramSettings.FreqStep = 0.25;
            app.State.Processing.SpectrogramSettings.MaxFreq = 30;
            app.State.Processing.SpectrogramSettings.Cycles = 7;
            app.State.Processing.SpectrogramSettings.HasBaseline = false;
            app.State.Processing.SpectrogramSettings.DoBaselineNorm = false;
            app.State.Processing.SpectrogramSettings.BaselineMethod = 'absolute';
            app.State.Processing.SpectrogramSettings.BaselineInterval = [-1, 0]; % seconds
            app.State.Processing.DoICA = false;
            % ---------------------------------------------------------
            % Map the State to the Properties of all Components
            MapStateToProps(app)
        end
        % =========================================================
        % MAIN FUNCTION
        % =========================================================
        % FUNCTION: Map the State to the Properties of all Components
        function MapStateToProps(app)
            % ---------------------------------------------------------
            % Filename Run value
            UpdateProperty(app, app.SessionInput, 'Enable', ~app.State.TargetFile.KeyVals.exclude.ses);
            UpdateProperty(app, app.RunInput, 'Enable', ~app.State.TargetFile.KeyVals.exclude.run);
            UpdateProperty(app, app.RunInput, 'Value', app.State.TargetFile.KeyVals.run);
            % ---------------------------------------------------------
            % Data file value
            UpdateProperty(app, app.DataFileValue, 'Value', app.State.SourceFilePath);
            if isempty(app.State.SourceFilePath)
                UpdateProperty(app, app.ChannelsEEGValue, 'Text', '- EEG Channels');
                UpdateProperty(app, app.ChannelsPNSValue, 'Text', '- PNS Channels');
                UpdateProperty(app, app.DurationValue, 'Text', '-m -s @ - Hz');
                UpdateProperty(app, app.ChannelsLabel, 'Enable', 'off');
                UpdateProperty(app, app.DurationLabel, 'Enable', 'off');
                UpdateProperty(app, app.ChannelsEEGValue, 'Enable', 'off');
                UpdateProperty(app, app.ChannelsPNSValue, 'Enable', 'off');
                UpdateProperty(app, app.DurationValue, 'Enable', 'off');
            else
                UpdateProperty(app, app.ChannelsEEGValue, 'Text', sprintf('%i EEG Channels', app.State.SourceFile.Header.nEEGChans));
                UpdateProperty(app, app.ChannelsPNSValue, 'Text', sprintf('%i PNS Channels', app.State.SourceFile.Header.nPNSChans));
                UpdateProperty(app, app.DurationValue, 'Text', sprintf('%s @ %i Hz', ...
                    duration2str((app.State.SourceFile.Header.nSamples/app.State.SourceFile.Header.Fs)/(24*60*60)), ...
                    app.State.SourceFile.Header.Fs));
                UpdateProperty(app, app.ChannelsLabel, 'Enable', 'on');
                UpdateProperty(app, app.DurationLabel, 'Enable', 'on');
                UpdateProperty(app, app.ChannelsEEGValue, 'Enable', 'on');
                UpdateProperty(app, app.ChannelsPNSValue, 'Enable', 'on');
                UpdateProperty(app, app.DurationValue, 'Enable', 'on');
            end
            % ---------------------------------------------------------
            % Channel Locations
            switch app.ChannelLocationsDropDown.Value
                case 'Geoscan'
                    UpdateProperty(app, app.ChannelLocationsBrowseButton, 'Enable', 'on');
                    UpdateProperty(app, app.ChannelLocationsValue, 'Value', app.State.Channels.Path);
                    UpdateProperty(app, app.AddProcessButton, 'Enable', ifelse(exist(app.State.Channels.Path, 'file') == 0, 'off', 'on'));
                otherwise
                    UpdateProperty(app, app.ChannelLocationsBrowseButton, 'Enable', 'off');
                    UpdateProperty(app, app.ChannelLocationsValue, 'Value', '');
            end
            % External Events
            if app.State.Events.Do
                UpdateProperty(app, app.ImportEventsSettingsButton, 'Enable', 'on');
                UpdateProperty(app, app.ImportEventFilesValue, 'Enable', 'on');
                UpdateProperty(app, app.ImportEventFilesValue, 'Text', sprintf('%i file(s) selected', sum([ ...
                    exist(app.State.Events.HypnoPath, 'file') == 2, ...
                    exist(app.State.Events.EventsPath, 'file') == 2, ...
                    exist(app.State.Events.WonambiXMLPath, 'file') == 2, ...
                    ])));
            else
                UpdateProperty(app, app.ImportEventsSettingsButton, 'Enable', 'off');
                UpdateProperty(app, app.ImportEventFilesValue, 'Enable', 'off');
                UpdateProperty(app, app.ImportEventFilesValue, 'Text', 'No external event files will be imported');
            end
            % ---------------------------------------------------------
            % Processing
            UpdateProperty(app, app.ResampleFreqInput, 'Value', app.State.Processing.ResampleFreq);
            if ~isfield(app.State.SourceFile, 'Header')
                UpdateProperty(app, app.DoResampleInput, 'Enable', 'off');
                UpdateProperty(app, app.ResampleLabel, 'Enable', 'off');
                UpdateProperty(app, app.ResampleFreqInput, 'Enable', 'off');
                UpdateProperty(app, app.ResampleUnitLabel, 'Enable', 'off');
            elseif app.State.Processing.DoResample
                UpdateProperty(app, app.DoResampleInput, 'Enable', 'on');
                UpdateProperty(app, app.ResampleLabel, 'Enable', 'on');
                UpdateProperty(app, app.ResampleFreqInput, 'Enable', 'on');
                UpdateProperty(app, app.ResampleUnitLabel, 'Enable', 'on');
            else
                UpdateProperty(app, app.DoResampleInput, 'Enable', 'on');
                UpdateProperty(app, app.ResampleLabel, 'Enable', 'off');
                UpdateProperty(app, app.ResampleFreqInput, 'Enable', 'off');
                UpdateProperty(app, app.ResampleUnitLabel, 'Enable', 'off');
            end
            % ---------------------------------------------------------
            if ~isfield(app.State.SourceFile, 'Header')
                UpdateProperty(app, app.DoFilterInput, 'Enable', 'off');
                UpdateProperty(app, app.FilterSettingsValue, 'Text', 'No temporal filter will be applied');
                UpdateProperty(app, app.FilterSettingsValue, 'Enable', 'off');
                UpdateProperty(app, app.FilterSettingsButton, 'Enable', 'off');
            elseif app.State.Processing.DoFilter
                Bandpass = ifelse(app.State.Processing.FilterSettings.DoBandpass, ...
                    sprintf('%.4g - %.4g Hz,', ...
                    app.State.Processing.FilterSettings.Highpass, ...
                    app.State.Processing.FilterSettings.Lowpass), ...
                    '');
                Notch = ifelse(app.State.Processing.FilterSettings.DoNotch, ...
                    sprintf('%i Hz,', ...
                    app.State.Processing.FilterSettings.Notch), ...
                    '');
                UpdateProperty(app, app.FilterSettingsValue, 'Text', sprintf('%s %s %s, TBW: %.4g Hz, N: %i', ...
                    Bandpass, ...
                    Notch, ...
                    app.State.Processing.FilterSettings.WindowType, ...
                    app.State.Processing.FilterSettings.TransitionBW, ...
                    app.State.Processing.FilterSettings.FilterOrder));
                UpdateProperty(app, app.DoFilterInput, 'Enable', 'on');
                UpdateProperty(app, app.FilterSettingsValue, 'Enable', 'on');
                UpdateProperty(app, app.FilterSettingsButton, 'Enable', 'on');
            else
                UpdateProperty(app, app.DoFilterInput, 'Enable', 'on');
                UpdateProperty(app, app.FilterSettingsValue, 'Text', 'No temporal filter will be applied');
                UpdateProperty(app, app.FilterSettingsValue, 'Enable', 'off');
                UpdateProperty(app, app.FilterSettingsButton, 'Enable', 'off');
            end
            % ---------------------------------------------------------
            if ~isfield(app.State.SourceFile, 'Header') || isempty(app.State.Channels.Chanlocs) || app.State.TargetFileType == 12
                UpdateProperty(app, app.DoSpectrogramInput, 'Value', app.State.Processing.DoSpectrogram);
                UpdateProperty(app, app.DoSpectrogramInput, 'Enable', 'off');
                UpdateProperty(app, app.SpectrogramSettingsValue, 'Text', 'No spectrogram will be calculated');
                UpdateProperty(app, app.SpectrogramSettingsValue, 'Enable', 'off');
                UpdateProperty(app, app.SpectrogramSettingsButton, 'Enable', 'off');
            elseif app.State.Processing.DoSpectrogram
                UpdateProperty(app, app.SpectrogramSettingsValue, 'Text', sprintf('%s, %i chans, [%.4g s, %.4g Hz], max: %.4g Hz, cycles: %ix', ...
                    app.State.Processing.SpectrogramSettings.SpectrogramType, ...
                    length(app.State.Processing.SpectrogramSettings.ChanSel), ...
                    app.State.Processing.SpectrogramSettings.TimeStep, ...
                    app.State.Processing.SpectrogramSettings.FreqStep, ...
                    app.State.Processing.SpectrogramSettings.MaxFreq, ...
                    app.State.Processing.SpectrogramSettings.Cycles));
                UpdateProperty(app, app.DoSpectrogramInput, 'Value', app.State.Processing.DoSpectrogram);
                UpdateProperty(app, app.DoSpectrogramInput, 'Enable', 'on');
                UpdateProperty(app, app.SpectrogramSettingsValue, 'Enable', 'on');
                UpdateProperty(app, app.SpectrogramSettingsButton, 'Enable', 'on');
            else
                UpdateProperty(app, app.DoSpectrogramInput, 'Value', app.State.Processing.DoSpectrogram);
                UpdateProperty(app, app.DoSpectrogramInput, 'Enable', 'on');
                UpdateProperty(app, app.SpectrogramSettingsValue, 'Text', 'No spectrogram will be calculated');
                UpdateProperty(app, app.SpectrogramSettingsValue, 'Enable', 'off');
                UpdateProperty(app, app.SpectrogramSettingsButton, 'Enable', 'off');
            end
            % ---------------------------------------------------------
            if ~isfield(app.State.SourceFile, 'Header') || app.State.TargetFileType == 12
                UpdateProperty(app, app.DoICAInput, 'Value', app.State.Processing.DoICA);
                UpdateProperty(app, app.DoICAInput, 'Enable', 'off');
            else
                UpdateProperty(app, app.DoICAInput, 'Value', app.State.Processing.DoICA);
                UpdateProperty(app, app.DoICAInput, 'Enable', 'on');
            end
            % ---------------------------------------------------------
            % Check if Add Process button can be enabled
            IsEnabled = 'on';
            if isempty(app.State.SourceFilePath)
                IsEnabled = 'off';
            elseif exist(app.State.SourceFilePath) == 0 %#ok<EXIST>
                IsEnabled = 'off';
            end
            if strcmpi(app.ChannelLocationsDropDown.Value, 'Geoscan') && isempty(app.State.Channels.Path)
                IsEnabled = 'off';
            elseif strcmpi(app.ChannelLocationsDropDown.Value, 'Geoscan') && exist(app.State.Channels.Path) == 0 %#ok<EXIST> 
                IsEnabled = 'off';
            end
            app.AddProcessButton.Enable = IsEnabled;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MainApp)
            % ---------------------------------------------------------
            % Store main app object
            if exist('MainApp', 'var')
                app.MainApp = MainApp;
                % Position the new app relative to the main app
                app_positionGUI(app.MainApp, app.UIFigure)
                % Show waitbar to indicate another gui is open
                app.InterruptDialog = uiprogressdlg(app.MainApp.UIFigure);
                drawnow;
            else
                app.MainApp = struct();
            end
            % Clear the import structure
            app.MainApp.Import = struct();
            app.MainApp.Import.Return = false;
            % ---------------------------------------------------------
            % Add icons to components
            app.DataFileBrowseButton.Icon = 'icon_folder-open.png';
            app.ChannelLocationsBrowseButton.Icon = 'icon_folder-open.png';
            app.ImportEventsSettingsButton.Icon = 'icon_cog.png';
            app.FilterSettingsButton.Icon = 'icon_cog.png';
            app.SpectrogramSettingsButton.Icon = 'icon_cog.png';
            % ---------------------------------------------------------
            % Set items in dropdowns
            if isempty(app.MainApp.DatasetSessionsListBox.Items)
                app.SessionInput.Items = {'1'};
            else
                app.SessionInput.Items = app.MainApp.DatasetSessionsListBox.Items;
            end
            if isempty(app.MainApp.DatasetTasksListBox.Items)
                app.TaskInput.Items = {'default'};
            else
                app.TaskInput.Items = app.MainApp.DatasetTasksListBox.Items;
            end
            % Initialize State
            InitializeState(app)
            % ---------------------------------------------------------
            % Map the State to the Properties of all Components
            app.MapStateToProps();
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            % ---------------------------------------------------------
            app.MainApp.Import.Return = false;
            % ---------------------------------------------------------
            % Delete the dialog box
            if isvalid(app)
                delete(app)
            end
        end

        % Button pushed function: AddProcessButton
        function AddProcessButtonPushed(app, event)
            % ---------------------------------------------------------
            % Replace File Separators with standard forward slashes
            app.State.SourceFilePath = strrep(app.State.SourceFilePath, filesep, '/');
            app.State.Channels.Path = strrep(app.State.Channels.Path, filesep, '/');
            app.State.Events.HypnoPath = strrep(app.State.Events.HypnoPath, filesep, '/');
            app.State.Events.EventsPath = strrep(app.State.Events.EventsPath, filesep, '/');
            app.State.TargetFilePath = strrep(app.State.TargetFilePath, filesep, '/');
            app.State.TargetFile.KeyVals.run = num2str(app.State.TargetFile.KeyVals.run);
            % Clean the import settings 
            if ~app.State.Events.Do
                app.State.Events = rmfield(app.State.Events, 'HypnoPath');
                app.State.Events = rmfield(app.State.Events, 'EventsPath');
                app.State.Events = rmfield(app.State.Events, 'WonambiXMLPath');
            end
            if ~app.State.Processing.DoResample
                app.State.Processing = rmfield(app.State.Processing, 'ResampleFreq');
            end
            if ~app.State.Processing.DoFilter
                app.State.Processing = rmfield(app.State.Processing, 'FilterSettings');
            end  
            if ~app.State.Processing.DoSpectrogram
                app.State.Processing = rmfield(app.State.Processing, 'SpectrogramSettings');
            end
            % -----
            app.MainApp.Import.Return = true;
            app.MainApp.Import.Data = app.State;
            % ---------------------------------------------------------
            % Delete the dialog box
            if isvalid(app)
                delete(app)
            end
        end

        % Value changed function: SessionInput
        function SessionInputValueChanged(app, event)
            % ---------------------------------------------------------
            % Set Session
            app.State.TargetFile.KeyVals.ses = app.SessionInput.Value;
            % ---------------------------------------------------------
            % Map state to props
            app.MapStateToProps();
        end

        % Value changed function: TaskInput
        function TaskInputValueChanged(app, event)
            % ---------------------------------------------------------
            % Set Session
            app.State.TargetFile.KeyVals.task = app.TaskInput.Value;
            app.State.TargetFile.KeyVals.run = 1;
            % ---------------------------------------------------------
            % Map state to props
            app.MapStateToProps();
        end

        % Value changed function: RunInput
        function RunInputValueChanged(app, event)
            % ---------------------------------------------------------
            % Set Session
            app.State.TargetFile.KeyVals.run = app.RunInput.Value;
            % ---------------------------------------------------------
            % Map state to props
            app.MapStateToProps();
        end

        % Value changed function: SessionNaInput
        function SessionNaInputValueChanged(app, event)
            % ---------------------------------------------------------
            % Set
            app.State.TargetFile.KeyVals.exclude.ses = event.Source.Value;
            % ---------------------------------------------------------
            % Map state to props
            app.MapStateToProps();
        end

        % Value changed function: RunNaInput
        function RunNaInputValueChanged(app, event)
            % ---------------------------------------------------------
            % Set
            app.State.TargetFile.KeyVals.exclude.run = event.Source.Value;
            % ---------------------------------------------------------
            % Map state to props
            app.MapStateToProps();
        end

        % Value changed function: DataFileTypeInput
        function DataFileTypeInputValueChanged(app, event)
            % ---------------------------------------------------------
            % Set File Type
            app.State.SourceFileType = app.DataFileTypeInput.Value;
            % Change the default channel location if it is not Geoscan
            if ~strcmpi(app.State.Channels.Type, 'Geoscan')
                switch app.State.SourceFileType
                    case 'MFF'
                        app.State.Channels.Type = 'GSN-HydroCel-257.sfp';
                        app.State.Channels.Path = which(app.State.Channels.Type);
                        app.State.Channels.Chanlocs = template_to_chanlocs(app.State.Channels.Path);
                        app.ChannelLocationsDropDown.Value = 'GSN-HydroCel-257.sfp';
                    case 'COMPU257'
                        app.State.Channels.Type = 'Compumedics-257.sfp';
                        app.State.Channels.Path = which(app.State.Channels.Type);
                        app.State.Channels.Chanlocs = template_to_chanlocs(app.State.Channels.Path);
                        app.ChannelLocationsDropDown.Value = 'Compumedics-257.sfp';
                    case 'GRAEL'
                        app.State.Channels.Type = 'Nomenclature';
                        app.State.Channels.Path = which('Compumedics-257.sfp');
                        app.State.Channels.Chanlocs = template_to_chanlocs(app.State.Channels.Path);
                        app.ChannelLocationsDropDown.Value = 'Nomenclature';
                    case 'REMLOGIC'
                        app.State.Channels.Type = 'Nomenclature';
                        app.State.Channels.Path = which('Compumedics-257.sfp');
                        app.State.Channels.Chanlocs = template_to_chanlocs(app.State.Channels.Path);
                        app.ChannelLocationsDropDown.Value = 'Nomenclature';
                    case 'TENTWENTY'
                        % DEPRICATED ID #0013
                        app.State.Channels.Type = 'Ten-Twenty.sfp';
                        app.State.Channels.Path = which(app.State.Channels.Type);
                        app.State.Channels.Chanlocs = template_to_chanlocs(app.State.Channels.Path);
                        app.ChannelLocationsDropDown.Value = 'Ten-Twenty.sfp';
                    otherwise
                        % Do nothing
                end
            end
            % ---------------------------------------------------------
            % Map state to props
            app.MapStateToProps();
        end

        % Button pushed function: DataFileBrowseButton
        function DataFileBrowseButtonPushed(app, event)
            % ---------------------------------------------------------
            % Ask the user to select a file
            % -----
            % But there are some issues
            % (1) If PC, then MFFs are regarded as directories, not files
            % (2) If Mac, we cannot use filters
            % -----
            % Get whether we need to select a directory or a file
            GetType = ifelse((ispc && strcmpi(app.State.SourceFileType, 'MFF')), 'dir', 'file');
            % Get whether we want file type we want
            switch app.State.SourceFileType
                case 'MFF'
                    FilterValue = {'*.mff', 'EEG, Netstation MFF (*.mff)'};
                case 'COMPU257'
                    FilterValue = {'*.edf', 'EEG, Compumedics HD-EEG EDF (*.edf)'};
                case 'GRAEL'
                    FilterValue = {'*.edf', 'EEG, Grael routine-EEG EDF (*.edf)'};
                case 'REMLOGIC'
                    FilterValue = {'*.edf', 'REMLOGIC routine-EEG EDF (*.edf)'};
                case 'TENTWENTY'
                    % DEPRICATED ID #0013
                    FilterValue = {'*.edf', 'EEG, 10-20 Converted by EEG Processor (*.edf)'};
                case 'SET'
                    FilterValue = {'*.set', 'EEG, EEGLAB (*.set)'};
            end
            % Set the filter, but only if we're on a PC
            Filter = ifelse(ispc, FilterValue, '*.*');
            % -----
            switch GetType
                case 'dir'
                    FullFilePath = uigetdir(path, 'Select a file to import');
                    if FullFilePath == 0
                        return
                    end
                    [Filepath, Filename] = fileparts(FullFilePath);
                    Filename = [Filename, '.mff'];
                case 'file'
                    [Filename, Filepath] = uigetfile(Filter, 'Select a file to import');
            end
            % ---------------------------------------------------------
            % If filename is zero, user pressed cancel
            if Filename == 0
                return
            end
            % ---------------------------------------------------------
            % Update the Waitbar
            app.Waitbar = uiprogressdlg(app.UIFigure);
            app.Waitbar.Title = 'Cogwheels are turning, please wait...';
            app.Waitbar.Message = 'Loading header information (may take up to 30 s)';
            app.Waitbar.Indeterminate = 'on';
            % ---------------------------------------------------------
            % Navigate to the selected folder
            cd(Filepath)
            % ---------------------------------------------------------
            % Set filepath
            app.State.SourceFilePath = fullfile(Filepath, Filename);
            app.State.SourceFile.Header = struct();
            try
                switch app.State.SourceFileType
                    case 'MFF'
                        app.State.SourceFile.Header.orig = mff_import_meta_data(app.State.SourceFilePath);
                        app.State.SourceFile.Header.Fs = app.State.SourceFile.Header.orig.signal_binaries(1).channels.sampling_rate(1);
                        app.State.Processing.ResampleFreq = app.State.SourceFile.Header.Fs;
                        app.State.SourceFile.Header.nEEGChans = app.State.SourceFile.Header.orig.signal_binaries(1).num_channels;
                        if numel(app.State.SourceFile.Header.orig.signal_binaries) > 1
                            app.State.SourceFile.Header.nPNSChans = app.State.SourceFile.Header.orig.signal_binaries(2).num_channels;
                        else
                            app.State.SourceFile.Header.nPNSChans = 0;
                        end
                        app.State.SourceFile.Header.nSamples = app.State.SourceFile.Header.orig.signal_binaries(1).channels.num_samples(1);
                        app.State.SourceFile.Header.nTrials = 1;
                        app.State.SourceFile.Header.orig.EEG.chanlocs = [];
                        app.State.SourceFile.Header.orig.EEG = mff_import_piblocs(app.State.SourceFile.Header.orig.EEG, app.State.SourceFilePath);
                    case 'COMPU257'
                        app.State.SourceFile.Header.orig = ft_read_header(app.State.SourceFilePath);
                        app.State.SourceFile.Header.Fs = app.State.SourceFile.Header.orig.Fs;
                        app.State.Processing.ResampleFreq = app.State.SourceFile.Header.Fs;
                        pnsChans = ...
                            regexpIdx(app.State.SourceFile.Header.orig.label, 'BP [0-9]*') | ...
                            strcmpi(app.State.SourceFile.Header.orig.label, 'SpO2') | ...
                            strcmpi(app.State.SourceFile.Header.orig.label, 'VEOU') | ...
                            strcmpi(app.State.SourceFile.Header.orig.label, 'HEOR');
                        app.State.SourceFile.Header.nEEGChans = app.State.SourceFile.Header.orig.nChans - sum(pnsChans);
                        app.State.SourceFile.Header.nPNSChans = sum(pnsChans);
                        app.State.SourceFile.Header.nSamples = app.State.SourceFile.Header.orig.nSamples;
                        app.State.SourceFile.nTrials = app.State.SourceFile.Header.orig.nTrials;
                    case 'GRAEL'
                        % Read header
                        app.State.SourceFile.Header.orig = ft_read_header(app.State.SourceFilePath);
                        % By default any channel with a different sampling rate is not loaded, but we CAN load them
                        app.State.SourceFile.Header.orig.nChans = size(app.State.SourceFile.Header.orig.orig.Label, 1);
                        app.State.SourceFile.Header.orig.label = cellstr(app.State.SourceFile.Header.orig.orig.Label);
                        % Parse the channel labels and return the references
                        [app.State.SourceFile.Header.orig.label, ref, eegChans] = parseRoutineChanLabel(app.State.SourceFile.Header.orig.label);
                        app.State.SourceFile.Header.Fs = app.State.SourceFile.Header.orig.Fs;
                        app.State.Processing.ResampleFreq = app.State.SourceFile.Header.Fs;
                        % Find all EEG and PNS chans
                        pnsChans = ~eegChans;
                        % Keep only the channel locs of the EEG chans
                        app.State.Channels.Chanlocs = app.State.Channels.Chanlocs(ismember({app.State.Channels.Chanlocs.labels}, app.State.SourceFile.Header.orig.label));
                        for i = 1:length(app.State.Channels.Chanlocs)
                            idx = strcmpi(app.State.Channels.Chanlocs(i).labels, app.State.SourceFile.Header.orig.label);
                            app.State.Channels.Chanlocs(i).ref = ref{idx};
                        end
                        app.State.SourceFile.Header.nEEGChans = sum(eegChans);
                        app.State.SourceFile.Header.nPNSChans = sum(pnsChans);
                        app.State.SourceFile.Header.nSamples = app.State.SourceFile.Header.orig.nSamples;
                        app.State.SourceFile.nTrials = app.State.SourceFile.Header.orig.nTrials;
                    case 'REMLOGIC'
                        % Read header
                        app.State.SourceFile.Header.orig = ft_read_header(app.State.SourceFilePath);
                        % By default any channel with a different sampling rate is not loaded, but we CAN load them
                        app.State.SourceFile.Header.orig.nChans = size(app.State.SourceFile.Header.orig.orig.Label, 1);
                        app.State.SourceFile.Header.orig.label = cellstr(app.State.SourceFile.Header.orig.orig.Label);
                        % Parse the channel labels and return the references
                        [app.State.SourceFile.Header.orig.label, ref, eegChans] = parseRoutineChanLabel(app.State.SourceFile.Header.orig.label);
                        app.State.SourceFile.Header.Fs = app.State.SourceFile.Header.orig.Fs;
                        app.State.Processing.ResampleFreq = app.State.SourceFile.Header.Fs;
                        % Find all EEG and PNS chans
                        pnsChans = ~eegChans;
                        % Keep only the channel locs of the EEG chans
                        app.State.Channels.Chanlocs = app.State.Channels.Chanlocs(ismember({app.State.Channels.Chanlocs.labels}, app.State.SourceFile.Header.orig.label));
                        for i = 1:length(app.State.Channels.Chanlocs)
                            idx = strcmpi(app.State.Channels.Chanlocs(i).labels, app.State.SourceFile.Header.orig.label);
                            app.State.Channels.Chanlocs(i).ref = ref{idx};
                        end
                        app.State.SourceFile.Header.nEEGChans = sum(eegChans);
                        app.State.SourceFile.Header.nPNSChans = sum(pnsChans);
                        app.State.SourceFile.Header.nSamples = app.State.SourceFile.Header.orig.nSamples;
                        app.State.SourceFile.nTrials = app.State.SourceFile.Header.orig.nTrials;
                    case 'TENTWENTY'
                        % DEPRICATED ID #0013
                        app.State.SourceFile.Header.orig = ft_read_header(app.State.SourceFilePath);
                        app.State.SourceFile.Header.Fs = app.State.SourceFile.Header.orig.Fs;
                        app.State.Processing.ResampleFreq = app.State.SourceFile.Header.Fs;
                        pnsChans = strcmpi(app.State.SourceFile.Header.orig.label, 'EOG');
                        app.State.SourceFile.Header.nEEGChans = app.State.SourceFile.Header.orig.nChans - sum(pnsChans);
                        app.State.SourceFile.Header.nPNSChans = sum(pnsChans);
                        app.State.SourceFile.Header.nSamples = app.State.SourceFile.Header.orig.nSamples;
                        app.State.SourceFile.nTrials = app.State.SourceFile.Header.orig.nTrials;
                    case 'SET'
                        app.State.SourceFile.Header.orig = load(app.State.SourceFilePath, '-mat', 'EEG');
                        app.State.SourceFile.Header.Fs = app.State.SourceFile.Header.orig.EEG.srate;
                        app.State.Processing.ResampleFreq = app.State.SourceFile.Header.Fs;
                        app.State.SourceFile.Header.nEEGChans = app.State.SourceFile.Header.orig.EEG.nbchan - sum(~strcmpi({app.State.SourceFile.Header.orig.EEG.chanlocs.type}, 'EEG'));
                        app.State.SourceFile.Header.nPNSChans = sum(~strcmpi({app.State.SourceFile.Header.orig.EEG.chanlocs.type}, 'EEG'));
                        app.State.SourceFile.Header.nSamples = app.State.SourceFile.Header.orig.EEG.pnts;
                        app.State.SourceFile.nTrials = app.State.SourceFile.Header.orig.EEG.trials;
                end
                % Update filter order on the sampling freq
                app.State.Processing.FilterSettings.FilterOrder = pop_firwsord(lower(app.State.Processing.FilterSettings.WindowType), app.State.SourceFile.Header.Fs, app.State.Processing.FilterSettings.TransitionBW);
            catch ME
                app.State.SourceFilePath = '';
                app.State.SourceFile.Header = struct();
                delete(app.Waitbar)
                sel = uiconfirm(app.UIFigure, 'Sorry, an error occurred while trying to load the file header information.', 'Error', ...
                    'Options',{'Ok'},...
                    'DefaultOption', 'Ok', ...
                    'Icon', 'error'); %#ok<NASGU>
                printME(ME);
                return
            end
            % ---------------------------------------------------------
            % Map state to props
            app.MapStateToProps();
            delete(app.Waitbar)
        end

        % Value changed function: ChannelLocationsDropDown
        function ChannelLocationsDropDownValueChanged(app, event)
            % ---------------------------------------------------------
            % Set Channel type
            app.State.Channels.Type = app.ChannelLocationsDropDown.Value;
            % ---------------------------------------------------------
            % Load the channel locations
            switch app.State.Channels.Type
                case 'Geoscan'
                    app.State.Channels.Path = '';
                    app.State.Channels.Chanlocs = [];
                case 'Nomenclature'
                    app.State.Channels.Path = which('Compumedics-257.sfp');
                    app.State.Channels.Chanlocs = template_to_chanlocs(app.State.Channels.Path);
                otherwise
                    app.State.Channels.Path = which(app.State.Channels.Type);
                    app.State.Channels.Chanlocs = template_to_chanlocs(app.State.Channels.Path);
            end
            % ---------------------------------------------------------
            % Map state to props
            app.MapStateToProps();
        end

        % Button pushed function: ChannelLocationsBrowseButton
        function ChannelLocationsBrowseButtonPushed(app, event)
            % ---------------------------------------------------------
            % Ask the user to select a file
            Filter = ifelse(ispc, { ...
                '*.txt', 'Geoscan file (*.txt)'; ...
                }, '*.*');
            [Filename, Filepath] = uigetfile(Filter, 'Select a file to import');
            % ---------------------------------------------------------
            % If filename is zero, user pressed cancel
            if Filename == 0
                return
            end
            % Navigate to the selected folder
            cd(Filepath)
            % ---------------------------------------------------------
            % Set filepath
            app.State.Channels.Path = fullfile(Filepath, Filename);
            % ---------------------------------------------------------
            % Load the channel locations
            switch app.State.Channels.Type
                case 'Geoscan'
                    if exist(app.State.Channels.Path, 'file') == 2
                        app.State.Channels.Chanlocs = geoscan_to_chanlocs(app.State.Channels.Path);
                    else
                        app.State.Channels.Chanlocs = [];
                    end
                otherwise
                    app.State.Channels.Path = which(app.State.Channels.Type);
                    app.State.Channels.Chanlocs = template_to_chanlocs(app.State.Channels.Path);
            end
            % ---------------------------------------------------------
            % Map state to props
            app.MapStateToProps();
        end

        % Value changed function: DoImportEvents
        function DoImportEventsValueChanged(app, event)
            % ---------------------------------------------------------
            % Set Do or Not to Do.
            app.State.Events.Do = app.DoImportEvents.Value;
            % ---------------------------------------------------------
            % Map state to props
            app.MapStateToProps();
        end

        % Button pushed function: ImportEventsSettingsButton
        function ImportEventsSettingsButtonPushed(app, event)
            % ---------------------------------------------------------
            if ~app.State.Events.Do
                return
            end
            % ---------------------------------------------------------
            % Call the add file GUI
            app.gui_ExternalEvents = ImportExternalEventsSettings(app, app.State.Events);
            uiwait(app.gui_ExternalEvents.UIFigure);
            % ---------------------------------------------------------
            % Check if user pressed cancel
            if ~app.Import.Return
                return
            end
            app.State.Events = app.Import.Data;
            % ---------------------------------------------------------
            % Map state to props
            app.MapStateToProps();
        end

        % Value changed function: DoFilterInput
        function DoFilterInputValueChanged(app, event)
            % ---------------------------------------------------------
            % Set Do or Not to Do.
            app.State.Processing.DoFilter = app.DoFilterInput.Value;
            % ---------------------------------------------------------
            % Map state to props
            app.MapStateToProps();
        end

        % Button pushed function: FilterSettingsButton
        function FilterSettingsButtonPushed(app, event)
            % ---------------------------------------------------------
            if ~app.State.Processing.DoFilter
                return
            end
            % ---------------------------------------------------------
            % Call the add file GUI
            app.State.Processing.FilterSettings.Fs = app.State.SourceFile.Header.Fs;
            app.gui_FilterSettings = FilterSettings(app, app.State.Processing.FilterSettings);
            uiwait(app.gui_FilterSettings.UIFigure);
            % ---------------------------------------------------------
            % Check if user pressed cancel
            if ~app.Import.Return
                return
            end
            app.State.Processing.FilterSettings = app.Import.Data;
            if app.State.Processing.FilterSettings.DoBandpass || app.State.Processing.FilterSettings.DoNotch
                app.State.Processing.DoFilter = true;
            else
                app.State.Processing.DoFilter = false;
            end
            % ---------------------------------------------------------
            % Map state to props
            app.MapStateToProps();
        end

        % Value changed function: DoResampleInput
        function DoResampleInputValueChanged(app, event)
            % ---------------------------------------------------------
            % Set Do or Not to Do.
            app.State.Processing.DoResample = app.DoResampleInput.Value;
            % ---------------------------------------------------------
            % Map state to props
            app.MapStateToProps();
        end

        % Value changed function: ResampleFreqInput
        function ResampleFreqInputValueChanged(app, event)
            % ---------------------------------------------------------
            % Set Do or Not to Do.
            app.State.Processing.ResampleFreq = app.ResampleFreqInput.Value;
            % ---------------------------------------------------------
            % Map state to props
            app.MapStateToProps();
        end

        % Value changed function: DoSpectrogramInput
        function DoSpectrogramInputValueChanged(app, event)
            % ---------------------------------------------------------
            % Set Do or Not to Do.
            app.State.Processing.DoSpectrogram = app.DoSpectrogramInput.Value;
            % ---------------------------------------------------------
            % Map state to props
            app.MapStateToProps();
        end

        % Button pushed function: SpectrogramSettingsButton
        function SpectrogramSettingsButtonPushed(app, event)
            % ---------------------------------------------------------
            if ~app.State.Processing.DoSpectrogram
                return
            end
            % ---------------------------------------------------------
            % Call the add file GUI
            app.gui_SpectrogramSettings = SpectrogramSettings(app, app.State.Processing.SpectrogramSettings, app.State.Channels.Chanlocs);
            uiwait(app.gui_SpectrogramSettings.UIFigure);
            % ---------------------------------------------------------
            % Check if user pressed cancel
            if ~app.Import.Return
                return
            end
            app.State.Processing.SpectrogramSettings = app.Import.Data;
            % ---------------------------------------------------------
            % Map state to props
            app.MapStateToProps();
        end

        % Value changed function: DoICAInput
        function DoICAInputValueChanged(app, event)
            % ---------------------------------------------------------
            % Set Do or Not to Do.
            app.State.Processing.DoICA = app.DoICAInput.Value;
            % ---------------------------------------------------------
            % Map state to props
            app.MapStateToProps();
        end

        % Value changed function: SaveAsDropDown
        function SaveAsDropDownValueChanged(app, event)
            % ---------------------------------------------------------
            % Set save as
            app.State.TargetFileType = str2double(app.SaveAsDropDown.Value);
            % If we save as a EDF, we can't do any analysis
            if app.State.TargetFileType == 12
                app.State.Processing.DoSpectrogram = false;
                app.State.Processing.DoICA = false;
            end
            % ---------------------------------------------------------
            % Map state to props
            app.MapStateToProps();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Add File';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.CancelButton = uibutton(app.GridLayout, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.AddProcessButton = uibutton(app.GridLayout, 'push');
            app.AddProcessButton.ButtonPushedFcn = createCallbackFcn(app, @AddProcessButtonPushed, true);
            app.AddProcessButton.Enable = 'off';
            app.AddProcessButton.Text = 'Add Process';
            app.RecordingNamePanel = uipanel(app.GridLayout);
            app.RecordingNamePanel.Title = 'Recording Name';
            app.SessionPanelGridLayout = uigridlayout(app.RecordingNamePanel);
            app.RunInput = uispinner(app.SessionPanelGridLayout);
            app.RunInput.Limits = [1 Inf];
            app.RunInput.ValueChangedFcn = createCallbackFcn(app, @RunInputValueChanged, true);
            app.RunInput.Enable = 'off';
            app.RunInput.Value = 1;
            app.RunNaInput = uicheckbox(app.SessionPanelGridLayout);
            app.RunNaInput.ValueChangedFcn = createCallbackFcn(app, @RunNaInputValueChanged, true);
            app.RunNaInput.Text = 'n/a';
            app.RunNaInput.Value = true;
            app.RunLabel = uilabel(app.SessionPanelGridLayout);
            app.RunLabel.Text = 'Run:';
            app.TaskInput = uidropdown(app.SessionPanelGridLayout);
            app.TaskInput.Items = {};
            app.TaskInput.ValueChangedFcn = createCallbackFcn(app, @TaskInputValueChanged, true);
            app.TaskInput.Value = {};
            app.TaskLabel = uilabel(app.SessionPanelGridLayout);
            app.TaskLabel.Text = 'Task:';
            app.SessionInput = uidropdown(app.SessionPanelGridLayout);
            app.SessionInput.Items = {};
            app.SessionInput.ValueChangedFcn = createCallbackFcn(app, @SessionInputValueChanged, true);
            app.SessionInput.Enable = 'off';
            app.SessionInput.Value = {};
            app.SessionNaInput = uicheckbox(app.SessionPanelGridLayout);
            app.SessionNaInput.ValueChangedFcn = createCallbackFcn(app, @SessionNaInputValueChanged, true);
            app.SessionNaInput.Text = 'n/a';
            app.SessionNaInput.Value = true;
            app.SessionLabel = uilabel(app.SessionPanelGridLayout);
            app.SessionLabel.Text = 'Session:';
            app.SourceDataFilePanel = uipanel(app.GridLayout);
            app.SourceDataFilePanel.Title = 'Source Data File';
            app.SourceDataFileGridLayout = uigridlayout(app.SourceDataFilePanel);
            app.DataFileBrowseButton = uibutton(app.SourceDataFileGridLayout, 'push');
            app.DataFileBrowseButton.ButtonPushedFcn = createCallbackFcn(app, @DataFileBrowseButtonPushed, true);
            app.DataFileBrowseButton.Text = '';
            app.DataFileValue = uieditfield(app.SourceDataFileGridLayout, 'text');
            app.DataFileValue.Editable = 'off';
            app.DataFileTypeLabel = uilabel(app.SourceDataFileGridLayout);
            app.DataFileTypeLabel.Text = 'File Type:';
            app.DataFileTypeInput = uidropdown(app.SourceDataFileGridLayout);
            app.DataFileTypeInput.Items = {'EGI-Philips HD-EEG (*.mff)', 'Compumedics HD-EEG (*.edf)', 'Grael routine-EEG (*.edf)', 'REMLogic Titanium Routine (*.edf)'};
            app.DataFileTypeInput.ItemsData = {'MFF', 'COMPU257', 'GRAEL', 'REMLOGIC'};
            app.DataFileTypeInput.ValueChangedFcn = createCallbackFcn(app, @DataFileTypeInputValueChanged, true);
            app.DataFileTypeInput.Value = 'MFF';
            app.ChannelsLabel = uilabel(app.SourceDataFileGridLayout);
            app.ChannelsLabel.Enable = 'off';
            app.ChannelsLabel.Text = 'Channels:';
            app.DurationLabel = uilabel(app.SourceDataFileGridLayout);
            app.DurationLabel.Enable = 'off';
            app.DurationLabel.Text = 'Duration';
            app.ChannelsEEGValue = uilabel(app.SourceDataFileGridLayout);
            app.ChannelsEEGValue.Enable = 'off';
            app.ChannelsEEGValue.Text = '- EEG Channels';
            app.ChannelsPNSValue = uilabel(app.SourceDataFileGridLayout);
            app.ChannelsPNSValue.Enable = 'off';
            app.ChannelsPNSValue.Text = '- PNS Channels';
            app.DurationValue = uilabel(app.SourceDataFileGridLayout);
            app.DurationValue.Enable = 'off';
            app.DurationValue.Text = '-m -s @ - Hz';
            app.ChannelLocationsPanel = uipanel(app.GridLayout);
            app.ChannelLocationsPanel.Title = 'Channel Locations';
            app.ChannelLocationsPanelGridLayout = uigridlayout(app.ChannelLocationsPanel);
            app.ChannelLocationsDropDown = uidropdown(app.ChannelLocationsPanelGridLayout);
            app.ChannelLocationsDropDown.Items = {'Template: GSN-HydroCel-257', 'Template: Compumedics-257', 'Template: 10-20 nomenclature', 'Import Geoscan'};
            app.ChannelLocationsDropDown.ItemsData = {'GSN-HydroCel-257.sfp', 'Compumedics-257.sfp', 'Nomenclature', 'Geoscan'};
            app.ChannelLocationsDropDown.ValueChangedFcn = createCallbackFcn(app, @ChannelLocationsDropDownValueChanged, true);
            app.ChannelLocationsDropDown.Value = 'GSN-HydroCel-257.sfp';
            app.ChannelLocationsBrowseButton = uibutton(app.ChannelLocationsPanelGridLayout, 'push');
            app.ChannelLocationsBrowseButton.ButtonPushedFcn = createCallbackFcn(app, @ChannelLocationsBrowseButtonPushed, true);
            app.ChannelLocationsBrowseButton.Enable = 'off';
            app.ChannelLocationsBrowseButton.Text = '';
            app.ChannelLocationsValue = uieditfield(app.ChannelLocationsPanelGridLayout, 'text');
            app.ChannelLocationsValue.Editable = 'off';
            app.ExternalEventsPanel = uipanel(app.GridLayout);
            app.ExternalEventsPanel.Title = 'External Events';
            app.ExternalEventsPanelGridLayout = uigridlayout(app.ExternalEventsPanel);
            app.DoImportEvents = uicheckbox(app.ExternalEventsPanelGridLayout);
            app.DoImportEvents.ValueChangedFcn = createCallbackFcn(app, @DoImportEventsValueChanged, true);
            app.DoImportEvents.Text = 'Import external event files:';
            app.ImportEventFilesValue = uilabel(app.ExternalEventsPanelGridLayout);
            app.ImportEventFilesValue.Enable = 'off';
            app.ImportEventFilesValue.Text = 'No external event files will be imported';
            app.ImportEventsSettingsButton = uibutton(app.ExternalEventsPanelGridLayout, 'push');
            app.ImportEventsSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @ImportEventsSettingsButtonPushed, true);
            app.ImportEventsSettingsButton.Enable = 'off';
            app.ImportEventsSettingsButton.Text = '';
            app.ProcessingPanel = uipanel(app.GridLayout);
            app.ProcessingPanel.Title = 'Apply processing';
            app.ProcessingGridLayout = uigridlayout(app.ProcessingPanel);
            app.DoFilterInput = uicheckbox(app.ProcessingGridLayout);
            app.DoFilterInput.ValueChangedFcn = createCallbackFcn(app, @DoFilterInputValueChanged, true);
            app.DoFilterInput.Text = 'Temporal filter:';
            app.DoFilterInput.Value = true;
            app.FilterSettingsButton = uibutton(app.ProcessingGridLayout, 'push');
            app.FilterSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @FilterSettingsButtonPushed, true);
            app.FilterSettingsButton.Text = '';
            app.FilterSettingsValue = uilabel(app.ProcessingGridLayout);
            app.FilterSettingsValue.Text = '0.1 - 60 Hz, 50 Hz, Hamming, TBW: 0.2 Hz, N: 8250';
            app.DoResampleInput = uicheckbox(app.ProcessingGridLayout);
            app.DoResampleInput.ValueChangedFcn = createCallbackFcn(app, @DoResampleInputValueChanged, true);
            app.DoResampleInput.Text = 'Resample:';
            app.ResampleLabel = uilabel(app.ProcessingGridLayout);
            app.ResampleLabel.Enable = 'off';
            app.ResampleLabel.Text = 'Target sample rate:';
            app.ResampleFreqInput = uieditfield(app.ProcessingGridLayout, 'numeric');
            app.ResampleFreqInput.Limits = [50 Inf];
            app.ResampleFreqInput.ValueDisplayFormat = '%.0f';
            app.ResampleFreqInput.ValueChangedFcn = createCallbackFcn(app, @ResampleFreqInputValueChanged, true);
            app.ResampleFreqInput.Enable = 'off';
            app.ResampleFreqInput.Value = 500;
            app.ResampleUnitLabel = uilabel(app.ProcessingGridLayout);
            app.ResampleUnitLabel.Enable = 'off';
            app.ResampleUnitLabel.Text = 'Hz';
            app.DoSpectrogramInput = uicheckbox(app.ProcessingGridLayout);
            app.DoSpectrogramInput.ValueChangedFcn = createCallbackFcn(app, @DoSpectrogramInputValueChanged, true);
            app.DoSpectrogramInput.Text = 'Calculate Spectrogram:';
            app.SpectrogramSettingsButton = uibutton(app.ProcessingGridLayout, 'push');
            app.SpectrogramSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @SpectrogramSettingsButtonPushed, true);
            app.SpectrogramSettingsButton.Enable = 'off';
            app.SpectrogramSettingsButton.Text = '';
            app.SpectrogramSettingsValue = uilabel(app.ProcessingGridLayout);
            app.SpectrogramSettingsValue.Enable = 'off';
            app.SpectrogramSettingsValue.Text = 'No spectrogram will be calculated';
            app.DoICAInput = uicheckbox(app.ProcessingGridLayout);
            app.DoICAInput.ValueChangedFcn = createCallbackFcn(app, @DoICAInputValueChanged, true);
            app.DoICAInput.Text = 'Calculate ICA';
            app.SaveAsPanel = uipanel(app.GridLayout);
            app.SaveAsPanel.Title = 'Save as';
            app.SaveAsPanelGridLayout = uigridlayout(app.SaveAsPanel);
            app.SaveAsDropDown = uidropdown(app.SaveAsPanelGridLayout);
            app.SaveAsDropDown.Items = {'EEGLAB, all EEG channels + PNS', 'EDF, 12 EEG channels + EOG'};
            app.SaveAsDropDown.ItemsData = {'256', '12'};
            app.SaveAsDropDown.ValueChangedFcn = createCallbackFcn(app, @SaveAsDropDownValueChanged, true);
            app.SaveAsDropDown.Value = '256';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AddFile(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end