classdef ConcatenateData < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        GridLayout             matlab.ui.container.GridLayout
        OutputfilenamePanel    matlab.ui.container.Panel
        SettingsGridLayout_2   matlab.ui.container.GridLayout
        SessionLabel           matlab.ui.control.Label
        SessionNaInput         matlab.ui.control.CheckBox
        SessionInput           matlab.ui.control.DropDown
        TaskLabel              matlab.ui.control.Label
        TaskInput              matlab.ui.control.DropDown
        RunLabel               matlab.ui.control.Label
        RunNaInput             matlab.ui.control.CheckBox
        RunInput               matlab.ui.control.Spinner
        DescriptionLabel       matlab.ui.control.Label
        DescriptionInput       matlab.ui.control.EditField
        SettingsPanel          matlab.ui.container.Panel
        SettingsGridLayout     matlab.ui.container.GridLayout
        ConcatenateInput       matlab.ui.control.DropDown
        ConcatenateLabel       matlab.ui.control.Label
        InstructionPanel       matlab.ui.container.Panel
        InstructionGridLayout  matlab.ui.container.GridLayout
        InstructionLabel       matlab.ui.control.Label
        AddProcessButton       matlab.ui.control.Button
        CancelButton           matlab.ui.control.Button
    end


    properties (Access = public)
        MainApp;
        InterruptDialog;
        % Structure that keeps the state
        State = struct();
    end

    methods (Access = public)
        % =========================================================
        % FUNCTION: Initializes a new Empty State
        function InitializeState(app)
            
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MainApp, FileList)
            % ---------------------------------------------------------
            % Store main app object
            if exist('MainApp', 'var')
                app.MainApp = MainApp;
                % Position the new app relative to the main app
                app_positionGUI(app.MainApp, app.UIFigure)
                % Show waitbar to indicate another gui is open
                app.InterruptDialog = uiprogressdlg(app.MainApp.UIFigure);
            else
                app.MainApp = struct();
            end
            % Clear the import structure
            app.MainApp.Import = struct();
            app.MainApp.Import.Return = false;
            % ---------------------------------------------------------
            % Check the keyvals of the input files
            if all(cellfun(@(kv) isfield(kv, 'ses'), FileList.KeyVals))
                ExcludeSes = false;
            else
                ExcludeSes = true;
            end
            if all(cellfun(@(kv) isfield(kv, 'run'), FileList.KeyVals))
                ExcludeRun = false;
            else
                ExcludeRun = true;
            end
            if ~ExcludeSes
                Session = unique(cellfun(@(kv) kv.ses, FileList.KeyVals, 'UniformOutput', false));
                if length(Session) >= 1
                    Session = Session{1};
                else
                    ExcludeSes = true;
                end
            end
            if ~ExcludeRun
                Run = unique(cellfun(@(kv) kv.run, FileList.KeyVals, 'UniformOutput', false));
                if length(Run) >= 1
                    Run = str2double(Run{1});
                else
                    ExcludeRun = true;
                end
            end
            Task = unique(cellfun(@(kv) kv.task, FileList.KeyVals, 'UniformOutput', false));
            Task = Task{1};
            % ---------------------------------------------------------
            % Initialize objects
            app.SessionNaInput.Value = ExcludeSes;
            if ~ExcludeSes
                app.SessionInput.Enable = 'on';
            end
            if isempty(app.MainApp.DatasetSessionsListBox.Items)
                app.SessionInput.Items = {'1'};
            else
                app.SessionInput.Items = app.MainApp.DatasetSessionsListBox.Items;
                if ExcludeSes
                    Session = app.MainApp.DatasetSessionsListBox.Items{1};
                end
                app.SessionInput.Value = Session;
            end
            app.RunNaInput.Value = ExcludeRun;
            if ~ExcludeRun
                app.RunInput.Value = Run;
                app.RunInput.Enable = 'on';
            end
            if isempty(app.MainApp.DatasetTasksListBox.Items)
                app.TaskInput.Items = {'default'};
            else
                app.TaskInput.Items = app.MainApp.DatasetTasksListBox.Items;
                app.TaskInput.Value = Task;
            end
            % ---------------------------------------------------------
            % Initialize State
            app.State = struct();
            app.State.Concatenate = 'time';
            app.State.ExcludeSes = ExcludeSes;
            app.State.ExcludeRun = ExcludeRun;
            app.State.KeyVals.sub = '';
            app.State.KeyVals.ses = app.SessionInput.Value;
            app.State.KeyVals.task = app.TaskInput.Value;
            app.State.KeyVals.run = num2str(app.RunInput.Value);
            app.State.KeyVals.desc = '<desc>conc';
            app.State.KeyVals.filetype = '';
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            app.MainApp.Import.Return = false;
            % Delete the dialog box
            if isvalid(app)
                delete(app)
            end
        end

        % Button pushed function: AddProcessButton
        function AddProcessButtonPushed(app, event)
            app.MainApp.Import.Return = true;
            app.MainApp.Import.Data = app.State;
            % Delete the dialog box
            if isvalid(app)
                delete(app)
            end
        end

        % Value changed function: DescriptionInput
        function DescriptionInputValueChanged(app, event)
            % ---------------------------------------------------------
            % Remove nonalpha numeric values except arrows
            Rm = isstrprop(app.DescriptionInput.Value, 'alphanum') == 0;
            arrowidx = regexp(app.DescriptionInput.Value, '<|>');
            if ~isempty(arrowidx)
                Rm(arrowidx) = false;
            end
            app.DescriptionInput.Value(Rm) = [];
            app.DescriptionInput.Value = lower(app.DescriptionInput.Value);
            startIdx = regexp(app.DescriptionInput.Value, '<');
            endIdx = regexp(app.DescriptionInput.Value, '>');
            isproblem = false;
            if ~isempty(startIdx) || ~isempty(endIdx)
                if length(startIdx) ~= length(endIdx)
                    isproblem = true;
                    Message = 'Issue: different number of ''<'' and ''>'' characters found.';
                end
                if ~isproblem
                    if any(endIdx < startIdx)
                        isproblem = true;
                        Message = 'Issue: at least one ''<'' character appears after its corresponding ''>'' character.';
                    end
                end
            end
            if isproblem
                sel = uiconfirm(app.UIFigure, Message, 'Error', ...
                    'Options',{'Ok'},...
                    'DefaultOption', 'Ok', ...
                    'Icon', 'error'); %#ok<NASGU>
                event.Source.Value = app.State.KeyVals.desc;
            else
                app.State.KeyVals.desc = event.Source.Value;
            end
        end

        % Value changed function: RunInput
        function RunInputValueChanged(app, event)
            app.State.KeyVals.run = num2str(event.Source.Value);
        end

        % Value changed function: TaskInput
        function TaskInputValueChanged(app, event)
            app.State.KeyVals.task = event.Source.Value;
        end

        % Value changed function: SessionInput
        function SessionInputValueChanged(app, event)
            app.State.KeyVals.ses = event.Source.Value;
        end

        % Value changed function: ConcatenateInput
        function ConcatenateInputValueChanged(app, event)
            app.State.Concatenate = event.Source.Value;
        end

        % Value changed function: SessionNaInput
        function SessionNaInputValueChanged(app, event)
            app.State.ExcludeSes = event.Source.Value;
            if app.State.ExcludeSes
                app.SessionInput.Enable = 'off';
            else
                app.SessionInput.Enable = 'on';
            end
        end

        % Value changed function: RunNaInput
        function RunNaInputValueChanged(app, event)
            app.State.ExcludeRun = event.Source.Value;
            if app.State.ExcludeRun
                app.RunInput.Enable = 'off';
            else
                app.RunInput.Enable = 'on';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Concatenate Data';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.CancelButton = uibutton(app.GridLayout, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.AddProcessButton = uibutton(app.GridLayout, 'push');
            app.AddProcessButton.ButtonPushedFcn = createCallbackFcn(app, @AddProcessButtonPushed, true);
            app.AddProcessButton.Text = 'Add Process';
            app.InstructionPanel = uipanel(app.GridLayout);
            app.InstructionGridLayout = uigridlayout(app.InstructionPanel);
            app.InstructionLabel = uilabel(app.InstructionGridLayout);
            app.InstructionLabel.Text = 'Concatenate data runs separate processes for each subject. You can check files across subjects and create the processes at once. Use the form below to define the output filename. For example, when combining ''run-1'' and ''run-2'', you may want to select ''n/a'' for run, which will omit this key-value from the output filename.';
            app.SettingsPanel = uipanel(app.GridLayout);
            app.SettingsPanel.Title = 'Settings';
            app.SettingsGridLayout = uigridlayout(app.SettingsPanel);
            app.ConcatenateLabel = uilabel(app.SettingsGridLayout);
            app.ConcatenateLabel.Text = 'Concatenate:';
            app.ConcatenateInput = uidropdown(app.SettingsGridLayout);
            app.ConcatenateInput.Items = {'time', 'epochs'};
            app.ConcatenateInput.ValueChangedFcn = createCallbackFcn(app, @ConcatenateInputValueChanged, true);
            app.ConcatenateInput.Value = 'time';
            app.OutputfilenamePanel = uipanel(app.GridLayout);
            app.OutputfilenamePanel.Title = 'Output filename';
            app.SettingsGridLayout_2 = uigridlayout(app.OutputfilenamePanel);
            app.DescriptionInput = uieditfield(app.SettingsGridLayout_2, 'text');
            app.DescriptionInput.ValueChangedFcn = createCallbackFcn(app, @DescriptionInputValueChanged, true);
            app.DescriptionInput.Value = '<desc>conc';
            app.DescriptionLabel = uilabel(app.SettingsGridLayout_2);
            app.DescriptionLabel.Text = 'Description:';
            app.RunInput = uispinner(app.SettingsGridLayout_2);
            app.RunInput.Limits = [1 Inf];
            app.RunInput.RoundFractionalValues = 'on';
            app.RunInput.ValueDisplayFormat = '%i';
            app.RunInput.ValueChangedFcn = createCallbackFcn(app, @RunInputValueChanged, true);
            app.RunInput.Enable = 'off';
            app.RunInput.Value = 1;
            app.RunNaInput = uicheckbox(app.SettingsGridLayout_2);
            app.RunNaInput.ValueChangedFcn = createCallbackFcn(app, @RunNaInputValueChanged, true);
            app.RunNaInput.Text = 'n/a';
            app.RunNaInput.Value = true;
            app.RunLabel = uilabel(app.SettingsGridLayout_2);
            app.RunLabel.Text = 'Run:';
            app.TaskInput = uidropdown(app.SettingsGridLayout_2);
            app.TaskInput.Items = {};
            app.TaskInput.ValueChangedFcn = createCallbackFcn(app, @TaskInputValueChanged, true);
            app.TaskInput.Value = {};
            app.TaskLabel = uilabel(app.SettingsGridLayout_2);
            app.TaskLabel.Text = 'Task:';
            app.SessionInput = uidropdown(app.SettingsGridLayout_2);
            app.SessionInput.Items = {};
            app.SessionInput.ValueChangedFcn = createCallbackFcn(app, @SessionInputValueChanged, true);
            app.SessionInput.Enable = 'off';
            app.SessionInput.Value = {};
            app.SessionNaInput = uicheckbox(app.SettingsGridLayout_2);
            app.SessionNaInput.ValueChangedFcn = createCallbackFcn(app, @SessionNaInputValueChanged, true);
            app.SessionNaInput.Text = 'n/a';
            app.SessionNaInput.Value = true;
            app.SessionLabel = uilabel(app.SettingsGridLayout_2);
            app.SessionLabel.Text = 'Session:';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ConcatenateData(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end