classdef InterpolatePowerSpectralOutliers < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        LoadingImage              matlab.ui.control.Image
        CancelButton              matlab.ui.control.Button
        AddProcessButton          matlab.ui.control.Button
        Panel                     matlab.ui.container.Panel
        MainGridLayout            matlab.ui.container.GridLayout
        InterpolateListPanel      matlab.ui.container.Panel
        InterpolateGridLayout     matlab.ui.container.GridLayout
        ChanInput                 matlab.ui.control.EditField
        InterpolateTree           matlab.ui.container.Tree
        ResetButton               matlab.ui.control.Button
        OutliersPanel             matlab.ui.container.Panel
        OutliersGridLayout        matlab.ui.container.GridLayout
        DeltaLabel                matlab.ui.control.Label
        BetaLabel                 matlab.ui.control.Label
        DeltaZscoreUIAxes         matlab.ui.control.UIAxes
        BetaPowerUIAxes           matlab.ui.control.UIAxes
        DeltaPowerUIAxes          matlab.ui.control.UIAxes
        BetaZscoreUIAxes          matlab.ui.control.UIAxes
        PowerSpectPanel           matlab.ui.container.Panel
        PowerSpectGridLayout      matlab.ui.container.GridLayout
        InstructionLabel          matlab.ui.control.Label
        PowerSpectInsetUIAxes     matlab.ui.control.UIAxes
        PowerSpectUIAxes          matlab.ui.control.UIAxes
        OutliershavevaluesabovethethresholdinatleastKNinputfilesPanel  matlab.ui.container.Panel
        ThresholdPanelGridLayout  matlab.ui.container.GridLayout
        ThresholdSpinner          matlab.ui.control.Spinner
        SDSpinnerLabel            matlab.ui.control.Label
        InputsSpinner             matlab.ui.control.Spinner
        NumInputsLabel            matlab.ui.control.Label
        LogTransDataCheckBox      matlab.ui.control.CheckBox
    end

    
    properties (Access = public)
        MainApp;
        InterruptDialog;
        % Structure that keeps the state
        State = struct();
        Objs = struct();
    end
    
    methods (Access = public)
        % =========================================================
        % FUNCTION: Initializes a new Empty State
        function InitializeState(app, FileList)
            % ---------------------------------------------------------
            % Init state
            app.State = struct();
            app.State.MarkerSize = 12;
            app.State.Threshold = 3;
            app.State.InputRatio = ceil(size(FileList, 1).*2/3);
            app.State.LogTransData = false;
            app.State.EEG = struct();
            app.State.Outliers = {};
            app.State.SelChan = zeros(0);
            % ---------------------------------------------------------
            % Load, check, and extract data
            app.State.EEG = struct();
            for i = 1:size(FileList, 1)
                % Load data
                app.State.EEG(i).eeg = LoadDataset(FileList.Path{i}, 'all');
                % Check that the dimensions of the data are the same
                if i > 1
                    if app.State.EEG(i).eeg.nbchan ~= app.State.EEG(i-1).eeg.nbchan
                        app.RenderErrorMessage('A critical error occurred. The input files do not have the same number of channels.');
                        app.CancelButtonPushed();
                        return
                    end
                    if ~all(strcmpi({app.State.EEG(i).eeg.chanlocs.labels}, {app.State.EEG(i-1).eeg.chanlocs.labels}))
                        app.RenderErrorMessage('A critical error occurred. The input files do not have the same channel labels.');
                        app.CancelButtonPushed();
                        return
                    end
                end
                % Check that there are no interpolated channels yet
                if isfield(app.State.EEG(i).eeg.etc, 'interp_channels')
                    if ~isempty(app.State.EEG(i).eeg.etc.interp_channels)
                        app.RenderErrorMessage('A critical error occurred. The data already contains interpolated channels. You cannot interpolate channels again.');
                        app.CancelButtonPushed();
                        return
                    end
                end

                keyboard;
                if isfield (Settings.EEG(idxFile).eeg, 'features')
                    fprintf('Running Power Spectral Outliers Interpolation');
                elseif isfield (Settings.EEG(idxFile).eeg, 'bands')
                    Settings.EEG(idxFile).eeg.features=Settings.EEG(idxFile).eeg.bands;
                    fprintf('Running Power Spectral Outliers Interpolation');
                else
                    app.RenderErrorMessage('Unrecognized field name "bands"/ "features"');
                end

                % Get the indices of the delta and beta bands
                [idxDelta, idxBeta] = GetFreqBandIndex(app, app.State.EEG(i).eeg.features);
                % Extract the average PSD across all trials
                app.State.PSD.psd(:, :, i) = squeeze(mean(app.State.EEG(i).eeg.data, 3));
                app.State.PSD.delta(:, i) = squeeze(mean(app.State.EEG(i).eeg.features(idxDelta).data, 3));
                app.State.PSD.beta(:, i) = squeeze(mean(app.State.EEG(i).eeg.features(idxBeta).data, 3));
            end
            % ---------------------------------------------------------
            % Get Legend
            KeyVals = FileList.KeyVals;
            Keys = fieldnames(FileList.KeyVals{1});
            for i = 1:length(Keys)
                Vals = unique(cellfun(@(k) k.(Keys{i}), KeyVals, 'UniformOutput', false));
                if length(Vals) == 1
                    KeyVals = cellfun(@(s) rmfield(s, Keys{i}), KeyVals, 'UniformOutput', false);
                end
            end
            app.State.Legend = cell(size(FileList, 1), 1);
            for i = 1:length(KeyVals)
                app.State.Legend{i} = strjoin(cellfun(@(key, val) [key, '-', val], fieldnames(KeyVals{i}), struct2cell(KeyVals{i}), 'UniformOutput', false), ' ');
            end
            % ---------------------------------------------------------
            % Set static render objects
            app.NumInputsLabel.Text = sprintf('/%i', size(FileList, 1));
            app.InputsSpinner.Value = app.State.InputRatio;
            if size(FileList, 1) > 1
                app.InputsSpinner.Limits = [1, size(FileList, 1)];
            else
                app.InputsSpinner.Enable = 'off';
            end
            % ---------------------------------------------------------
            % Initial render of power spectrum
            for i = 1:app.State.EEG(1).eeg.nbchan
                YData = asrow(squeeze(mean(app.State.PSD.psd(i, :, :), 3)));
                XData = asrow(app.State.EEG(1).eeg.freqs);
                app.Objs.PSD(i).Obj = patch(app.PowerSpectUIAxes, ...
                        'XData', [XData, nan], ...
                        'YData', [YData, nan], ...
                        'EdgeColor', 'k', ...
                        'EdgeAlpha', 0.33, ...
                        'PickableParts', 'none');
                app.Objs.PSD(i).Obj.Tag = app.State.EEG(1).eeg.chanlocs(i).labels;
            end
            % Initial render of selected channel line
            app.Objs.PowerSpecLine = plot(app.PowerSpectUIAxes, ...
                [0; nan], ...
                [0; nan], ...
                'Color', 'b', ...
                'PickableParts', 'none');
            % Initial render of inset power spectrum lines
            clrs = standard_colors(size(FileList, 1));
            for i = 1:size(FileList, 1)
                app.Objs.Inset(i).Obj = plot(app.PowerSpectInsetUIAxes, ...
                    [0, nan], ...
                    [0, nan], ...
                    'Color', clrs(i, :), ...
                    'PickableParts', 'none');
            end
            % Initial render of the legend
            app.Objs.Legend = legend(app.PowerSpectInsetUIAxes, [app.Objs.Inset.Obj], repmat({'x'}, 1, size(FileList, 1)));
            % ---------------------------------------------------------
            % Get common outlier channels across all files
            app.Objs.TreeNodes = struct();
            app.Objs.TreeNodes(1) = [];
            app.GetOutlierChannels();
        end
        % =========================================================
        % FUNCTION: Returns a list of outlier channels for one file
        function [idxDelta, idxBeta] = GetFreqBandIndex(app, features)
            % ---------------------------------------------------------
            % Find index of delta and beta bands
            idxDelta = find(strcmpi({features.label}, 'delta') & strcmpi({features.type}, 'absolute power'), 1, 'first');
            % Legacy support for old label for low and high delta
            if isempty(idxDelta)
                idxDelta = find(strcmpi({features.label}, 'high-delta'), 1, 'first');
            end
            idxBeta = find(strcmpi({features.label}, 'beta') & strcmpi({features.type}, 'absolute power'), 1, 'first');
            % Check the output is valid or throw error
            if isempty(idxDelta) || isempty(idxBeta)
                app.RenderErrorMessage('A critical error occurred. The ''delta'' or ''beta'' band was not found in the frequency bands.');
                return
            end
        end
        % =========================================================
        % FUNCTION: Calculates the z-score using the mean and SD of
        % channels inside the innermost scalp region (i.e., excluding neck
        % and cheek electrodes)
        function z = applyZscore(app, data)
            % ---------------------------------------------------------
            % Check if log10 transfomation needs to be applied
            if app.State.LogTransData 
                data = log10(data);
            end
            % ---------------------------------------------------------
            % Get the mean and SD
            mu = mean(data, 1); % Mean across all channels
            sd = std(data, [], 1); % SD across all channels
            z = abs((data - repmat(mu, size(data, 1), 1)) ./ repmat(sd, size(data, 1), 1));
        end
        % =========================================================
        % FUNCTION: Returns a list of outlier channels across all files
        function GetOutlierChannels(app)
            % ---------------------------------------------------------
            % Find channel indices that are above the threshold for at least N input files
            idxOutlChans = sum(...
                app.applyZscore(app.State.PSD.delta) >= app.State.Threshold | ...
                app.applyZscore(app.State.PSD.beta) >= app.State.Threshold, ...
                2) >= app.State.InputRatio;
            % ---------------------------------------------------------
            % Get the channel labels of the outlier channels
            app.State.Outliers = {app.State.EEG(1).eeg.chanlocs(idxOutlChans).labels};
        end
        % =========================================================
        % RENDER
        % =========================================================
        % FUNCTION: Sets an error message
        function RenderErrorMessage(app, Message)
            % ---------------------------------------------------------
            % Render error confirm dialog
            sel = uiconfirm(app.UIFigure, Message, 'Error', ...
                'Options',{'Ok'},...
                'DefaultOption', 'Ok', ...
                'Icon', 'error'); %#ok<NASGU> 
            drawnow;
        end
        % =========================================================
        % FUNCTION: Renders the power spectra
        function RenderPowerSpectUIAxes(app)
            % ---------------------------------------------------------
            % Set color of outliers
            childOrder = zeros(0);
            for i = 1:app.State.EEG(1).eeg.nbchan
                if ismember(app.Objs.PSD(i).Obj.Tag, app.State.Outliers)
                    % Add to order
                    childOrder = [app.Objs.PSD(i).Obj; childOrder]; %#ok<AGROW> 
                    % Color red
                    UpdateProperty(app, app.Objs.PSD(i).Obj, 'EdgeColor', 'r');
                    UpdateProperty(app, app.Objs.PSD(i).Obj, 'EdgeAlpha', 1);
                else
                    % Add to order
                    childOrder = [childOrder; app.Objs.PSD(i).Obj]; %#ok<AGROW> 
                    % Color black
                    UpdateProperty(app, app.Objs.PSD(i).Obj, 'EdgeColor', 'k');
                    UpdateProperty(app, app.Objs.PSD(i).Obj, 'EdgeAlpha', 0.1);
                end
            end
            % ---------------------------------------------------------
            % Reorder children
            app.PowerSpectUIAxes.Children = [...
                app.Objs.PowerSpecLine; ...
                childOrder ...
                ];
        end
        % =========================================================
        % FUNCTION: Renders the topoplots of absolute power
        function RenderTopoPowerUIAxes(app)
            % ---------------------------------------------------------
            % Delete any existing children
            delete(app.DeltaPowerUIAxes.Children)
            delete(app.BetaPowerUIAxes.Children)
            % ---------------------------------------------------------
            % Get delta power and plot it
            if app.State.LogTransData
                YData = squeeze(mean(log10(app.State.PSD.delta), 2));
            else
                YData = squeeze(mean(app.State.PSD.delta, 2));
            end
            app.DeltaPowerUIAxes = plotTopoplot(app.DeltaPowerUIAxes, YData, app.State.EEG(1).eeg.chanlocs, -1);
            % ---------------------------------------------------------
            % Get beta power and plot it
            if app.State.LogTransData
                YData = mean(maxk(log10(app.State.PSD.beta), app.State.InputRatio, 2), 2);
            else
                YData = mean(maxk(app.State.PSD.beta, app.State.InputRatio, 2), 2);
            end
            app.BetaPowerUIAxes = plotTopoplot(app.BetaPowerUIAxes, YData, app.State.EEG(1).eeg.chanlocs, -1);
        end
        % =========================================================
        % FUNCTION: Renders the topoplots of z-scores
        function RenderTopoZscoreUIAxes(app)
            for band = {'Delta', 'Beta'}
                b = band{:};
                % ---------------------------------------------------------
                % Delete any existing children
                delete(app.([b, 'ZscoreUIAxes']).Children)
                % ---------------------------------------------------------
                % Get power z-scores
                HData = mean(maxk(app.applyZscore(app.State.PSD.(lower(b))), app.State.InputRatio, 2), 2);
                HData(HData >= app.State.Threshold) = app.State.Threshold;
                % ---------------------------------------------------------
                % Generate topoplot
                app.([b, 'ZscoreUIAxes']) = plotTopoplot(app.([b, 'ZscoreUIAxes']), HData, app.State.EEG(1).eeg.chanlocs, app.State.MarkerSize);
                % ---------------------------------------------------------
                % Extract channel line objects
                tmpObj1 = findobj(app.([b, 'ZscoreUIAxes']).Children, 'Marker', '.', 'MarkerSize', app.State.MarkerSize+3);
                tmpObj2 = findobj(app.([b, 'ZscoreUIAxes']).Children, 'MarkerSize', app.State.MarkerSize);
                % ---------------------------------------------------------
                % Save all channel locations for the topoplot
                app.State.TopoChanlocs.XData = [tmpObj1.XData, tmpObj2.XData];
                app.State.TopoChanlocs.YData = [tmpObj1.YData, tmpObj2.YData];
                app.State.TopoChanlocs.ZData = [tmpObj1.ZData, tmpObj2.ZData];
                % ---------------------------------------------------------
                % Initialize the selected channels
                app.Objs.SelChans.(b) = tmpObj1;
                app.Objs.SelChans.(b).XData = zeros(0);
                app.Objs.SelChans.(b).YData = zeros(0);
                app.Objs.SelChans.(b).ZData = zeros(0);
                app.Objs.SelChans.(b).Color = 'b';
                % Initialize the outlier channels
                app.Objs.OutlChans.(b) = tmpObj2;
                app.Objs.OutlChans.(b).XData = zeros(0);
                app.Objs.OutlChans.(b).YData = zeros(0);
                app.Objs.OutlChans.(b).ZData = zeros(0);
                app.Objs.OutlChans.(b).Color = 'r';
            end
        end
        % =========================================================
        % FUNCTION: Renders the interpolate tree
        function RenderInterpolateTree(app)
            % ---------------------------------------------------------
            % Remove selected node if no channle is selected
            if ~any(app.State.SelChan)
                app.InterpolateTree.SelectedNodes = [];
            end
            % ---------------------------------------------------------
            % Delete any children if they are no longer necessary
            for i = length(app.Objs.TreeNodes):-1:length(app.State.Outliers)+1
                delete(app.Objs.TreeNodes(i).Obj)
                app.Objs.TreeNodes(i) = [];
            end
            % ---------------------------------------------------------
            % Create or update a node for each Common Outlier channel
            for i = 1:length(app.Objs.TreeNodes)
                UpdateProperty(app, app.Objs.TreeNodes(i).Obj, 'Text', app.State.Outliers{i});
            end
            for i = length(app.Objs.TreeNodes)+1:length(app.State.Outliers)
                app.Objs.TreeNodes(i).Obj = uitreenode(app.InterpolateTree, 'Text', app.State.Outliers{i});
            end
        end
        % =========================================================
        % FUNCTION: Renders the selected channel
        function RenderSelChan(app)
            % ---------------------------------------------------------
            % If the selected channel is in the list, show remove button
            % Otherwise show the add button
            if ~any(app.State.SelChan)
                UpdateProperty(app, app.ResetButton, 'Text', 'Reset');
            else
                app.ChanInput.Value = app.State.EEG(1).eeg.chanlocs(app.State.SelChan).labels;
                if ismember(app.State.EEG(1).eeg.chanlocs(app.State.SelChan).labels, app.State.Outliers)
                    UpdateProperty(app, app.ResetButton, 'Text', ['Remove ', app.State.EEG(1).eeg.chanlocs(app.State.SelChan).labels]);
                else
                    UpdateProperty(app, app.ResetButton, 'Text', ['Add ', app.State.EEG(1).eeg.chanlocs(app.State.SelChan).labels]);
                end
            end
            % ---------------------------------------------------------
            % Show the blue dot in the z-score topoplots
            Z = zeros(size(app.State.PSD.delta));
            for band = {'Delta', 'Beta'}
                b = band{:};
                % If no channel is selected remove dot from view
                if ~any(app.State.SelChan)
                    app.Objs.SelChans.(b).XData = zeros(0);
                    app.Objs.SelChans.(b).YData = zeros(0);
                    app.Objs.SelChans.(b).ZData = zeros(0);
                    continue
                end
                % Get power z-scores
                HData = app.applyZscore(app.State.PSD.(lower(b)));
                Z = max(cat(3, Z, HData), [], 3);
                % Set the topoplot blue dot
                app.Objs.SelChans.(b).XData = app.State.TopoChanlocs.XData(app.State.SelChan);
                app.Objs.SelChans.(b).YData = app.State.TopoChanlocs.YData(app.State.SelChan);
                app.Objs.SelChans.(b).ZData = app.State.TopoChanlocs.ZData(app.State.SelChan);
            end
            % ---------------------------------------------------------
            % Show/hide inset axis and plot individual PSDs from input files
            if ~any(app.State.SelChan)
                UpdateProperty(app, app.Objs.PowerSpecLine, 'XData', [0; nan]);
                UpdateProperty(app, app.Objs.PowerSpecLine, 'YData', [0; nan]);
                UpdateProperty(app, app.InstructionLabel, 'Visible', 'on');
                UpdateProperty(app, app.Objs.Legend, 'Visible', 'off');
                UpdateProperty(app, app.PowerSpectInsetUIAxes, 'Visible', 'off');
                for i = 1:length(app.PowerSpectInsetUIAxes.Children)
                    UpdateProperty(app, app.PowerSpectInsetUIAxes.Children(i), 'Visible', 'off');
                end
                return
            else
                UpdateProperty(app, app.InstructionLabel, 'Visible', 'off');
                UpdateProperty(app, app.Objs.Legend, 'Visible', 'on');
                UpdateProperty(app, app.PowerSpectInsetUIAxes, 'Visible', 'on');
                for i = 1:length(app.PowerSpectInsetUIAxes.Children)
                    UpdateProperty(app, app.PowerSpectInsetUIAxes.Children(i), 'Visible', 'on');
                end
            end
            % ---------------------------------------------------------
            % Show the blue power spectrum line
            UpdateProperty(app, app.Objs.PowerSpecLine, 'XData', app.Objs.PSD(app.State.SelChan).Obj.XData);
            UpdateProperty(app, app.Objs.PowerSpecLine, 'YData', app.Objs.PSD(app.State.SelChan).Obj.YData);
            % ---------------------------------------------------------
            % Plot the PSDs for each input file for this selected channel
            XData = asrow(app.State.EEG(1).eeg.freqs);
            for i = 1:length(app.State.EEG)
                YData = asrow(squeeze(mean(app.State.EEG(i).eeg.data(app.State.SelChan, :, :), 3)));
                app.Objs.Inset(i).Obj.XData = [XData, nan];
                app.Objs.Inset(i).Obj.YData = [YData, nan];
            end
            % ---------------------------------------------------------
            % Show channel label on the title
            app.PowerSpectInsetUIAxes.Title.String = app.Objs.PSD(app.State.SelChan).Obj.Tag;
            % ---------------------------------------------------------
            % Show legend with individual z-scores
            app.Objs.Legend.String = cellfun(@(l, z) sprintf('%s Z: %.2f', l, z), asrow(app.State.Legend), num2cell(Z(app.State.SelChan, :)), 'UniformOutput', false);;
        end
        % =========================================================
        % FUNCTION: Renders the outlier channels
        function RenderOutlChan(app)
            % ---------------------------------------------------------
            % Show the blue dot in the z-score topoplots
            for band = {'Delta', 'Beta'}
                b = band{:};
                % Update the second tmp object to show only outlier channels
                idx = ismember({app.State.EEG(1).eeg.chanlocs.labels}, app.State.Outliers);
                app.Objs.OutlChans.(b).XData = app.State.TopoChanlocs.XData(idx);
                app.Objs.OutlChans.(b).YData = app.State.TopoChanlocs.YData(idx);
                app.Objs.OutlChans.(b).ZData = app.State.TopoChanlocs.ZData(idx);
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MainApp, FileList)
            % ---------------------------------------------------------
            % Store main app object
            if exist('MainApp', 'var')
                app.MainApp = MainApp;
                % Position the new app relative to the main app
                app_positionGUI(app.MainApp, app.UIFigure)
                % Show waitbar to indicate another gui is open
                app.InterruptDialog = uiprogressdlg(app.MainApp.UIFigure);
            else
                app.MainApp = struct();
            end
            % Clear the import structure
            app.MainApp.Import = struct();
            app.MainApp.Import.Return = false;
            % ---------------------------------------------------------
            % Set loading
            app.LoadingImage.ImageSource = 'loading.gif';
            app.LoadingImage.Visible = 'on';
            app.UIFigure.Pointer = 'watch';
            drawnow();
            % ---------------------------------------------------------
            % Load the colormap
            if exist('colormap_batlow.mat', 'file') == 2
                app.State.CMap = load('colormap_batlow.mat');
                app.State.CMap = app.State.CMap.batlow;
                app.UIFigure.Colormap = app.State.CMap;
            else
                app.State.CMap = parula();
                app.UIFigure.Colormap = app.State.CMap;
            end
            % ---------------------------------------------------------
            % Initialize State
            app.InitializeState(FileList);
            % ---------------------------------------------------------
            % Map the State to the Properties of all Components
            app.RenderPowerSpectUIAxes();
            app.RenderTopoPowerUIAxes();
            app.RenderTopoZscoreUIAxes();
            app.RenderSelChan();
            app.RenderOutlChan();
            app.RenderInterpolateTree();
            % ---------------------------------------------------------
            % Set the main panel to enabled = 'on'
            drawnow();
            app.Panel.Enable = 'on';
            app.LoadingImage.Visible = 'off';
            app.UIFigure.Pointer = 'arrow';
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            app.MainApp.Import.Return = false;
            % Delete the dialog box
            if isvalid(app)
                delete(app)
            end
        end

        % Button pushed function: AddProcessButton
        function AddProcessButtonPushed(app, event)
            app.MainApp.Import.Return = true;
            app.MainApp.Import.Data = app.State;
            % Delete the dialog box
            if isvalid(app)
                delete(app)
            end
        end

        % Value changed function: ThresholdSpinner
        function ThresholdSpinnerValueChanged(app, event)
            % ---------------------------------------------------------
            % Update state
            app.State.Threshold = app.ThresholdSpinner.Value;
            app.State.SelChan = false;
            % Get common outlier channels across all files
            app.GetOutlierChannels();
            % ---------------------------------------------------------
            % Render
            app.RenderPowerSpectUIAxes(); % To update colors
            app.RenderSelChan(); % To update selected channel
            app.RenderOutlChan(); % To update outlier channels
            app.RenderInterpolateTree(); % List all outlier channels
        end

        % Value changed function: InputsSpinner
        function InputsSpinnerValueChanged(app, event)
            % ---------------------------------------------------------
            % Update state
            app.State.InputRatio = app.InputsSpinner.Value;
            app.State.SelChan = false;
            % Get common outlier channels across all files
            app.GetOutlierChannels();
            % ---------------------------------------------------------
            % Render
            app.RenderPowerSpectUIAxes(); % To update colors
            app.RenderSelChan(); % To update selected channel
            app.RenderOutlChan(); % To update outlier channels
            app.RenderInterpolateTree(); % List all outlier channels
        end

        % Value changed function: LogTransDataCheckBox
        function LogTransDataCheckBoxValueChanged(app, event)
            % ---------------------------------------------------------
            % Update state
            app.State.LogTransData = app.LogTransDataCheckBox.Value;
            app.State.SelChan = false;
            % Get common outlier channels across all files
            app.GetOutlierChannels();
            % ---------------------------------------------------------
            % Render
            app.RenderTopoZscoreUIAxes(); % To update Z-score topoplots
            app.RenderPowerSpectUIAxes(); % To update colors
            app.RenderSelChan(); % To update selected channel
            app.RenderOutlChan(); % To update outlier channels
            app.RenderInterpolateTree(); % List all outlier channels
        end

        % Value changed function: ChanInput
        function ChanInputValueChanged(app, event)
            % ---------------------------------------------------------
            CurrVal = app.State.SelChan;
            app.State.SelChan = strcmpi({app.State.EEG(1).eeg.chanlocs.labels}, event.Source.Value);
            if any(app.State.SelChan)
                app.State.SelChan = find(app.State.SelChan);
            else
                app.State.SelChan = CurrVal;
                if isempty(CurrVal)
                    event.Source.Value = '';
                else
                    event.Source.Value = app.State.EEG(1).eeg.chanlocs(CurrVal).labels;
                end
            end
            % ---------------------------------------------------------
            % Render
            app.RenderSelChan(); % To update selected channel
        end

        % Button down function: PowerSpectUIAxes
        function PowerSpectUIAxesButtonDown(app, event)
            % ---------------------------------------------------------
            % Get current point
            cp = app.PowerSpectUIAxes.CurrentPoint(1, 1:2);
            % If current point is outside of graph, return
            if ...
                    cp(1) <= app.PowerSpectUIAxes.XLim(1) || ...
                    cp(2) <= app.PowerSpectUIAxes.YLim(1) || ...
                    cp(1) >= app.PowerSpectUIAxes.XLim(2) || ...
                    cp(2) >= app.PowerSpectUIAxes.YLim(2)
                app.Objs.PowerSpecLine.XData = [0; nan];
                app.Objs.PowerSpecLine.YData = [0; nan];
                return
            end
            % ---------------------------------------------------------
            % Find nearest channel
            closestPnts = arrayfun(@(o) min(sqrt((o.XData - cp(1)).^2 + (log10(o.YData) - log10(cp(2))).^2)), [app.Objs.PSD.Obj], 'UniformOutput', true);
            [~, idxChan] = min(closestPnts);
            app.State.SelChan = idxChan;
            % ---------------------------------------------------------
            % Render
            app.RenderSelChan(); % To update selected channel
        end

        % Button down function: BetaZscoreUIAxes, DeltaZscoreUIAxes
        function TopoZscoreUIAxesButtonDown(app, event)
            % ---------------------------------------------------------
            % Get current point
            cp = event.Source.CurrentPoint(1, 1:2);
            % Find nearest channel
            [~, idxChan] = min((app.State.TopoChanlocs.XData - cp(1)).^2 + (app.State.TopoChanlocs.YData - cp(2)).^2);
            app.State.SelChan = idxChan;
            % ---------------------------------------------------------
            % Render
            app.RenderSelChan(); % To update selected channel
        end

        % Selection changed function: InterpolateTree
        function InterpolateTreeSelectionChanged(app, event)
            % ---------------------------------------------------------
            % Set state
            app.State.SelChan = strcmpi({app.State.EEG(1).eeg.chanlocs.labels}, app.InterpolateTree.SelectedNodes.Text);
            % ---------------------------------------------------------
            % Render
            app.RenderSelChan(); % To update selected channel
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            % ---------------------------------------------------------
            % Set state
            if any(app.State.SelChan)
                lbl = app.State.EEG(1).eeg.chanlocs(app.State.SelChan).labels;
            end
            switch event.Source.Text(1:3)
                case 'Add'
                    app.State.Outliers = [app.State.Outliers, {lbl}];
                    [~, idx] = sort(cellfun(@(s) str2double(s(2:end)), app.State.Outliers));
                    app.State.Outliers = app.State.Outliers(idx);
                case 'Rem'
                    app.State.Outliers(strcmpi(app.State.Outliers, lbl)) = [];
                case 'Res'
                    app.GetOutlierChannels();
            end
            app.State.SelChan = false;
            % ---------------------------------------------------------
            % Render
            app.RenderPowerSpectUIAxes(); % To update colors
            app.RenderSelChan(); % To update selected channel
            app.RenderOutlChan(); % To update outlier channels
            app.RenderInterpolateTree(); % List all outlier channels
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Interpolate Power-Spectral Outliers';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.Panel = uipanel(app.GridLayout);
            app.Panel.Enable = 'off';
            app.MainGridLayout = uigridlayout(app.Panel);
            app.OutliershavevaluesabovethethresholdinatleastKNinputfilesPanel = uipanel(app.MainGridLayout);
            app.OutliershavevaluesabovethethresholdinatleastKNinputfilesPanel.Title = 'Outliers have values above the threshold in at least K / N input files';
            app.ThresholdPanelGridLayout = uigridlayout(app.OutliershavevaluesabovethethresholdinatleastKNinputfilesPanel);
            app.LogTransDataCheckBox = uicheckbox(app.ThresholdPanelGridLayout);
            app.LogTransDataCheckBox.ValueChangedFcn = createCallbackFcn(app, @LogTransDataCheckBoxValueChanged, true);
            app.LogTransDataCheckBox.Text = 'log transform data';
            app.NumInputsLabel = uilabel(app.ThresholdPanelGridLayout);
            app.NumInputsLabel.Text = '/N';
            app.InputsSpinner = uispinner(app.ThresholdPanelGridLayout);
            app.InputsSpinner.Limits = [1 Inf];
            app.InputsSpinner.ValueChangedFcn = createCallbackFcn(app, @InputsSpinnerValueChanged, true);
            app.InputsSpinner.Value = 2;
            app.SDSpinnerLabel = uilabel(app.ThresholdPanelGridLayout);
            app.SDSpinnerLabel.Text = 'SD';
            app.ThresholdSpinner = uispinner(app.ThresholdPanelGridLayout);
            app.ThresholdSpinner.Limits = [0 Inf];
            app.ThresholdSpinner.ValueChangedFcn = createCallbackFcn(app, @ThresholdSpinnerValueChanged, true);
            app.ThresholdSpinner.Value = 3;
            app.PowerSpectPanel = uipanel(app.MainGridLayout);
            app.PowerSpectGridLayout = uigridlayout(app.PowerSpectPanel);
            app.PowerSpectUIAxes = uiaxes(app.PowerSpectGridLayout);
            xlabel(app.PowerSpectUIAxes, 'Frequency (Hz)')
            ylabel(app.PowerSpectUIAxes, 'Power (uV/Hz^2)')
            app.PowerSpectUIAxes.XLim = [0 45];
            app.PowerSpectUIAxes.XTick = [1 4.5 8 12 15 25 40];
            app.PowerSpectUIAxes.XTickLabel = {'1'; '4.5'; '8'; '12'; '15'; '25'; '40'};
            app.PowerSpectUIAxes.YScale = 'log';
            app.PowerSpectUIAxes.ButtonDownFcn = createCallbackFcn(app, @PowerSpectUIAxesButtonDown, true);
            app.PowerSpectUIAxes.Tag = 'PowerSpectUIAxes';
            app.PowerSpectInsetUIAxes = uiaxes(app.PowerSpectGridLayout);
            title(app.PowerSpectInsetUIAxes, 'chan')
            xlabel(app.PowerSpectInsetUIAxes, 'Frequency (Hz)')
            ylabel(app.PowerSpectInsetUIAxes, 'Power (uV/Hz^2)')
            app.PowerSpectInsetUIAxes.XLim = [0 45];
            app.PowerSpectInsetUIAxes.XTick = [1 4.5 8 12 15 25 40];
            app.PowerSpectInsetUIAxes.XTickLabel = {'1'; '4.5'; '8'; '12'; '15'; '25'; '40'};
            app.PowerSpectInsetUIAxes.YScale = 'log';
            app.PowerSpectInsetUIAxes.Tag = 'PowerSpectUIAxes';
            app.PowerSpectInsetUIAxes.Visible = 'off';
            app.InstructionLabel = uilabel(app.PowerSpectGridLayout);
            app.InstructionLabel.Text = 'Select a channel for closer inspection.';
            app.OutliersPanel = uipanel(app.MainGridLayout);
            app.OutliersPanel.Title = 'Outliers';
            app.OutliersGridLayout = uigridlayout(app.OutliersPanel);
            app.BetaZscoreUIAxes = uiaxes(app.OutliersGridLayout);
            title(app.BetaZscoreUIAxes, 'Z-score')
            app.BetaZscoreUIAxes.XTick = [];
            app.BetaZscoreUIAxes.YTick = [];
            app.BetaZscoreUIAxes.ZTick = [];
            app.BetaZscoreUIAxes.ButtonDownFcn = createCallbackFcn(app, @TopoZscoreUIAxesButtonDown, true);
            app.DeltaPowerUIAxes = uiaxes(app.OutliersGridLayout);
            title(app.DeltaPowerUIAxes, 'log_{10}(Abs. power)')
            app.DeltaPowerUIAxes.XTick = [];
            app.DeltaPowerUIAxes.YTick = [];
            app.DeltaPowerUIAxes.ZTick = [];
            app.BetaPowerUIAxes = uiaxes(app.OutliersGridLayout);
            title(app.BetaPowerUIAxes, 'log_{10}(Abs. power)')
            app.BetaPowerUIAxes.XTick = [];
            app.BetaPowerUIAxes.YTick = [];
            app.BetaPowerUIAxes.ZTick = [];
            app.DeltaZscoreUIAxes = uiaxes(app.OutliersGridLayout);
            title(app.DeltaZscoreUIAxes, 'Z-score')
            app.DeltaZscoreUIAxes.XLim = [0 1];
            app.DeltaZscoreUIAxes.YLim = [0 1];
            app.DeltaZscoreUIAxes.XTick = [];
            app.DeltaZscoreUIAxes.YTick = [];
            app.DeltaZscoreUIAxes.ZTick = [];
            app.DeltaZscoreUIAxes.ButtonDownFcn = createCallbackFcn(app, @TopoZscoreUIAxesButtonDown, true);
            app.BetaLabel = uilabel(app.OutliersGridLayout);
            app.BetaLabel.Text = 'Beta (15 - 25 Hz)';
            app.DeltaLabel = uilabel(app.OutliersGridLayout);
            app.DeltaLabel.Text = 'Delta (1 - 4.5 Hz)';
            app.InterpolateListPanel = uipanel(app.MainGridLayout);
            app.InterpolateListPanel.Title = 'To be interpolated';
            app.InterpolateGridLayout = uigridlayout(app.InterpolateListPanel);
            app.ResetButton = uibutton(app.InterpolateGridLayout, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.InterpolateTree = uitree(app.InterpolateGridLayout);
            app.InterpolateTree.SelectionChangedFcn = createCallbackFcn(app, @InterpolateTreeSelectionChanged, true);
            app.ChanInput = uieditfield(app.InterpolateGridLayout, 'text');
            app.ChanInput.ValueChangedFcn = createCallbackFcn(app, @ChanInputValueChanged, true);
            app.AddProcessButton = uibutton(app.GridLayout, 'push');
            app.AddProcessButton.ButtonPushedFcn = createCallbackFcn(app, @AddProcessButtonPushed, true);
            app.AddProcessButton.Text = 'Add Process';
            app.CancelButton = uibutton(app.GridLayout, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.LoadingImage = uiimage(app.GridLayout);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = InterpolatePowerSpectralOutliers(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end